classdef HardwareControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HardwareControlPanelUIFigure  matlab.ui.Figure
        PlSubPanel1                   matlab.ui.container.Panel
        PhaseLock                     matlab.ui.container.Panel
        PlSubPanel2                   matlab.ui.container.Panel
        ShowAllButton_4               matlab.ui.control.Button
        ConnectAllButton_4            matlab.ui.control.Button
        WgSubPanel1                   matlab.ui.container.Panel
        RgSubPanel1                   matlab.ui.container.Panel
        ScopeSubPanel1                matlab.ui.container.Panel
        WaveformGeneratorPanel        matlab.ui.container.Panel
        WgSubPanel2                   matlab.ui.container.Panel
        WaveformListEditorButton      matlab.ui.control.Button
        ShowAllButton                 matlab.ui.control.Button
        ConnectAllButton              matlab.ui.control.Button
        RFGeneratorPanel              matlab.ui.container.Panel
        RgSubPanel2                   matlab.ui.container.Panel
        ShowAllButton_2               matlab.ui.control.Button
        ConnectAllButton_2            matlab.ui.control.Button
        ScopePanel                    matlab.ui.container.Panel
        ScopeSubPanel2                matlab.ui.container.Panel
        ShowAllButton_3               matlab.ui.control.Button
        ConnectAllButton_3            matlab.ui.control.Button
        VariablesPanel                matlab.ui.container.Panel
        SearchVariableButton          matlab.ui.control.Button
        SearchVariableEditField       matlab.ui.control.EditField
        RepeatSpinner                 matlab.ui.control.Spinner
        RepeatSpinnerLabel            matlab.ui.control.Label
        AutoShuffleCheckBox           matlab.ui.control.CheckBox
        UploadButton                  matlab.ui.control.Button
        TriggerSourceButtonGroup      matlab.ui.container.ButtonGroup
        ManualButton                  matlab.ui.control.RadioButton
        BecExpButton                  matlab.ui.control.RadioButton
        ScanButton                    matlab.ui.control.Button
        LockVariableButton            matlab.ui.control.Button
        ScanMethodButtonGroup         matlab.ui.container.ButtonGroup
        CrossButton                   matlab.ui.control.RadioButton
        DotButton                     matlab.ui.control.RadioButton
        EditListDropDown              matlab.ui.control.DropDown
        EditListLabel                 matlab.ui.control.Label
        AddVariableButton             matlab.ui.control.Button
        Panel_3                       matlab.ui.container.Panel
        VarGrid                       matlab.ui.container.GridLayout
    end


    properties (Access = public)
        HardwareList % Store hardware's Name and Type
        HardwareSetting HardwareSetting
        RunningWgControl WgControlPanel
        RunningScopeControl ScopeControlPanel
        RunningPhaseLockControl PhaseLockControlPanel
        RunningWaveformListEditor WaveformListEditor
        VariableList VariableList % Store variable's Name, Value, List, Equation and EquationValue
        ListList ListList % Store Lists for scan
        ScanList table % Store variable's Name, Value, List, Equation, EquationValue and ListValue
        CurrentRunNumber double = 1
        CurrentVariableList table % Store runtime variable's Name and Value. Updated based on ListValue and Equation.
        IsBecExpScanned logical
        WaveformLibrary WaveformLibrary
        WaveformListLibrary WaveformListLibrary
        NWg double = 0
        NScope double = 0
        NPl double = 0
        WgID int64
        ScopeID int64
        PlID int64
    end
    
    properties (Access = private)
        IsEquationCheck logical % Description
    end

    properties (Constant)
        VarColumn = 5
        VarPrefix = "hw_"
        EquationDepth = 5
    end

    methods (Access = private)

        function addVar(app,name,value,list)
            name = string(name);
            list = string(list);
            app.VarGrid.RowHeight{end + 1} = 20;
            uieditfield(app.VarGrid,'text','Value',name,'ValueChangedFcn',{@app.nameChanged});
            v = uieditfield(app.VarGrid, 'numeric','Value',value,'ValueChangedFcn',{@app.valueChanged});
            l = uidropdown(app.VarGrid,'Items',{'None','List1','List2','List3','List4','List5','List6','List7','List8','List9','List10'},...
                'Value',list,'ValueChangedFcn',{@app.listDropDownChanged});
            eq = uibutton(app.VarGrid, 'push','Text','Equation','ButtonPushedFcn',{@app.equationButtonPress});
            uibutton(app.VarGrid, 'push','Text','Delete','ButtonPushedFcn',{@app.deleteButtonPress});
            if list ~= "None"
                v.Enable = "off";
                eq.Enable = "off";
            end
            if app.VariableList.readValue(name,"Equation","Name") ~= "None"
                v.Enable = "off";
                l.Enable = "off";
            end
        end

        function listDropDownChanged(app,src,~)
            row = src.Layout.Row;
            nColumn = app.VarColumn;
            varName = string(app.VarGrid.Children((row-1)*nColumn + 1).Value);
            app.VariableList.updateValue(varName,"List",string(src.Value),"Name");
            app.buildScanList;
            app.evaluateEquation;
            if string(src.Value) ~= "None"
                app.VarGrid.Children(row*app.VarColumn - 1).Enable = "off";
                app.VarGrid.Children(row*app.VarColumn - 3).Enable = "off";
            else
                app.VarGrid.Children(row*app.VarColumn - 1).Enable = "on";
                app.VarGrid.Children(row*app.VarColumn - 3).Enable = "on";
            end
        end

        function deleteButtonPress(app,src,~)
            nRow = numel(app.VarGrid.RowHeight);
            nColumn = app.VarColumn;
            row = src.Layout.Row;
            varName = string(app.VarGrid.Children((row-1)*nColumn + 1).Value);
            msg = app.checkVariableBound(varName);
            if msg ~= ""
                msg = varName + " is bound to:" + newline + msg + "Please resolve then delete.";
                msgbox(msg);
                return
            else
                answer = questdlg("Do you want to delete the variable [" + varName + "]?", ...
                	'Confirm', ...
                    'Yes','No','No');
                if string(answer) == "No"
                    return
                end
            end
            for ii = (row+1):nRow
                for jj = 1:nColumn
                    app.VarGrid.Children((ii-1)*nColumn + jj).Layout.Row = ...
                        app.VarGrid.Children((ii-1)*nColumn + jj).Layout.Row - 1;
                end
            end
            permuteIdx = [1:(row-1)*nColumn,(row*nColumn+1):nRow*nColumn,((row-1)*nColumn+1):row*nColumn];
            app.VarGrid.Children = app.VarGrid.Children(permuteIdx);
            delete(app.VarGrid.Children((end-nColumn+1) : end))
            app.VarGrid.RowHeight(end) = [];
            app.VariableList.deleteEntry(varName,"Name");
            app.buildScanList
            app.evaluateEquation
        end

        function equationButtonPress(app,src,~)
            row = src.Layout.Row;
            nColumn = app.VarColumn;
            varName = string(app.VarGrid.Children((row-1)*nColumn + 1).Value);
            eq = app.VariableList.readValue(varName,"Equation","Name");
            if eq == "None"
                answer = inputdlg("Enter equation for [" + varName + "], remember to put space between operators.","Equation",[6,30]);
            else
                answer = inputdlg("Enter equation for [" + varName + "], remember to put space between operators.","Equation",[6,30],eq);
            end
            if size(answer,1) == 0
                return
            end
            if isempty(answer)
                answer = "";
            end
            answer = string(answer);
            app.VariableList.updateValue(varName,"Equation",answer,"Name")
            if answer == ""
                val = app.VariableList.readValue(varName,"DefaultValue","Name");
                app.VariableList.updateValue(varName,"CurrentValue",val,"Name")
                app.VarGrid.Children((row-1)*nColumn + 2).Value = val;
                app.VarGrid.Children(row*nColumn - 2).Enable = "on";
                app.VarGrid.Children(row*nColumn - 3).Enable = "on";
            else
                app.VarGrid.Children(row*nColumn - 2).Enable = "off";
                app.VarGrid.Children(row*nColumn - 3).Enable = "off";
            end
            app.evaluateEquation
        end

        function valueChanged(app,src,~)
            row = src.Layout.Row;
            nColumn = app.VarColumn;
            varName = string(app.VarGrid.Children((row-1)*nColumn + 1).Value);
            app.VariableList.updateValue(varName,"DefaultValue",src.Value,"Name")
            app.VariableList.updateValue(varName,"CurrentValue",src.Value,"Name")
            app.buildScanList;
            app.evaluateEquation;
        end

        function nameChanged(app,src,evt)
            name = string(src.Value);
            nameOld = string(evt.PreviousValue);

            % Add prefix
            if ~startsWith(name,app.VarPrefix)
                name = app.VarPrefix + name;
            end

            % Check name conflicts
            if any(app.VariableList.readColumn("Name") == name)
                warning("[" + name + "] has been used.")
                src.Value = nameOld;
                return
            end

            % Rename equations
            eq = app.VariableList.readColumn(["ID","Equation"]);
            eqIdx = eq.Equation ~= "None";
            if any(eqIdx)
                eq(eqIdx,:).Equation = replace(eq(eqIdx,:).Equation,nameOld + " ",name + " "); % the space here is critical for not accidentaly replace a short name
                app.VariableList.updateValue(eq.ID(eqIdx),"Equation",eq.Equation(eqIdx))
            end

            % Rename
            app.VariableList.updateValue(nameOld,"Name",name,"Name");
            src.Value = name;

            % Rename BecExp
            p = BecExpVariableUnit;
            p.updateValue(nameOld,"ScannedVariable",name,"ScannedVariable")
            becApp = get(findall(0, 'Tag', "BecControl"), 'RunningAppInstance');
            if ~isempty(becApp)
                becApp.resetVariableDropDown;
            end

            % Rename PhaseLock variable
            rpl = app.RunningPhaseLockControl;
            if ~isempty(rpl)
                for ii = 1:numel(rpl)
                    rpl(ii).updateDisplay
                end
            end


            app.buildScanList
            app.evaluateEquation
        end

        function lockVariable(app)
            for ii = 1:numel(app.VarGrid.Children)
                app.VarGrid.Children(ii).Enable = "off";
            end
            app.EditListDropDown.Enable = "off";
            app.AddVariableButton.Enable = "off";
        end

        function unlockVariable(app)
            % Potentially, this has problem if VariableList and the GUI
            % have different variable orders.
            nColumn = app.VarColumn;
            varList = app.VariableList.readColumn(["Equation","List"]);
            eqIdx = find(varList.Equation ~= "None");
            listIdx = find(varList.List ~= "None");
            for ii = 1:numel(app.VarGrid.Children)
                app.VarGrid.Children(ii).Enable = "on";
            end
            if ~isempty(eqIdx)
                [app.VarGrid.Children(eqIdx * nColumn - 3).Enable] = deal("off");
                [app.VarGrid.Children(eqIdx * nColumn - 2).Enable] = deal("off");
            end
            if ~isempty(listIdx)
                [app.VarGrid.Children(listIdx * nColumn - 3).Enable] = deal("off");
                [app.VarGrid.Children(listIdx * nColumn - 1).Enable] = deal("off");
            end
            app.EditListDropDown.Enable = "on";
            app.AddVariableButton.Enable = "on";
        end

        function upload(app)
            % Reset run number
            if string(app.ScanButton.Text) == "Start"
                app.CurrentRunNumber = 1;
            end
            
            % Check equation
            app.evaluateEquation
            if ~app.IsEquationCheck
                app.disableUpload
                error("Can not upload. Please fix equation errors.")
            else
                app.enableUpload
            end

            % Save variable values in to the runtime property CurrentVariableList
            varList = app.VariableList.readColumn(["Name","List","CurrentValue"]);
            scanningVar = varList(varList.List ~= "None",:).Name;

            % Display
            if ~isempty(scanningVar)
                disp(newline)
                disp("Run #" + num2str(app.CurrentRunNumber) + "(out of " + num2str(size(app.ScanList.ListValue,2)) + " total runs)")
                disp(varList(ismember(varList.Name,scanningVar),:))
            else
                disp("No variable bound to any lists.")
            end

            % Reset BecExp hardware log number
            if app.IsBecExpScanned
                becApp = get(findall(0, 'Tag', "BecControl"), 'RunningAppInstance');
                if ~isempty(becApp) && isvalid(becApp)
                    becExp = becApp.BecExp;
                    if ~isempty(becExp) && isvalid(becExp)
                        becExp.coutExistedHardwareLog
                    end
                end
            end

            % Upload to awg
            for ii = 1:numel(app.RunningWgControl)
                app.RunningWgControl(ii).upload;
            end

            % Phase Lock
            if string(app.ScanButton.Text) == "Stop"
                if app.CurrentRunNumber == 1
                    app.lock
                else
                    rPl = app.RunningPhaseLockControl;
                    if ~isempty(rPl)
                        for ii = 1:numel(rPl)
                            plVar = string(rPl(ii).VariableDropDown.Value);
                            oldfreq=rPl(ii).PhaseLock.CurrentFrequency;
                            if plVar ~= "None"
                                newfreq=app.VariableList.readValue(plVar, "CurrentValue", "Name");
                            else
                                newfreq=oldfreq;
                            end
                            
                            if ismember(plVar,scanningVar)
                                rPl(ii).lock
                            elseif oldfreq~=newfreq
                                rPl(ii).lock;

                            end
                            
                        end
                    end
                end
            else
                app.lock
            end

            % Scope
            if string(app.ScanButton.Text) == "Stop"
                if app.CurrentRunNumber == 1
                    app.setScope
                end
            else
                app.setScope
            end

            % WaveformListEditor
            if ~isempty(app.RunningWaveformListEditor)
                if isvalid(app.RunningWaveformListEditor)
                    app.RunningWaveformListEditor.plot
                end
            end
        end

        function disableButton(app)
            for ii = 1:numel(app.RunningWgControl)
                if isvalid(app.RunningWgControl(ii))
                    app.RunningWgControl(ii).disableButton;
                end
            end
        end

        function enableButton(app)
            for ii = 1:numel(app.RunningWgControl)
                if isvalid(app.RunningWgControl(ii))
                    app.RunningWgControl(ii).enableButton;
                end
            end
        end
    end

    methods (Access = public)
        function connectButtonPress(app,src,~,id)
            if string(app.ScanButton.Text) == "Stop"
                return
            end
            hwType = app.HardwareList.readValue(id,"Type");
            if string(src.Text) == "Connect"
                switch hwType
                    case "WaveformGenerator"
                        app.RunningWgControl(end+1) = WgControlPanel(id,app);
                        if numel(app.RunningWgControl) == app.NWg
                            app.ConnectAllButton.Text = "Disconnect All";
                        end
                    case "Scope"
                        app.RunningScopeControl(end+1) = ScopeControlPanel(id,app);
                        if numel(app.RunningScopeControl) == app.NScope
                            app.ConnectAllButton_3.Text = "Disconnect All";
                        end
                    case "PhaseLock"
                        app.RunningPhaseLockControl(end+1) = PhaseLockControlPanel(id,app);
                        if numel(app.RunningPhaseLockControl) == app.NPl
                            app.ConnectAllButton_4.Text = "Disconnect All";
                        end
                end
                src.Text = 'Disconnect';
            else
                switch hwType
                    case "WaveformGenerator"
                        validWg = app.RunningWgControl(isvalid(app.RunningWgControl));
                        validWg([validWg.ID] == id).disconnect
                        app.RunningWgControl = app.RunningWgControl(isvalid(app.RunningWgControl));
                        if isempty(app.RunningWgControl)
                            app.ConnectAllButton.Text = "Connect All";
                        end
                    case "Scope"
                        validScope = app.RunningScopeControl(isvalid(app.RunningScopeControl));
                        validScope([validScope.ID] == id).disconnect
                        app.RunningScopeControl = app.RunningScopeControl(isvalid(app.RunningScopeControl));
                        if isempty(app.RunningScopeControl)
                            app.ConnectAllButton_3.Text = "Connect All";
                        end
                    case "PhaseLock"
                        validPl = app.RunningPhaseLockControl(isvalid(app.RunningPhaseLockControl));
                        validPl([validPl.ID] == id).disconnect
                        app.RunningPhaseLockControl = app.RunningPhaseLockControl(isvalid(app.RunningPhaseLockControl));
                        if isempty(app.RunningPhaseLockControl)
                            app.ConnectAllButton_4.Text = "Connect All";
                        end
                end
                src.Text = 'Connect';
            end
        end

        function showWindowButtonPress(app,~,~,id)
            hwType = app.HardwareList.readValue(id,"Type");
            switch hwType
                case "WaveformGenerator"
                    validWg = app.RunningWgControl(isvalid(app.RunningWgControl));
                    if ~isempty(validWg)
                        ids = [validWg.ID];
                        if any(ismember(ids,id))
                            validWg([validWg.ID] == id).WaveformGeneratorControlPanelUIFigure.Visible = 'on';
                            focus(validWg([validWg.ID] == id).WaveformGeneratorControlPanelUIFigure);
                        end
                    end
                case "Scope"
                    validScope = app.RunningScopeControl(isvalid(app.RunningScopeControl));
                    if ~isempty(validScope)
                        ids = [validScope.ID];
                        if any(ismember(ids,id))
                            validScope([validScope.ID] == id).ScopeControlPanelUIFigure.Visible = 'on';
                            focus(validScope([validScope.ID] == id).ScopeControlPanelUIFigure);
                        end
                    end
                case "PhaseLock"
                    validPl = app.RunningPhaseLockControl(isvalid(app.RunningPhaseLockControl));
                    if ~isempty(validPl)
                        ids = [validPl.ID];
                        if any(ismember(ids,id))
                            validPl([validPl.ID] == id).PhaseLockControlPanelUIFigure.Visible = 'on';
                            focus(validPl([validPl.ID] == id).PhaseLockControlPanelUIFigure)
                        end
                    end
            end
        end

        function readScope(app)
            for ii = 1:numel(app.RunningScopeControl)
                app.RunningScopeControl(ii).read
            end
        end

        function setScope(app)
            rSc = app.RunningScopeControl;
            for ii = 1:numel(rSc)
                rSc(ii).set
            end
        end

        function update(app)
            if app.IsBecExpScanned
                nRun = size(app.ScanList.ListValue,2);
                runNumber = app.CurrentRunNumber;
                if runNumber < nRun
                    app.CurrentRunNumber = runNumber + 1;
                    app.upload;
                elseif runNumber == nRun
                    app.ScanButtonPushed;
                end
            end
        end

        function lock(app)
            rPl = app.RunningPhaseLockControl;
            if ~isempty(rPl)
                for ii = 1:numel(rPl)
                    if isvalid(rPl(ii))
                        rPl(ii).lock
                    end
                end
            end
        end

        function unlock(app)
            rPl = app.RunningPhaseLockControl;
            if ~isempty(rPl)
                for ii = 1:numel(rPl)
                    if isvalid(rPl(ii))
                        rPl(ii).unlock
                    end
                end
            end
        end

        function setAssociation(app,hwId)
            if isempty(hwId)
                return
            end

            %% If it's scanning, stop it
            isScan = app.ScanButton.Text == "Stop";
            if isScan
                app.ScanButtonPushed;
            end

            %% Connect to hardwares
            for ii = 1:numel(hwId)
                hwType = app.HardwareList.readValue(hwId(ii),"Type");
                switch hwType
                    case "WaveformGenerator"
                        if ~ismember(hwId(ii),[app.RunningWgControl.ID])
                            rowNumber = find(app.WgID == hwId(ii));
                            gridChild = app.WgSubPanel1.Children.Children;
                            app.connectButtonPress(gridChild(rowNumber*3-1),1,hwId(ii));
                            app.hideWindow(hwId(ii),"WaveformGenerator")
                        end
                        hwapp = app.RunningWgControl([app.RunningWgControl.ID] == hwId(ii));
                    case "Scope"
                        if ~ismember(hwId(ii),[app.RunningScopeControl.ID])
                            rowNumber = find(app.ScopeID == hwId(ii));
                            gridChild = app.ScopeSubPanel1.Children.Children;
                            app.connectButtonPress(gridChild(rowNumber*3-1),1,hwId(ii));
                            app.hideWindow(hwId(ii),"Scope")
                        end
                        hwapp = app.RunningScopeControl([app.RunningScopeControl.ID] == hwId(ii));
                    case "PhaseLock"
                        if ~ismember(hwId(ii),[app.RunningPhaseLockControl.ID])
                            rowNumber = find(app.PlID == hwId(ii));
                            gridChild = app.PlSubPanel1.Children.Children;
                            app.connectButtonPress(gridChild(rowNumber*3-1),1,hwId(ii));
                        end
                        hwapp = app.RunningPhaseLockControl([app.RunningPhaseLockControl.ID] == hwId(ii));
                        app.hideWindow(hwId(ii),"PhaseLock")
                end
                hwapp.updateDisplay;
            end

            %% Restart scan or upload
            if isScan
                app.ScanButtonPushed;
            else
                app.UploadButtonPushed;
            end

        end
        
        function dispMsg = checkVariableBound(app,varName)
            dispMsg = "";
            varID = app.VariableList.readValue(varName,"ID","Name");

            % Check WaveformList
            wflID = unique(app.WaveformLibrary.checkVariableBound(varID));
            if ~isempty(wflID)
                wflName = app.WaveformListLibrary.readValue(wflID,"Name");
                dispMsg = dispMsg + join("WaveformList [" + wflName +"]",newline) + newline;
            end

            % Check BecExp Trials
            p = BecExpSetting;
            trialName = unique([p.readValue(varID,"TrialName","ScannedVariableID");...
                p.readValue(varID,"TrialName","ScannedVariableID2")]);
            if ~isempty(trialName)
                dispMsg = dispMsg + join("Trial [" + trialName + "]",newline) + newline;
            end
            
            % Check HardwareSetting
            t = app.HardwareSetting.readValue(varID,["HardwareID","Name","ChannelNumber"],"VariableID");
            if ~isempty(t)
                if isstruct(t)
                    t = struct2table(t);
                end
                t = renamevars(t,["Name","HardwareID"],["SettingName","ID"]);
                hwName = app.HardwareList.readValue(t.ID,["ID","Name"]);
                if isstruct(hwName)
                    hwName = struct2table(hwName);
                end
                t = join(t,hwName,"Keys","ID");
                dispMsg = dispMsg + join("Hardware [" + t.Name + "]" + " Channel " + t.ChannelNumber + ...
                    " [" + t.SettingName + "]",newline) + newline;
            end

            % Check HardwareAssociation
            ha = HardwareAssociation;
            trialId = unique(ha.readValue(varID,"TrialID","VariableID"));
            if ~isempty(trialId)
                trialName = p.readValue(trialId,"TrialName");
                dispMsg = dispMsg + join("Hardware association for Trial [" + trialName + "]",newline) + newline;
            end
        end
        
        function evaluateEquation(app)
            %% Get constants and find equation index
            varList = app.VariableList.readColumn(["ID","Name","DefaultValue","Equation","CurrentValue"]);
            initialVal = varList.CurrentValue;
            nColumn = app.VarColumn;
            idx = varList.Equation ~= "None";
            eqIdx = find(idx);
            neqIdx = find(~idx);

            %% Toggle gui components' color
            if ~isempty(neqIdx)
                % for non-equation variables, make the button background
                % grey
                [app.VarGrid.Children((neqIdx-1)*nColumn + 4).BackgroundColor] = deal([0.96,0.96,0.96]);
            end
            if ~isempty(eqIdx)
                % for equation variables, make the buttont background green
                [app.VarGrid.Children((eqIdx-1)*nColumn + 4).BackgroundColor] = deal([0.3922 0.8314 0.0745]);
            end

            %% Assign initial values into val
            if isempty(app.ScanList)
                val = varList.DefaultValue; % If no scan, use the default value
            else
                val = app.ScanList.ListValue(:,app.CurrentRunNumber); % If scan, use the current value from ScanList
            end
            val(eqIdx) = deal(NaN); % For equation-variables, assign the initial value to be NaN

            %% Rewrite equatioons so we can evaluate them
            eq = varList.Equation(eqIdx); % Equation list
            [~,idx] = sort(strlength(varList.Name),'descend'); % Sort variable names according to length
            eq = replace(eq,varList.Name(idx),"val("+string(idx)+")"); %Always match the longest variable first to avoid matching sub-string

            %% Evaluate the equations and update the values for multiple times
            for ii = 1:app.EquationDepth
                val(eqIdx) = arrayfun(@(x) evalEquation(x,val),eq);
            end   

            %% Check and see if evaluation failed
            val(imag(val)~=0) = deal(NaN); % We don't want imaginary values for now
            val(isinf(val)) = deal(NaN); % We don't want infinity
            varList.CurrentValue = val; % Assign output to VariableList
            invalidEqIdx = eqIdx(isnan(val(eqIdx))); % Invalid output index

            if ~isempty(invalidEqIdx)
                % Make the invalid variable's equation button red
                [app.VarGrid.Children((invalidEqIdx-1)*nColumn + 4).BackgroundColor] = deal([0.85,0.33,0.10]);
                app.IsEquationCheck = false;
                app.disableUpload
            else
                app.IsEquationCheck = true;
                app.enableUpload
            end

            %% Update values in gui
            validEqIdx = eqIdx(~isnan(val(eqIdx)));
            validVal = num2cell(val(validEqIdx));
            if ~isempty(validEqIdx)
                [app.VarGrid.Children((validEqIdx-1)*nColumn + 2).Value] = validVal{:};
            end
            if ~isempty(neqIdx)
                neVal = num2cell(val(neqIdx));
                [app.VarGrid.Children((neqIdx-1)*nColumn + 2).Value] = neVal{:};
            end

            %% Update VariableList table in sqlite
            varList = varList(:,[1,5]); % Only update current values using ID
            varList = varList(varList.CurrentValue ~= initialVal,:); % Only update entries that has been changed.
            varList(isnan(varList.CurrentValue),:) = []; % Delete nan
            if ~isempty(varList)
                app.VariableList.updateEntry(varList);
            end

            %% Local function for equation evaluation
            function out = evalEquation(eq,val)
                try 
                    out = eval(eq);
                catch
                    out = NaN;
                end
            end
        end
        
        function buildScanList(app)
            %% Prepare, get constants, and initialize
            % No ScanList if there is not enabledList
            varList = app.VariableList.readColumn(["DefaultValue","List"]);
            enabledVarList = varList(varList.List~="None",:); % List of variables that bound to a list
            if isempty(enabledVarList)
                app.ScanList = [];
                return
            end
            
            % Get eabled list information
            listList = app.ListList.readColumn(["List","ListValue"]); % Get ListList but remove its ID column
            listName = unique(enabledVarList.List); % Name of enabled lists, e.g., "List1"
            enabledListList = app.ListList.readValue(listName,["List","ListValue"],"List"); % Name and ListValue of enabled lists
            if isstruct(enabledListList)
                sz = numel(enabledListList.ListValue);
            else
                sz = cellfun(@numel,enabledListList.ListValue);
            end
            nRepeat = app.RepeatSpinner.Value;

            % Throw error if any bound Lists are empty
            if any(sz==0)
                app.disableUpload
                error("Can not bind variables to empty lists. Please fix.")
            else
                app.enableUpload
            end

            % initialize ScanList by joining VariableList and ListList
            app.ScanList = join(varList,listList,'Keys',{'List','List'});

            %% Update ListValue for scanned variables based on the scan method
            if numel(sz) == 1 || app.ScanMethodButtonGroup.SelectedObject == app.DotButton
                % Dot scan
                if ~all(sz == sz(1))
                    app.disableUpload
                    error('In dot scan, the list sizes must be the same.')
                else
                    app.enableUpload
                end
                nRun = sz(1);
            elseif numel(sz) == 2
                % Cross scan
                [list1,list2] = meshgrid(enabledListList.ListValue{1},enabledListList.ListValue{2});
                list1 = {list1(:).'};
                list2 = {list2(:).'};
                nRun = numel(list1{1});
                nList1 = size(app.ScanList(app.ScanList.List == enabledListList.List(1),:),1);
                nList2 = size(app.ScanList(app.ScanList.List == enabledListList.List(2),:),1);
                app.ScanList(app.ScanList.List == enabledListList.List(1),:).ListValue = repmat(list1,nList1,1);
                app.ScanList(app.ScanList.List == enabledListList.List(2),:).ListValue = repmat(list2,nList2,1);
                app.enableUpload
            else
                app.disableUpload
                List was not saved correctly('Can not do cross scan with more than two lists.')
            end

            %% Assign ListValue to non-scanned variables
            noneList = app.ScanList(app.ScanList.List == "None",:);
            app.ScanList(app.ScanList.List == "None",:).ListValue = ...
                mat2cell(repmat(noneList.DefaultValue,1,nRun),ones(1,numel(noneList.DefaultValue)),nRun);
            app.ScanList.ListValue = repmat(cell2mat(app.ScanList.ListValue),1,nRepeat); %Repeat

            %% Shuffle
            if app.AutoShuffleCheckBox.Value && string(app.ScanButton.Text) == "Stop"
                idx = randperm(nRepeat*nRun);
                app.ScanList.ListValue = app.ScanList.ListValue(:,idx);
            end
        end
        
        function disableUpload(app)
            app.ScanButton.Enable = "off";
            app.UploadButton.Enable = "off";
        end
        
        function enableUpload(app)
            app.ScanButton.Enable = "on";
            app.UploadButton.Enable = "on";
        end
        
        
        function hideWindow(app,hwId,hwType)
            switch hwType
                case "WaveformGenerator"
                    hwApp = app.RunningWgControl([app.RunningWgControl.ID] == hwId);
                case "Scope"
                    hwApp = app.RunningScopeControl([app.RunningScopeControl.ID] == hwId);
                case "PhaseLock"
                    hwApp = app.RunningPhaseLockControl([app.RunningPhaseLockControl.ID] == hwId);
            end
            if ~isempty(hwApp)
                if isvalid(hwApp)
                    switch hwType
                        case "WaveformGenerator"
                            [hwApp.WaveformGeneratorControlPanelUIFigure.Visible] = deal('off');
                        case "Scope"
                            [hwApp.ScopeControlPanelUIFigure.Visible] = deal('off');
                        case "PhaseLock"
                            [hwApp.PhaseLockControlPanelUIFigure.Visible] = deal('off');
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Initialize MmSetting connections
            app.HardwareList = HardwareList;
            app.VariableList = VariableList;
            app.WaveformLibrary = WaveformLibrary;
            app.WaveformListLibrary = WaveformListLibrary;
            app.ListList = ListList;
            app.HardwareSetting = HardwareSetting;

            %% Initialize Variables
            varList = app.VariableList.readColumn(["Name","DefaultValue","List"]);
            for ii = 1:size(varList,1)
                app.addVar(varList.Name(ii),varList.DefaultValue(ii),varList.List(ii))
            end
            try
                app.buildScanList
                app.evaluateEquation
            catch me
                warning(me.message)
            end

            %% Initialize Waveform Generator
            t = app.HardwareList.readValue("WaveformGenerator",["ID","Name"],"Type");
            if ~isempty(t)
                app.WgID = t.ID;
                app.NWg = numel(app.WgID);
                gl = uigridlayout(app.WgSubPanel1);
                gl.ColumnWidth = {'1x', '1x', '1x'};
                gl.RowHeight = repmat({20},1,app.NWg);
                gl.Scrollable = 'on';
                for ii = 1:app.NWg
                    uilabel(gl, 'Text',t.Name(ii));
                    uibutton(gl, 'push','Text','Connect','ButtonPushedFcn',{@app.connectButtonPress,app.WgID(ii)});
                    uibutton(gl, 'push','Text','Show Window','ButtonPushedFcn',{@app.showWindowButtonPress,app.WgID(ii)});
                end
            end

            %% Initialize Scope
            t = app.HardwareList.readValue("Scope",["ID","Name"],"Type");
            if ~isempty(t)
                app.ScopeID = t.ID;
                app.NScope = numel(app.ScopeID);
                gl = uigridlayout(app.ScopeSubPanel1);
                gl.ColumnWidth = {'1x', '1x', '1x'};
                gl.RowHeight = repmat({20},1,app.NScope);
                gl.Scrollable = 'on';
                for ii = 1:app.NScope
                    uilabel(gl, 'Text',t.Name(ii));
                    uibutton(gl, 'push','Text','Connect','ButtonPushedFcn',{@app.connectButtonPress,app.ScopeID(ii)});
                    uibutton(gl, 'push','Text','Show Window','ButtonPushedFcn',{@app.showWindowButtonPress,app.ScopeID(ii)});
                end
            end

            %% Initialize Phase Lock
            t = app.HardwareList.readValue("PhaseLock",["ID","Name"],"Type");
            if ~isempty(t)
                app.PlID = t.ID;
                app.NPl = numel(app.PlID);
                gl = uigridlayout(app.PlSubPanel1);
                gl.ColumnWidth = {'1x', '1x', '1x'};
                gl.RowHeight = repmat({20},1,app.NPl);
                gl.Scrollable = 'on';
                for ii = 1:app.NPl
                    uilabel(gl, 'Text',t.Name(ii));
                    uibutton(gl, 'push','Text','Connect','ButtonPushedFcn',{@app.connectButtonPress,app.PlID(ii)});
                    uibutton(gl, 'push','Text','Show Window','ButtonPushedFcn',{@app.showWindowButtonPress,app.PlID(ii)});
                end
            end
        end

        % Button pushed function: AddVariableButton
        function AddVariableButtonPushed(app, event)
            n = numel(app.VarGrid.RowHeight);
            nameList = app.VariableList.readColumn("Name");
            ii = 1;
            newName = app.VarPrefix + "var" + num2str(n+1);
            while any(nameList == newName)
                ii = ii + 1;
                newName = app.VarPrefix + "var" + num2str(n+ii);
            end
            app.addVar(newName,0,'None')
            app.VariableList.writeEntry(table(newName,'VariableNames',{'Name'}));
            app.buildScanList
        end

        % Clicked callback: EditListDropDown
        function EditListDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if ~isempty(item)
                ListDialog("List" + num2str(item),app)
            end
        end

        % Close request function: HardwareControlPanelUIFigure
        function HardwareControlPanelUIFigureCloseRequest(app, event)
            rWg = app.RunningWgControl;
            rScope = app.RunningScopeControl;
            rPl = app.RunningPhaseLockControl;
            if ~isempty(rWg)
                rWg = rWg(isvalid(rWg));
                for ii = 1:numel(rWg)
                    rWg(ii).disconnect;
                    delete(rWg(ii));
                end
            end
            if ~isempty(rScope)
                rScope = rScope(isvalid(rScope));
                for ii = 1:numel(rScope)
                    rScope(ii).disconnect;
                    delete(rScope(ii));
                end
            end
            if ~isempty(rPl)
                rPl = rPl(isvalid(rPl));
                for ii = 1:numel(rPl)
                    rPl(ii).disconnect;
                    delete(rPl(ii));
                end
            end
            delete(app)
        end

        % Button pushed function: LockVariableButton
        function LockVariableButtonPushed(app, event)
            if string(app.LockVariableButton.Text) == "Lock Variable"
                app.lockVariable;
                app.LockVariableButton.Text = "Unlock Variable";
            else
                app.unlockVariable;
                app.LockVariableButton.Text = "Lock Variable";
            end
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            %% Set buttons
            if string(app.ScanButton.Text) == "Stop"
                app.ScanButton.Text = "Scan";
                app.ScanButton.BackgroundColor = [0.3922 0.8314 0.0745];
                app.unlockVariable;
                app.UploadButton.Enable = "on";
                app.LockVariableButton.Enable = "on";
                app.ScanMethodButtonGroup.Enable = "on";
                app.TriggerSourceButtonGroup.Enable = "on";
                app.WaveformListEditorButton.Enable = "on";
                app.IsBecExpScanned = false;
                app.AutoShuffleCheckBox.Enable = "on";
                app.RepeatSpinner.Enable = "on";
                app.CurrentRunNumber = 1;
                app.enableButton;
                return
            end
            varList = app.VariableList.readColumn("List");
            if all(varList == "None")
                return
            end
            app.ScanButton.Text = 'Stop';
            app.ScanButton.BackgroundColor = [0.85,0.33,0.10];
            app.lockVariable;
            app.LockVariableButton.Enable = "off";
            app.ScanMethodButtonGroup.Enable = "off";
            app.TriggerSourceButtonGroup.Enable = "off";
            app.WaveformListEditorButton.Enable = "off";
            app.AutoShuffleCheckBox.Enable = "off";
            app.RepeatSpinner.Enable = "off";
            app.disableButton;
            if app.TriggerSourceButtonGroup.SelectedObject == app.BecExpButton
                app.UploadButton.Enable = "off";
                app.IsBecExpScanned = true;
            end

            %% Build Scan
            app.buildScanList

            %% Upload the first one
            app.upload;
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            if string(app.ScanButton.Text) == "Stop" && app.TriggerSourceButtonGroup.SelectedObject == app.ManualButton
                if app.CurrentRunNumber < (size(app.ScanList.ListValue,2) - 1)
                    app.CurrentRunNumber = app.CurrentRunNumber + 1;
                    app.upload;
                elseif app.CurrentRunNumber == (size(app.ScanList.ListValue,2) - 1)
                    app.CurrentRunNumber = app.CurrentRunNumber + 1;
                    app.upload;
                    app.ScanButtonPushed
                end
            else
                app.upload;
            end
        end

        % Button pushed function: WaveformListEditorButton
        function WaveformListEditorButtonPushed(app, event)
            app.RunningWaveformListEditor =  WaveformListEditor(app);
        end

        % Button pushed function: ConnectAllButton
        function ConnectAllButtonPushed(app, event)
            if string(app.ScanButton.Text) == "Stop"
                return
            end
            wgId = app.WgID;
            if string(app.ConnectAllButton.Text) == "Connect All"
                for ii = 1:app.NWg
                    if isempty(app.RunningWgControl) || all([app.RunningWgControl.ID] ~= wgId(ii))
                        app.connectButtonPress(app.WgSubPanel1.Children.Children(ii*3-1),1,wgId(ii))
                    end
                end
                app.ConnectAllButton.Text = "Disconnect All";
            else
                for ii = 1:app.NWg
                    if (~isempty(app.RunningWgControl)) && any([app.RunningWgControl.ID] == wgId(ii))
                        app.connectButtonPress(app.WgSubPanel1.Children.Children(ii*3-1),1,wgId(ii))
                    end
                end
                app.ConnectAllButton.Text = "Connect All";
            end
        end

        % Button pushed function: ConnectAllButton_3
        function ConnectAllButton_3Pushed(app, event)
            sId = app.ScopeID;
            if string(app.ConnectAllButton_3.Text) == "Connect All"
                for ii = 1:app.NScope
                    if isempty(app.RunningScopeControl) || all([app.RunningScopeControl.ID] ~= sId(ii))
                        app.connectButtonPress(app.ScopeSubPanel1.Children.Children(ii*3-1),1,sId(ii))
                    end
                end
                app.ConnectAllButton_3.Text = "Disconnect All";
            else
                for ii = 1:app.NScope
                    if (~isempty(app.RunningScopeControl)) && any([app.RunningScopeControl.ID] == sId(ii))
                        app.connectButtonPress(app.ScopeSubPanel1.Children.Children(ii*3-1),1,sId(ii))
                    end
                end
                app.ConnectAllButton_3.Text = "Connect All";
            end
        end

        % Button pushed function: ShowAllButton
        function ShowAllButtonPushed(app, event)
            for ii = 1:app.NWg
                app.showWindowButtonPress(1,1,app.WgID(ii));
            end
        end

        % Button pushed function: ShowAllButton_3
        function ShowAllButton_3Pushed(app, event)
            for ii = 1:app.NScope
                app.showWindowButtonPress(1,1,app.ScopeID(ii));
            end
        end

        % Button pushed function: ConnectAllButton_4
        function ConnectAllButton_4Pushed(app, event)
            plId = app.PlID;
            if string(app.ConnectAllButton_4.Text) == "Connect All"
                for ii = 1:app.NPl
                    if isempty(app.RunningPhaseLockControl) || all([app.RunningPhaseLockControl.ID] ~= plId(ii))
                        app.connectButtonPress(app.PlSubPanel1.Children.Children(ii*3-1),1,plId(ii))
                    end
                end
                app.ConnectAllButton_4.Text = "Disconnect All";
            else
                for ii = 1:app.NPl
                    if (~isempty(app.RunningPhaseLockControl)) && any([app.RunningPhaseLockControl.ID] == plId(ii))
                        app.connectButtonPress(app.PlSubPanel1.Children.Children(ii*3-1),1,plId(ii))
                    end
                end
                app.ConnectAllButton_4.Text = "Connect All";
            end
        end

        % Button pushed function: ShowAllButton_4
        function ShowAllButton_4Pushed(app, event)
            for ii = 1:app.NPl
                app.showWindowButtonPress(1,1,app.PlID(ii));
            end
        end

        % Selection changed function: ScanMethodButtonGroup
        function ScanMethodButtonGroupSelectionChanged(app, event)
            app.buildScanList
            app.evaluateEquation
        end

        % Button pushed function: SearchVariableButton
        function SearchVariableButtonPushed(app, event)
            value = app.SearchVariableEditField.Value;
            if value == "" || isempty(value)
                return
            end
            nrow = numel(app.VariableList.readColumn("Name"));
            nColumn = app.VarColumn;
            nameList = string({app.VarGrid.Children(((1:nrow)-1)*nColumn + 1).Value});
            [~,sidx] = sort(strlength(nameList),'ascend');
            idx = find(contains(nameList(sidx),value));
            if ~isempty(idx)
                row = sidx(idx(1));
                tbox = app.VarGrid.Children((row-1)*nColumn + 1);
                scroll(app.VarGrid,tbox)
                tbox.BackgroundColor = [0.3922 0.8314 0.0745];
                pause(3)
                tbox.BackgroundColor = [1,1,1];
            end
        end

        % Value changed function: EditListDropDown
        function EditListDropDownValueChanged(app, event)
            value = app.EditListDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HardwareControlPanelUIFigure = uifigure('Visible', 'off');
            app.HardwareControlPanelUIFigure.Name = 'Hardware Control Panel';
            app.HardwareControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @HardwareControlPanelUIFigureCloseRequest, true);
            app.HardwareControlPanelUIFigure.Tag = 'HwControlPanel';
            app.VariablesPanel = uipanel(app.HardwareControlPanelUIFigure);
            app.VariablesPanel.Title = 'Variables';
            app.Panel_3 = uipanel(app.VariablesPanel);
            app.VarGrid = uigridlayout(app.Panel_3);
            app.AddVariableButton = uibutton(app.VariablesPanel, 'push');
            app.AddVariableButton.ButtonPushedFcn = createCallbackFcn(app, @AddVariableButtonPushed, true);
            app.AddVariableButton.Text = 'Add Variable';
            app.EditListLabel = uilabel(app.VariablesPanel);
            app.EditListLabel.Text = 'Edit List:';
            app.EditListDropDown = uidropdown(app.VariablesPanel);
            app.EditListDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.EditListDropDown.ValueChangedFcn = createCallbackFcn(app, @EditListDropDownValueChanged, true);
            app.EditListDropDown.ClickedFcn = createCallbackFcn(app, @EditListDropDownClicked, true);
            app.EditListDropDown.Value = '1';
            app.ScanMethodButtonGroup = uibuttongroup(app.VariablesPanel);
            app.ScanMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScanMethodButtonGroupSelectionChanged, true);
            app.ScanMethodButtonGroup.Title = 'Scan Method';
            app.DotButton = uiradiobutton(app.ScanMethodButtonGroup);
            app.DotButton.Text = 'Dot';
            app.DotButton.Value = true;
            app.CrossButton = uiradiobutton(app.ScanMethodButtonGroup);
            app.CrossButton.Text = 'Cross';
            app.LockVariableButton = uibutton(app.VariablesPanel, 'push');
            app.LockVariableButton.ButtonPushedFcn = createCallbackFcn(app, @LockVariableButtonPushed, true);
            app.LockVariableButton.Text = 'Lock Variable';
            app.ScanButton = uibutton(app.VariablesPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.TriggerSourceButtonGroup = uibuttongroup(app.VariablesPanel);
            app.TriggerSourceButtonGroup.Title = 'Trigger Source';
            app.BecExpButton = uiradiobutton(app.TriggerSourceButtonGroup);
            app.BecExpButton.Text = 'BecExp';
            app.BecExpButton.Value = true;
            app.ManualButton = uiradiobutton(app.TriggerSourceButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.UploadButton = uibutton(app.VariablesPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.AutoShuffleCheckBox = uicheckbox(app.VariablesPanel);
            app.AutoShuffleCheckBox.Text = 'Auto Shuffle';
            app.RepeatSpinnerLabel = uilabel(app.VariablesPanel);
            app.RepeatSpinnerLabel.Text = 'Repeat';
            app.RepeatSpinner = uispinner(app.VariablesPanel);
            app.RepeatSpinner.Limits = [1 Inf];
            app.RepeatSpinner.RoundFractionalValues = 'on';
            app.RepeatSpinner.Value = 1;
            app.SearchVariableEditField = uieditfield(app.VariablesPanel, 'text');
            app.SearchVariableButton = uibutton(app.VariablesPanel, 'push');
            app.SearchVariableButton.ButtonPushedFcn = createCallbackFcn(app, @SearchVariableButtonPushed, true);
            app.SearchVariableButton.Text = 'Search Variable:';
            app.ScopePanel = uipanel(app.HardwareControlPanelUIFigure);
            app.ScopePanel.Title = 'Scope';
            app.ScopeSubPanel2 = uipanel(app.ScopePanel);
            app.ConnectAllButton_3 = uibutton(app.ScopeSubPanel2, 'push');
            app.ConnectAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConnectAllButton_3Pushed, true);
            app.ConnectAllButton_3.Text = 'Connect All';
            app.ShowAllButton_3 = uibutton(app.ScopeSubPanel2, 'push');
            app.ShowAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButton_3Pushed, true);
            app.ShowAllButton_3.Text = 'Show All';
            app.RFGeneratorPanel = uipanel(app.HardwareControlPanelUIFigure);
            app.RFGeneratorPanel.Title = 'RF Generator';
            app.RgSubPanel2 = uipanel(app.RFGeneratorPanel);
            app.ConnectAllButton_2 = uibutton(app.RgSubPanel2, 'push');
            app.ConnectAllButton_2.Text = 'Connect All';
            app.ShowAllButton_2 = uibutton(app.RgSubPanel2, 'push');
            app.ShowAllButton_2.Text = 'Show All';
            app.WaveformGeneratorPanel = uipanel(app.HardwareControlPanelUIFigure);
            app.WaveformGeneratorPanel.Title = 'Waveform Generator';
            app.WgSubPanel2 = uipanel(app.WaveformGeneratorPanel);
            app.ConnectAllButton = uibutton(app.WgSubPanel2, 'push');
            app.ConnectAllButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectAllButtonPushed, true);
            app.ConnectAllButton.Text = 'Connect All';
            app.ShowAllButton = uibutton(app.WgSubPanel2, 'push');
            app.ShowAllButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButtonPushed, true);
            app.ShowAllButton.Text = 'Show All';
            app.WaveformListEditorButton = uibutton(app.WgSubPanel2, 'push');
            app.WaveformListEditorButton.ButtonPushedFcn = createCallbackFcn(app, @WaveformListEditorButtonPushed, true);
            app.WaveformListEditorButton.Text = 'Waveform List Editor';
            app.ScopeSubPanel1 = uipanel(app.HardwareControlPanelUIFigure);
            app.RgSubPanel1 = uipanel(app.HardwareControlPanelUIFigure);
            app.WgSubPanel1 = uipanel(app.HardwareControlPanelUIFigure);
            app.PhaseLock = uipanel(app.HardwareControlPanelUIFigure);
            app.PhaseLock.Title = 'Phase Lock';
            app.PlSubPanel2 = uipanel(app.PhaseLock);
            app.ConnectAllButton_4 = uibutton(app.PlSubPanel2, 'push');
            app.ConnectAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConnectAllButton_4Pushed, true);
            app.ConnectAllButton_4.Text = 'Connect All';
            app.ShowAllButton_4 = uibutton(app.PlSubPanel2, 'push');
            app.ShowAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButton_4Pushed, true);
            app.ShowAllButton_4.Text = 'Show All';
            app.PlSubPanel1 = uipanel(app.HardwareControlPanelUIFigure);
            % Show the figure after all components are created
            app.HardwareControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HardwareControlPanel

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.HardwareControlPanelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.HardwareControlPanelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HardwareControlPanelUIFigure)
        end
    end
end