classdef ListDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UpdateButton         matlab.ui.control.Button
        ShuffleButton        matlab.ui.control.Button
        FillButton           matlab.ui.control.Button
        List1TextArea        matlab.ui.control.TextArea
        List1TextAreaLabel   matlab.ui.control.Label
        StepEditField        matlab.ui.control.NumericEditField
        StepEditFieldLabel   matlab.ui.control.Label
        StopEditField        matlab.ui.control.NumericEditField
        StopEditFieldLabel   matlab.ui.control.Label
        StartEditField       matlab.ui.control.NumericEditField
        StartEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ListName string
        ListList ListList
        SrcApp HardwareControlPanel
    end
    
    methods (Access = private)
        
        function update(app)
            s = struct;
            s.List = app.ListName;
            s.Start = app.StartEditField.Value;
            s.Stop = app.StopEditField.Value;
            s.Step = app.StepEditField.Value;
            listValue = str2double(app.List1TextArea.Value);
            listValue(isnan(listValue)) = [];
            listValue = listValue(:).';
            s.ListValue = listValue;

            app.ListList.updateEntry(s,"List")
            if ~isempty(app.SrcApp)
                app.SrcApp.buildScanList
                app.SrcApp.evaluateEquation
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, listName, srcApp)
            if nargin == 3
                app.SrcApp = srcApp;
            end
            app.ListList = ListList;
            app.ListName = listName;
            app.List1TextAreaLabel.Text = listName;
            s = app.ListList.readEntry(listName,"List");
            if ~isempty(s)
                if ~isempty(s.ListValue)
                    app.List1TextArea.Value = string(num2str(s.ListValue.','%.10f'));
                end
                app.StartEditField.Value = s.Start;
                app.StopEditField.Value = s.Stop;
                app.StepEditField.Value = s.Step;
            else
                
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.update;
        end

        % Button pushed function: FillButton
        function FillButtonPushed(app, event)
            if app.StepEditField.Value == 0
                valueList = app.StartEditField.Value;
            else
                valueList = app.StartEditField.Value : app.StepEditField.Value : app.StopEditField.Value;
            end
            app.List1TextArea.Value = string(valueList);
        end

        % Button pushed function: ShuffleButton
        function ShuffleButtonPushed(app, event)
            if ~isempty(app.List1TextArea.Value)
                app.List1TextArea.Value = app.List1TextArea.Value(randperm(length(app.List1TextArea.Value)));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.update;
                delete(app)
            catch me
                delete(app)
                rethrow(me)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopEditFieldLabel = uilabel(app.UIFigure);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopEditField.Value = 10;
            app.StepEditFieldLabel = uilabel(app.UIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField.Value = 1;
            app.List1TextAreaLabel = uilabel(app.UIFigure);
            app.List1TextAreaLabel.Text = 'List 1';
            app.List1TextArea = uitextarea(app.UIFigure);
            app.FillButton = uibutton(app.UIFigure, 'push');
            app.FillButton.ButtonPushedFcn = createCallbackFcn(app, @FillButtonPushed, true);
            app.FillButton.Text = 'Fill';
            app.ShuffleButton = uibutton(app.UIFigure, 'push');
            app.ShuffleButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleButtonPushed, true);
            app.ShuffleButton.Text = 'Shuffle';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ListDialog(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end