classdef WgControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaveformGeneratorControlPanelUIFigure  matlab.ui.Figure
        UseDDSCheckBox        matlab.ui.control.CheckBox
        ResetButton           matlab.ui.control.Button
        DisconnectButton      matlab.ui.control.Button
        ChannelsPanel         matlab.ui.container.Panel
        ChannelGrid           matlab.ui.container.GridLayout
        UploadButton          matlab.ui.control.Button
        TriggerButton         matlab.ui.control.Button
        MaxSamplingRateLabel  matlab.ui.control.Label
        MemoryLabel           matlab.ui.control.Label
        ResourceNameLabel     matlab.ui.control.Label
        ModelLabel            matlab.ui.control.Label
        ManufacturerLabel     matlab.ui.control.Label
        NameLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        WaveformListLibrary WaveformListLibrary
        HardwareList HardwareList
        HardwareSetting HardwareSetting
    end

    properties (Access = public)
        SrcApp HardwareControlPanel
        WaveformGenerator
        ID int64
        NColumn
        NRow
        IsDDSEnabled logical = 0
    end

    properties (Constant)
        PropertyList = ["IsOutput","SamplingRate","TriggerSource",...
            "TriggerSlope","OutputMode","OutputLoad","WaveformList"]
    end

    methods (Access = private)
        function disableProperty(app)
            % Disable properties that are not supported by this AWG model
            dP = app.WaveformGenerator.DisabledProperty;
            appProp = string(properties(app));
            for ii = 1:numel(dP)
                targetAppProp = appProp(contains(appProp,dP(ii)));
                for jj = 1:numel(targetAppProp)
                    try
                        app.(targetAppProp(jj)).Enable = "off";
                    catch
                    end
                end
            end
            for ii = 1:numel(dP)
                row = find(app.PropertyList == dP(ii)) + 1;
                if ~isempty(row)
                    for jj = 1:app.NColumn
                        obj = findGridChild(app.ChannelGrid,row,jj+1);
                        try
                            obj.Enable = "off";
                        catch
                        end
                    end
                end
            end
        end
    end

    methods (Access = public)

        function channelWfDropDownValueChanged(app,src,evt,cNumber)
            name = string(src.Value);
            if ~ismember(name,string(src.Items))
                src.Value = evt.PreviousValue;
                return
            end

            % Update WaveformGenerator
            if name ~= "None"
                app.WaveformGenerator.WaveformList{cNumber} = app.WaveformListLibrary.loadEntry(name);
            else
                app.WaveformGenerator.WaveformList{cNumber} = [];
            end

            % Update HardwareSetting
            wflId = app.WaveformListLibrary.readValue(name,"ID","Name");
            app.HardwareSetting.updateSettingValue(app.ID,"WaveformList",wflId,cNumber) 
            if app.DDSUsable
                app.UseDDSCheckBox.Enable='on';
            else
                app.UseDDSCheckBox.Value=0;
                app.UseDDSCheckBox.Enable='off';
            end
        end

        function uiValueChanged(app,src,~,cNumber)
            value = src.Value;
            if ischar(value)
                value = string(value);
            end
            row = src.Layout.Row;
            propName = app.PropertyList(row-1);

            % Update WaveformGenerator
            app.WaveformGenerator.(propName)(cNumber) = value;

            % Update HardwareSetting
            app.HardwareSetting.updateSettingValue(app.ID,propName,string(value),cNumber)
            
            % Check if DDS can be enabled
            if app.DDSUsable
                app.UseDDSCheckBox.Enable='on';
            else
                app.UseDDSCheckBox.Value=0;
                app.UseDDSCheckBox.Enable='off';
            end


        end

        function DDSStateChanged(app,~)
            value = app.UseDDSCheckBox.Value;
            if value
                app.IsDDSEnabled=1;
                app.WaveformGenerator.IsDDSEnabled=1;
            else
                app.IsDDSEnabled=0;
                app.WaveformGenerator.IsDDSEnabled=0;
            end
            app.SrcApp.checkDDS;
        end

        function value=DDSUsable(app)
            % Checks for conditions for whether DDS can be used.
            targetoutput=zeros(1,length(app.WaveformGenerator.IsOutput));
            if ~isequal(size(app.WaveformGenerator.IsOutput), size(targetoutput))
                targetoutput=targetoutput';
            end
            targetoutput(1)=1;
            matchchannel=(targetoutput==app.WaveformGenerator.IsOutput);
            waveform=app.WaveformGenerator.WaveformList{1};
            matchconcatmethod=strcmp(waveform.ConcatMethod, 'Simultaneous');
            matchwaveformtype=1;
            for ii=1:length(waveform.WaveformOrigin)
                if ~isa(waveform.WaveformOrigin{ii},'SineWaveModulated')
                    matchwaveformtype=0;
                end
            end
            success= matchchannel & matchconcatmethod & matchwaveformtype;
            if success
                value=1;
            else
                value=0;

            end 
        end

        function disconnect(app)
            if ~isempty(app.WaveformGenerator)
                app.WaveformGenerator.close
            end
            delete(app)
        end

        function upload(app)
            app.updateHardware
            app.WaveformGenerator.set
            app.WaveformGenerator.upload
        end

        function updateHardware(app)
            app.WaveformGenerator = app.HardwareList.updateHardware(app.ID,app.WaveformGenerator);
        end

        function updateDisplay(app)
            for ii = 1:app.WaveformGenerator.NChannel
                for jj = 1:numel(app.PropertyList)
                    val = app.HardwareSetting.readSettingValue(app.ID,app.PropertyList(jj),ii);
                    obj = findGridChild(app.ChannelGrid,jj + 1, ii + 1);
                    if ismember(app.PropertyList(jj),["SamplingRate","IsOutput"])
                        val = str2num(val);
                    elseif app.PropertyList(jj) == "WaveformList"
                        val = app.WaveformListLibrary.readValue(double(val),"Name");
                        if isempty(val)
                            val = "None";
                        end
                    end
                    obj.Value = val;
                end
            end
        end

        function disableButton(app)
            app.ResetButton.Enable = "off";
            app.UploadButton.Enable = "off";
            app.DisconnectButton.Enable = "off";
            child = app.ChannelGrid.Children((app.NRow + 1) :end);
            for ii = 1:numel(child)
                child(ii).Enable = "off";
            end
        end

        function enableButton(app)
            app.ResetButton.Enable = "on";
            if isempty(app.SrcApp)
                app.UploadButton.Enable = "on";
                app.DisconnectButton.Enable = "on";
            end
            child = app.ChannelGrid.Children((app.NRow + 1) :end);
            for ii = 1:numel(child)
                child(ii).Enable = "on";
            end
            app.disableProperty;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, id, srcApp)
            %% Initialize MmParameter connections
            if nargin == 3
                app.SrcApp = srcApp;
                app.WaveformListLibrary = srcApp.WaveformListLibrary;
                app.HardwareList = srcApp.HardwareList;
                app.HardwareSetting = srcApp.HardwareSetting;
                app.DisconnectButton.Enable = 'off';
                app.UploadButton.Enable = 'off';
            else
                app.WaveformListLibrary = WaveformListLibrary;
                app.HardwareList = HardwareList;
                app.HardwareSetting = HardwareSetting;
            end

            %% Load setting
            app.WaveformGenerator = app.HardwareList.loadEntry(id);
            app.WaveformGenerator.connect
            app.ID = id;
            maxSr = app.WaveformGenerator.SamplingRateLimit;
            if isempty(maxSr)
                maxSr = inf;
            end
            app.MaxSamplingRateLabel.Text = app.MaxSamplingRateLabel.Text + string(maxSr);

            %% Display labels
            app.NameLabel.Text = app.NameLabel.Text + app.HardwareList.readValue(id,"Name");
            app.ManufacturerLabel.Text = app.ManufacturerLabel.Text + app.WaveformGenerator.Manufacturer;
            app.ModelLabel.Text = app.ModelLabel.Text + app.WaveformGenerator.Model;
            app.ResourceNameLabel.Text = app.ResourceNameLabel.Text + app.WaveformGenerator.ResourceName;
            app.MemoryLabel.Text = app.MemoryLabel.Text + string(app.WaveformGenerator.Memory);
            
            %% Create UI components
            wflName = app.WaveformListLibrary.readColumn("Name");
            app.NRow = numel(app.PropertyList);
            app.NColumn = app.WaveformGenerator.NChannel;
            app.ChannelGrid.RowHeight = repmat(25,1,app.NRow + 1);
            for ii = 1:app.NRow
                ul = uilabel(app.ChannelGrid,"Text",app.PropertyList(ii));
                ul.Layout.Column = 1;
                ul.Layout.Row = ii+1;
            end
            for ii = 1:app.WaveformGenerator.NChannel
                app.ChannelGrid.ColumnWidth{end + 1} = 150;

                uil = uilabel(app.ChannelGrid,"Text","Ch" + num2str(ii),'HorizontalAlignment','Center');
                uil.Layout.Column = ii + 1;
                uil.Layout.Row = 1;

                uis = uiswitch(app.ChannelGrid, 'slider','ItemsData',[0,1],'Value',app.WaveformGenerator.IsOutput(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uis.Layout.Column = ii + 1;
                uis.Layout.Row = 2;

                uie = uieditfield(app.ChannelGrid,'numeric','Limits',[1,maxSr],'Value',app.WaveformGenerator.SamplingRate(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uie.Layout.Column = ii + 1;
                uie.Layout.Row = 3;
    
                uidd1 = uidropdown(app.ChannelGrid,"Items",["External","Software","Immediate"],'Value',app.WaveformGenerator.TriggerSource(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uidd1.Layout.Column = ii + 1;
                uidd1.Layout.Row = 4;
                
                uidd2 = uidropdown(app.ChannelGrid,"Items",["Rise","Fall"],'Value',app.WaveformGenerator.TriggerSlope(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uidd2.Layout.Column = ii + 1;
                uidd2.Layout.Row = 5;

                uidd3 = uidropdown(app.ChannelGrid,"Items",["Normal","Gated"],'Value',app.WaveformGenerator.OutputMode(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uidd3.Layout.Column = ii + 1;
                uidd3.Layout.Row = 6;

                uidd4 = uidropdown(app.ChannelGrid,"Items",["50","Infinity"],'Value',app.WaveformGenerator.OutputLoad(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uidd4.Layout.Column = ii + 1;
                uidd4.Layout.Row = 7;

                uidd5 = uidropdown(app.ChannelGrid,"Items",["None";wflName],'Editable','on',"ValueChangedFcn",{@app.channelWfDropDownValueChanged,ii});
                uidd5.Layout.Column = ii + 1;
                uidd5.Layout.Row = 8;

                if ~isempty(app.WaveformGenerator.WaveformList{ii})
                    if ismember(app.WaveformGenerator.WaveformList{ii}.Name,string(uidd5.Items))
                        uidd5.Value = app.WaveformGenerator.WaveformList{ii}.Name;
                    else
                        uidd5.Value = "None";
                    end
                else
                    uidd5.Value = "None";
                end

                app.channelWfDropDownValueChanged(uidd5,1,ii) % update the WaveformList of the AWG object
            end
            
            %If DDS compatible create a checkmark to use DDS

            if app.WaveformGenerator.IsDDSCompatible
                app.UseDDSCheckBox.ValueChangedFcn=createCallbackFcn(app, @DDSStateChanged, true);
                app.UseDDSCheckBox.Visible='on';
                app.IsDDSEnabled=0;
                %check that only channel 1 is enabled
                if app.DDSUsable
                    app.UseDDSCheckBox.Enable='on';
                else
                    app.UseDDSCheckBox.Enable='off';
                end
            else
                app.UseDDSCheckBox.Visible='off';

            end

            %% Diable properties
            app.disableProperty;

        end

        % Close request function: WaveformGeneratorControlPanelUIFigure
        function WaveformGeneratorControlPanelUIFigureCloseRequest(app, event)
            if ~isempty(app.SrcApp)
                app.WaveformGeneratorControlPanelUIFigure.Visible = 'off';
            else
                app.disconnect
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnect
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.upload;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.WaveformGenerator.set;
        end

        % Button pushed function: TriggerButton
        function TriggerButtonPushed(app, event)
            if ismethod(app.WaveformGenerator,"trigger")
                app.WaveformGenerator.trigger
            else
                warning("Software trigger is not supported for this device.")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaveformGeneratorControlPanelUIFigure = uifigure('Visible', 'off');
            app.WaveformGeneratorControlPanelUIFigure.Name = 'Waveform Generator Control Panel';
            app.WaveformGeneratorControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @WaveformGeneratorControlPanelUIFigureCloseRequest, true);
            app.WaveformGeneratorControlPanelUIFigure.Tag = 'WgControlPanel';
            app.NameLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.NameLabel.Text = 'Name: ';
            app.ManufacturerLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.ManufacturerLabel.Text = 'Manufacturer: ';
            app.ModelLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.ModelLabel.Text = 'Model: ';
            app.ResourceNameLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.ResourceNameLabel.Text = 'Resource Name: ';
            app.MemoryLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.MemoryLabel.Text = 'Memory: ';
            app.MaxSamplingRateLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.MaxSamplingRateLabel.Text = 'Max Sampling Rate: ';
            app.TriggerButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.TriggerButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerButtonPushed, true);
            app.TriggerButton.Text = 'Trigger';
            app.UploadButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ChannelsPanel = uipanel(app.WaveformGeneratorControlPanelUIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.ChannelGrid = uigridlayout(app.ChannelsPanel);
            app.DisconnectButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ResetButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UseDDSCheckBox = uicheckbox(app.WaveformGeneratorControlPanelUIFigure);
            app.UseDDSCheckBox.Text = 'Use DDS';
            % Show the figure after all components are created
            app.WaveformGeneratorControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WgControlPanel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaveformGeneratorControlPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaveformGeneratorControlPanelUIFigure)
        end
    end
end