classdef FitOverride < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FitOverrideUIFigure  matlab.ui.Figure
        OverrideCheckBox     matlab.ui.control.CheckBox
        ConfirmButton        matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        UITable              matlab.ui.control.Table
        FitFormulaLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        FitData % Description
        SrcApp % Description
    end
    
    methods (Access = public)
        
        function displayOverride(app)
            data = [app.FitData.StartPointOverride;app.FitData.LowerOverride;app.FitData.UpperOverride];
            app.UITable.Data = data;
        end
        
        function saveOverride(app)
            data = app.UITable.Data;
            app.FitData.StartPointOverride = data(1,:);
            app.FitData.LowerOverride = data(2,:);
            app.FitData.UpperOverride = data(3,:);
            app.FitData.IsOverride = app.OverrideCheckBox.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fitData, srcApp)
            app.FitData = fitData;
            if ~isempty(fitData)
                fitData.setDefaultOverride;
                cName = fitData.CoefficientName;
                app.UITable.ColumnName = cName; 
                app.displayOverride;
                app.FitFormulaLabel.Text = app.FitFormulaLabel.Text + fitData.FitFormula;
                app.OverrideCheckBox.Value = fitData.IsOverride;
                if nargin == 3
                    app.SrcApp = srcApp;
                end
            else
                warning("FitData is empty. Can not edit override.")
                app.delete
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.FitData.clearOverride
            app.FitData.setDefaultOverride
            app.displayOverride
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.saveOverride
            if ~isempty(app.SrcApp)
                app.SrcApp.BecExp.refresh("CenterFit")
            end
            app.delete
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete
        end

        % Close request function: FitOverrideUIFigure
        function FitOverrideUIFigureCloseRequest(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to save the changes?");
            delete(f)
            answer = string(answer);
            if answer == "Yes"
                app.saveOverride
                if ~isempty(app.SrcApp)
                    app.SrcApp.BecExp.refresh("CenterFit")
                end
                delete(app)
            elseif answer == "No"
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FitOverrideUIFigure = uifigure('Visible', 'off');
            app.FitOverrideUIFigure.Name = 'Fit Override';
            app.FitOverrideUIFigure.CloseRequestFcn = createCallbackFcn(app, @FitOverrideUIFigureCloseRequest, true);
            app.FitOverrideUIFigure.Tag = 'Fit Override';
            app.FitFormulaLabel = uilabel(app.FitOverrideUIFigure);
            app.FitFormulaLabel.Text = 'Fit Formula: ';
            app.UITable = uitable(app.FitOverrideUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {'StartPoint'; 'Lower'; 'Upper'};
            app.UITable.ColumnEditable = true;
            app.ResetButton = uibutton(app.FitOverrideUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CancelButton = uibutton(app.FitOverrideUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ConfirmButton = uibutton(app.FitOverrideUIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.OverrideCheckBox = uicheckbox(app.FitOverrideUIFigure);
            app.OverrideCheckBox.Text = 'Override?';
            % Show the figure after all components are created
            app.FitOverrideUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FitOverride(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FitOverrideUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FitOverrideUIFigure)
        end
    end
end