classdef WaveformEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        GridLayout  matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        SrcApp
        PropertyNameList string
        IsDatabase logical = false % Whether we are operating a Waveform or a database entry in WaveformLibrary
        ParameterAll struct % Save all information of a waveform. Read from the WaveformLibrary table
    end
    
    properties (Access = public)
        Waveform
        WaveformLibrary WaveformLibrary
        WaveformListLibrary WaveformListLibrary
        VariableList VariableList
    end

    properties(Constant)
        PositiveParameter = ["Frequency","StartFrequency","StopFrequency"]
        ModulationParameter = ["AmplitudeModulation","FrequencyModulation","PhaseModulation"];
        NonnegativeParameter = ["Duration","RiseTime","FallTime","RampTime"]
        ExceptionWaveform = ["LinearRamp","PchipRamp","TanhRamp"]
    end
    
    methods (Access = private)
        
        function parameterEditFieldValueChanged(app,src,~)
            row = src.Layout.Row;
            if ~app.IsDatabase
                app.Waveform.(app.PropertyNameList(row)) = src.Value;
            else
                % It changes the default value if the edit field is changed
                app.ParameterAll.Parameter(row,:).DefaultValue = src.Value;
            end
        end

        function variableDropDownValueChanged(app,src,evt)
            row = src.Layout.Row;
            val = string(src.Value);

            % Get Variable ID from VariableList
            if val == "None"
                id = 0;
            elseif ~ismember(val,string(src.Items))
                src.Value = evt.PreviousValue;
                return
            else
                id = app.VariableList.readValue(val,"ID","Name");
            end
            paraName = app.PropertyNameList(row);

            % Update Waveform or ParameterAll
            if id == 0
                app.GridLayout.Children(3*row - 1).Enable = "on";
                if ~app.IsDatabase
                    app.Waveform.Scan(app.Waveform.Scan.Name == paraName,:) = [];
                else
                    % The display value will change back to the default
                    % value of the parameter
                    app.ParameterAll.Parameter(row,:).VariableID = id;
                    app.GridLayout.Children(3*row - 1).Value = app.ParameterAll.Parameter(row,:).DefaultValue;
                end
            else
                app.GridLayout.Children(3*row - 1).Enable = "off";
                if ~app.IsDatabase
                    if any(app.Waveform.Scan.Name == paraName)
                        app.Waveform.Scan(app.Waveform.Scan.Name == paraName,:).VariableName = id;
                    else
                        app.Waveform.Scan(end+1,:) = table(paraName,id,...
                            'VariableNames',{'ParameterName','VariableName'});
                    end
                else
                    app.ParameterAll.Parameter(row,:).VariableID = id;
                end
                % Read VariableList and change the display value to the
                % Current value of this variable
                app.GridLayout.Children(3*row - 1).Value = app.VariableList.readValue(id,"CurrentValue");
            end
        end

        function wflDropDownValueChanged(app,src,evt)
            row = src.Layout.Row;
            val = string(src.Value);

            % Get ID of the WaveformList for the modulation
            if val == "None"
                id = 0;
            elseif ~ismember(val,string(src.Items))
                src.Value = evt.PreviousValue;
                return
            else
                id = app.WaveformListLibrary.readValue(val,"ID","Name");
            end

            % Check if the ID is coincident with the parent WaveformList
            wflID = app.WaveformLibrary.readValue(app.ParameterAll.ID,"WaveformListID");
            if wflID == id
                warning("Can not assign a modulation to the Waveform's parent WaveformList.")
                src.Value = evt.PreviousValue;
                return
            end

            paraName = app.GridLayout.Children(3*row - 2).Text;
            paraName = paraName(1:end-1);

            % Update Waveform or ParameterAll
            if ~app.IsDatabase
                if id == 0
                    app.Waveform.(paraName) = WaveformList.empty;
                else
                    app.Waveform.(paraName) = app.WaveformListLibrary.loadEntry(id);
                end
            else

                app.ParameterAll.(paraName) = id;
            end
        end
        
        function save(app)
            if app.IsDatabase
                s = app.ParameterAll;
                s.Parameter.Value = []; % Remove the Value column in Parameter before saving it
                app.WaveformLibrary.updateEntry(s)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, wf, srcApp)
            %% Initialize connections
            if nargin == 3
                app.SrcApp = srcApp;
                app.WaveformLibrary = srcApp.WaveformLibrary;
                app.WaveformListLibrary = srcApp.WaveformListLibrary;
            else
                app.WaveformLibrary = WaveformLibrary;
                app.WaveformListLibrary = WaveformListLibrary;
            end
            
            %% Depends on the type of wf input, the app behaves differently
            if isa(wf,"Waveform")
                % If input is a Waveform, then save it in the memory
                wfType = class(wf);
                app.Waveform = wf;
            elseif isnumeric(wf)
                % If input is a number, it should be the ID that represents
                % a waveform in the database
                app.IsDatabase = true;
            end

            %% Generate PropertyList based on whethere we are operating on the database
            if ~app.IsDatabase
                mc = metaclass(app.Waveform);
                pName = mc.PropertyList;
                pName = pName(~([pName.Dependent] | [pName.Constant] | [pName.Hidden]));
                pName = string({pName.Name});
                pName(ismember(pName,["SamplingRate","Scan"])) = [];
                if isa(app.Waveform,"ConstantTop")
                    pName(ismember(pName,["Frequency","Phase"]))=[];
                end
                if ismember(wfType,app.ExceptionWaveform)
                    pName(ismember(pName,["Offset","Amplitude","RiseTime","FallTime"]))=[];
                end
                pName(ismember(pName,app.ModulationParameter))=[];
            else
                app.ParameterAll = app.WaveformLibrary.readParameter(wf);
                pName = app.ParameterAll.Parameter.Name;
                wfType = app.ParameterAll.Type;
            end
            app.PropertyNameList = pName;

            %% Populate the GUI
            app.Panel.Title = wfType;

            % Assign values to edit fields
            for ii = 1:numel(pName)
                app.GridLayout.RowHeight{end+1} = 20;
                uilabel(app.GridLayout,"Text",pName(ii) + ":");
                ef = uieditfield(app.GridLayout,'numeric','ValueChangedFcn',@app.parameterEditFieldValueChanged);
                if ismember(pName(ii),app.NonnegativeParameter)
                    ef.Limits = [0,inf];
                elseif ismember(pName(ii),app.PositiveParameter)
                    ef.Limits = [0,inf];
                    ef.LowerLimitInclusive = 'off';
                end
                if ~app.IsDatabase
                    if ~isempty(wf.(pName(ii)))
                        ef.Value = wf.(pName(ii));
                    end
                else
                    ef.Value = app.ParameterAll.Parameter(app.ParameterAll.Parameter.Name == (pName(ii)),:).Value;
                end
                uidropdown(app.GridLayout,'Items',{'None'},'Editable','on','ValueChangedFcn',@app.variableDropDownValueChanged);
            end

            % Disable StartTime if this Waveform is in a Sequential
            % WaveformList
            if nargin == 3
                if string(app.SrcApp.ConcatMethodDropDown.Value) == "Sequential"
                    idx = find(pName == "StartTime");
                    app.GridLayout.Children(idx*3).Enable = "off";
                    app.GridLayout.Children(idx*3-1).Enable = "off";
                end
            end

            % Assign values variable names
            if app.IsDatabase
                if nargin == 3 && ~isempty(srcApp.SrcApp)
                    app.VariableList = srcApp.SrcApp.VariableList;
                else
                    app.VariableList = VariableList;
                end
                varDict = dictionary([0;app.VariableList.readColumn("ID")],["None";app.VariableList.readColumn("Name")]);
                scanVariableNameList = varDict(app.ParameterAll.Parameter.VariableID);
                for ii = 1:numel(pName)
                    app.GridLayout.Children(ii*3).Items = varDict.values;
                    app.GridLayout.Children(ii*3).Value = scanVariableNameList(ii);
                    if scanVariableNameList(ii) ~= "None"
                        app.GridLayout.Children(ii*3 - 1).Enable = "off";
                    end
                end
            end

            % Modulation
            wflName = app.WaveformListLibrary.readColumn("Name");
            if ~app.IsDatabase
                isMod = isa(app.Waveform,"ModulatedWaveform");
            else
                isMod = contains(wfType,"Modulated"); %This may cause potential problems
            end
            if isMod
                if app.IsDatabase
                    wflDict= dictionary([0;app.WaveformListLibrary.readColumn("ID")],...
                        ["None";wflName]);
                end
                for ii = 1:numel(app.ModulationParameter)
                    % Add dropdowns
                    app.GridLayout.RowHeight{end+1} = 20;
                    uilabel(app.GridLayout,"Text",app.ModulationParameter(ii) + ":");
                    dd = uidropdown(app.GridLayout,"Items",wflDict.values,'Editable','on',...
                        "ValueChangedFcn",@app.wflDropDownValueChanged);
                    uilabel(app.GridLayout,"Text",""); % To ensure three columns

                    %Assign modulation WaveformList names
                    if ~app.IsDatabase
                        if ~isempty(wf.(app.ModulationParameter(ii)))
                            dd.Value = wf.(app.ModulationParameter(ii)).Name;
                        end
                    else
                        dd.Value = wflDict(app.ParameterAll.(app.ModulationParameter(ii)));
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.save
            if ~isempty(app.SrcApp)
                app.SrcApp.plot
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveformEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end