classdef WaveformListEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        EditOverrideFunctionButton    matlab.ui.control.Button
        TransformFunctionEditField    matlab.ui.control.EditField
        TransformFunctionEditFieldLabel  matlab.ui.control.Label
        RenameWaveformListButton      matlab.ui.control.Button
        DuplicateWaveformListButton   matlab.ui.control.Button
        SamplingRateEditField         matlab.ui.control.NumericEditField
        SamplingRateLabel             matlab.ui.control.Label
        WaveformPanel2                matlab.ui.container.Panel
        WaveformGrid                  matlab.ui.container.GridLayout
        PatchConstantEditField        matlab.ui.control.NumericEditField
        PatchConstantEditField_Label  matlab.ui.control.Label
        PatchMethodDropDown           matlab.ui.control.DropDown
        PatchMethodDropDown_Label     matlab.ui.control.Label
        ConcatMethodDropDown          matlab.ui.control.DropDown
        ConcatMethodDropDown_Label    matlab.ui.control.Label
        WaveformListNameDropDown      matlab.ui.control.DropDown
        WaveformListNameLabel         matlab.ui.control.Label
        TriggerAdvanceCheckBox        matlab.ui.control.CheckBox
        DeleteWaveformListButton      matlab.ui.control.Button
        AddWaveformStepButton         matlab.ui.control.Button
    end


    properties (Access = public)
        SrcApp HardwareControlPanel
        WaveformListLibrary WaveformListLibrary
        WaveformLibrary WaveformLibrary
        WaveformTypeList string
        WaveformListParameter struct
    end

    properties (Constant)
        PlotNumberLimit = 1e6
        NColumn = 4
    end

    methods (Access = private)

        function loadWaveformListProp(app)
            % Get WaveformList Parameter from the database
            name = string(app.WaveformListNameDropDown.Value);
            app.WaveformListParameter = app.WaveformListLibrary.readEntry(name,"Name");
            if isempty(app.WaveformListParameter)
                return
            end

            % Assign values to the gui components
            app.ConcatMethodDropDown.Value = app.WaveformListParameter.ConcatMethod;
            app.PatchMethodDropDown.Value = app.WaveformListParameter.PatchMethod;
            app.PatchConstantEditField.Value = app.WaveformListParameter.PatchConstant;
            app.TriggerAdvanceCheckBox.Value = app.WaveformListParameter.IsTriggerAdvance;
            app.SamplingRateEditField.Value = app.WaveformListParameter.SamplingRate;
            app.TransformFunctionEditField.Value = app.WaveformListParameter.TransformFunction;
            app.togglePatch;

            delete(app.WaveformGrid.Children(:))
            app.WaveformGrid.RowHeight = {};

            if isempty(app.WaveformListParameter.WaveformOrigin)
                return
            end
            for ii = 1:numel(app.WaveformListParameter.WaveformOrigin)
                wfType = app.WaveformLibrary.readValue(app.WaveformListParameter.WaveformOrigin(ii),"Type");
                app.addWaveformStep(wfType)
            end

            % Plot
            app.plot;
        end

        function addWaveformStep(app,typeName)
            app.WaveformGrid.RowHeight{end + 1} = 20;
            tpList = uidropdown(app.WaveformGrid,'Items',app.WaveformTypeList,'Editable','on',...
                'ValueChangedFcn',@app.typeDropDownChanged);
            if nargin == 2
                tpList.Value = typeName;
            end
            uibutton(app.WaveformGrid, 'push','Text','Edit','ButtonPushedFcn',@app.editButtonPushed);
            uibutton(app.WaveformGrid, 'push','Text','Delete','ButtonPushedFcn',@app.deleteButtonPushed);
            uibutton(app.WaveformGrid, 'push','Text','Duplicate','ButtonPushedFcn',@app.duplicateButtonPushed);
        end

        function typeDropDownChanged(app,src,evt)
            row = src.Layout.Row;
            wfType = string(src.Value);
            if ~ismember(wfType,string(src.Items))
                src.Value = evt.PreviousValue;
                return
            end

            % After we change the waveforom's type, we want to preserve its
            % properties as much as possible
            ids = app.WaveformListLibrary.readValue(app.WaveformListParameter.ID,"WaveformOrigin");
            id = ids(row); %Get the ID of the Waveform
            oldParameter = app.WaveformLibrary.readValue(id,"Parameter");
            wfTemp = feval(wfType);
            newParameterAll = wfTemp.convert2Table;
            newParameter = newParameterAll.Parameter{1};
            extraParameterName = setdiff(oldParameter.Name,newParameter.Name);
            [missingParameterName,idx] = setdiff(newParameter.Name,oldParameter.Name);
            if ~isempty(extraParameterName)
                oldParameter(ismember(oldParameter.Name,extraParameterName),:) = [];
            end
            if ~isempty(missingParameterName)
                t = table(missingParameterName(:),...
                    zeros(numel(missingParameterName),1),...
                    newParameter.DefaultValue(idx),VariableNames=["Name","VariableID","DefaultValue"]);
                oldParameter = [oldParameter;t];
            end
            app.WaveformLibrary.updateValue(id,"Parameter",{oldParameter});
            app.WaveformLibrary.updateValue(id,"Type",wfType)
            clear("wfTemp")

            % Plot
            app.plot
        end

        function editButtonPushed(app,src,~)
            row = src.Layout.Row;
            ids = app.WaveformListLibrary.readValue(app.WaveformListParameter.ID,"WaveformOrigin");
            id = ids(row); %Get the ID of the Waveform
            WaveformEditor(id,app)
        end

        function deleteButtonPushed(app,src,~)
            nRow = numel(app.WaveformGrid.RowHeight);
            nColumn = app.NColumn;
            row = src.Layout.Row;

            % Delete the gui components
            for ii = (row+1):nRow
                for jj = 1:nColumn
                    app.WaveformGrid.Children((ii-1)*nColumn + jj).Layout.Row = ...
                        app.WaveformGrid.Children((ii-1)*nColumn + jj).Layout.Row - 1;
                end
            end
            permuteIdx = [1:(row-1)*nColumn,(row*nColumn+1):nRow*nColumn,((row-1)*nColumn+1):row*nColumn];
            app.WaveformGrid.Children = app.WaveformGrid.Children(permuteIdx);
            delete(app.WaveformGrid.Children((end-nColumn+1) : end))
            app.WaveformGrid.RowHeight(end) = [];

            % Delete the waveform from database
            ids = app.WaveformListLibrary.readValue(app.WaveformListParameter.ID,"WaveformOrigin");
            id = ids(row);
            ids(row) = [];
            app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"WaveformOrigin",{ids})
            app.WaveformLibrary.deleteEntry(id)

            % Plot
            app.plot
        end

        function duplicateButtonPushed(app,src,~)
            row = src.Layout.Row;

            % Duplicate the waveform in database
            ids = app.WaveformListLibrary.readValue(app.WaveformListParameter.ID,"WaveformOrigin");
            id = ids(row);
            newId = app.WaveformLibrary.duplicateEntry(id,app.WaveformListParameter.ID);
            ids = [ids(1:row),newId,ids((row+1):end)];
            app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"WaveformOrigin",{ids})

            % Duplicate the gui component
            app.loadWaveformListProp
        end

        function togglePatch(app)
            if string(app.ConcatMethodDropDown.Value) == "Sequential"
                app.PatchMethodDropDown.Enable = "off";
                app.PatchConstantEditField.Enable = "off";
            else
                app.PatchMethodDropDown.Enable = "on";
                app.PatchConstantEditField.Enable = "on";
            end
        end

        function resetNameList(app)
            app.WaveformListNameDropDown.Items = [app.WaveformListLibrary.readColumn("Name");"New List"];
            id = min(app.WaveformListLibrary.readColumn("ID"));
            if ~isempty(id)
                app.WaveformListNameDropDown.Value = app.WaveformListLibrary.readValue(id,"Name");
            else
                app.WaveformListNameDropDown.Value = "New List";
            end
            app.loadWaveformListProp;
        end
    end

    methods (Access = public)

        function disableButton(app)
            if isempty(app.SrcApp) || string(app.SrcApp.ScanButton.Text) == "Scan"
                return
            end
            for ii = 1:numel(app.UIFigure.Children)
                if app.UIFigure.Children(ii) ~= app.WaveformListNameDropDown
                    app.UIFigure.Children(ii).Enable = "off";
                end
            end
        end

        function enableButton(app)
            if app.UIFigure.Children(ii) ~= app.WaveformListNameDropDown
                app.UIFigure.Children(ii).Enable = "on";
            end
        end

        function plot(app)
            wfl = app.WaveformListLibrary.loadEntry(app.WaveformListParameter.ID);
            wfl.plot(app.UIAxes);
        end
        
        function broadcastList(app)
            if isempty(app.SrcApp)
                return
            end
            rWg = app.SrcApp.RunningWgControl;
            if ~isempty(rWg)
                p = HardwareSetting;
                wflName = ["None";app.WaveformListLibrary.readColumn("Name")];
                rowIdx = find(rWg(1).PropertyList == "WaveformList");
                for ii = 1:numel(rWg)
                    gl = rWg(ii).ChannelGrid;
                    for jj = 1:rWg(ii).WaveformGenerator.NChannel
                        obj = findGridChild(gl,rowIdx + 1, jj + 1);
                        obj.Items = wflName;
                        wflId = p.readSettingID(rWg(ii).ID,"WaveformList",jj);
                        obj.Value = app.WaveformListLibrary.readValue(wflId,"Name");
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, srcApp)
            if nargin == 2
                app.SrcApp = srcApp;
                app.WaveformListLibrary = srcApp.WaveformListLibrary;
                app.WaveformLibrary = srcApp.WaveformLibrary;
            else
                app.WaveformListLibrary = WaveformListLibrary;
                app.WaveformLibrary = WaveformLibrary;
            end

            app.WaveformTypeList = getAllWaveform;
            wflName = app.WaveformListLibrary.readColumn("Name");
            app.WaveformListNameDropDown.Items = [wflName;"New List"];
            app.resetNameList
        end

        % Value changed function: WaveformListNameDropDown
        function WaveformListNameDropDownValueChanged(app, event)
            listName = string(app.WaveformListNameDropDown.Value);
            if ~ismember(listName,string(app.WaveformListNameDropDown.Items))
                app.WaveformListNameDropDown.Value = event.PreviousValue;
                return
            end
            if listName == "New List"
                listName = enterName("new WaveformList");
                if ~isempty(listName)
                    wflName = app.WaveformListLibrary.readColumn("Name");
                    if ~ismember(listName,wflName)
                        s.Name = listName;
                        app.WaveformListLibrary.writeEntry(s);
                        wflName = app.WaveformListLibrary.readColumn("Name");
                        app.WaveformListNameDropDown.Items = [wflName;"New List"];
                        app.WaveformListNameDropDown.Value = listName;
                        wgApp = get(findall(0, 'Tag', "WgControlPanel"), 'RunningAppInstance');
                        for ii = 1:numel(wgApp)
                            if iscell(wgApp)
                                wg = wgApp{ii};
                            else
                                wg = wgApp(ii);
                            end
                            row = find(wg.PropertyList == "WaveformList") + 1;
                            for jj = 1:wg.WaveformGenerator.NChannel
                                column = jj + 1;
                                cDropDown = findGridChild(wg.ChannelGrid,row,column);
                                cDropDown.Items = ["None";wflName];
                            end
                        end
                    else
                        msgbox("The name has been used.")
                        app.WaveformListNameDropDown.Value = app.WaveformListNameDropDown.Items{1};
                    end
                else
                    app.WaveformListNameDropDown.Value = app.WaveformListNameDropDown.Items{1};
                end
            end
            app.loadWaveformListProp

        end

        % Button pushed function: AddWaveformStepButton
        function AddWaveformStepButtonPushed(app, event)
            app.addWaveformStep("ConstantWave")
            wf = ConstantWave;
            wfID = app.WaveformLibrary.saveEntry(wf,app.WaveformListParameter.ID);
            ids = app.WaveformListLibrary.readValue(app.WaveformListParameter.ID,"WaveformOrigin");
            app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"WaveformOrigin",[ids,wfID])
            app.plot
        end

        % Value changed function: ConcatMethodDropDown
        function ConcatMethodDropDownValueChanged(app, event)
            value = string(app.ConcatMethodDropDown.Value);
            app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"ConcatMethod",value)
            app.togglePatch;
            app.plot
        end

        % Value changed function: PatchMethodDropDown
        function PatchMethodDropDownValueChanged(app, event)
            value = string(app.PatchMethodDropDown.Value);
            app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"PatchMethod",value)
            app.plot
        end

        % Value changed function: PatchConstantEditField
        function PatchConstantEditFieldValueChanged(app, event)
            value = app.PatchConstantEditField.Value;
            app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"PatchConstant",value)
            app.plot
        end

        % Value changed function: TriggerAdvanceCheckBox
        function TriggerAdvanceCheckBoxValueChanged(app, event)
            value = app.TriggerAdvanceCheckBox.Value;
            app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"IsTriggerAdvance",value)
        end

        % Button pushed function: DeleteWaveformListButton
        function DeleteWaveformListButtonPushed(app, event)
            wfName = string(app.WaveformListNameDropDown.Value);
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = string(questdlg("Do you want to delete the waveform list ["+ wfName +"]?"));
            delete(f)
            if answer == "Yes"
                app.WaveformListLibrary.deleteEntry(app.WaveformListParameter.ID);
                app.WaveformListNameDropDown.Items = [app.WaveformListLibrary.readColumn("Name");"New List"];
                id = min(app.WaveformListLibrary.readColumn("ID"));
                if ~isempty(id)
                    app.WaveformListNameDropDown.Value = app.WaveformListLibrary.readValue(id,"Name");
                else
                    app.WaveformListNameDropDown.Value = "New List";
                end
                app.loadWaveformListProp;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
            value = app.SamplingRateEditField.Value;
            app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"SamplingRate",value)
            app.plot
        end

        % Button pushed function: DuplicateWaveformListButton
        function DuplicateWaveformListButtonPushed(app, event)
            listName = enterName("duplicating WaveformList");
            if ~isempty(listName)
                wflName = app.WaveformListLibrary.readColumn("Name");
                if ~ismember(listName,wflName)
                    id = app.WaveformListParameter.ID;
                    app.WaveformListLibrary.duplicateEntry(id,listName);
                    wflName = app.WaveformListLibrary.readColumn("Name");
                    app.WaveformListNameDropDown.Items = [wflName;"New List"];
                    app.WaveformListNameDropDown.Value = listName;
                    wgApp = get(findall(0, 'Tag', "WgControlPanel"), 'RunningAppInstance');
                    for ii = 1:numel(wgApp)
                        if iscell(wgApp)
                            wg = wgApp{ii};
                        else
                            wg = wgApp(ii);
                        end
                        row = find(wg.PropertyList == "WaveformList") + 1;
                        for jj = 1:wg.WaveformGenerator.NChannel
                            column = jj + 1;
                            cDropDown = findGridChild(wg.ChannelGrid,row,column);
                            cDropDown.Items = ["None";wflName];
                        end
                    end
                    app.broadcastList
                else
                    msgbox("The name has been used.")
                end
            end
            app.loadWaveformListProp;
        end

        % Button pushed function: RenameWaveformListButton
        function RenameWaveformListButtonPushed(app, event)
            listName = enterName("Waveform List");
            if ~isempty(listName)
                allName = app.WaveformListLibrary.readColumn("Name");
                if ismember(listName,allName)
                    msgbox("The name has been used.")
                else
                    app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"Name",listName)
                    app.WaveformListNameDropDown.Items = [app.WaveformListLibrary.readColumn("Name");"New List"];
                    app.WaveformListNameDropDown.Value = listName;
                    app.broadcastList
                end
            end
        end

        % Value changed function: TransformFunctionEditField
        function TransformFunctionEditFieldValueChanged(app, event)
            value = app.TransformFunctionEditField.Value;
            app.WaveformListLibrary.updateValue(app.WaveformListParameter.ID,"TransformFunction",string(value))
            app.plot
        end

        % Button pushed function: EditOverrideFunctionButton
        function EditOverrideFunctionButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            name = string(app.WaveformListNameDropDown.Value);
            currentVal = app.WaveformListLibrary.readValue(name,"OverrideFunction","Name");
            orFunc = inputdlg("Please enter the overridefunction in the format: funcName(var1,var2,...) ","Override Function",5,currentVal);
            delete(f);
            if ~isempty(orFunc)
                orFunc = string(orFunc);
            else
                orFunc = "None";
            end
            app.WaveformListLibrary.updateValue(name,"OverrideFunction",orFunc,"Name")
            app.plot
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.AddWaveformStepButton = uibutton(app.Panel, 'push');
            app.AddWaveformStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddWaveformStepButtonPushed, true);
            app.AddWaveformStepButton.Text = 'Add Waveform Step';
            app.DeleteWaveformListButton = uibutton(app.Panel, 'push');
            app.DeleteWaveformListButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteWaveformListButtonPushed, true);
            app.DeleteWaveformListButton.Text = 'Delete Waveform List';
            app.TriggerAdvanceCheckBox = uicheckbox(app.Panel);
            app.TriggerAdvanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriggerAdvanceCheckBoxValueChanged, true);
            app.TriggerAdvanceCheckBox.Text = 'Trigger Advance';
            app.WaveformListNameLabel = uilabel(app.Panel);
            app.WaveformListNameLabel.Text = 'Waveform List Name:';
            app.WaveformListNameDropDown = uidropdown(app.Panel);
            app.WaveformListNameDropDown.Editable = 'on';
            app.WaveformListNameDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformListNameDropDownValueChanged, true);
            app.ConcatMethodDropDown_Label = uilabel(app.Panel);
            app.ConcatMethodDropDown_Label.Text = 'Concat Method:';
            app.ConcatMethodDropDown = uidropdown(app.Panel);
            app.ConcatMethodDropDown.Items = {'Sequential', 'Simultaneous'};
            app.ConcatMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ConcatMethodDropDownValueChanged, true);
            app.ConcatMethodDropDown.Value = 'Sequential';
            app.PatchMethodDropDown_Label = uilabel(app.Panel);
            app.PatchMethodDropDown_Label.Text = 'Patch Method:';
            app.PatchMethodDropDown = uidropdown(app.Panel);
            app.PatchMethodDropDown.Items = {'Continue', 'Constant'};
            app.PatchMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PatchMethodDropDownValueChanged, true);
            app.PatchMethodDropDown.Value = 'Continue';
            app.PatchConstantEditField_Label = uilabel(app.Panel);
            app.PatchConstantEditField_Label.Text = 'Patch Constant:';
            app.PatchConstantEditField = uieditfield(app.Panel, 'numeric');
            app.PatchConstantEditField.ValueChangedFcn = createCallbackFcn(app, @PatchConstantEditFieldValueChanged, true);
            app.WaveformPanel2 = uipanel(app.Panel);
            app.WaveformGrid = uigridlayout(app.WaveformPanel2);
            app.SamplingRateLabel = uilabel(app.Panel);
            app.SamplingRateLabel.Text = 'Sampling Rate:';
            app.SamplingRateEditField = uieditfield(app.Panel, 'numeric');
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.SamplingRateEditField.Value = 1000000;
            app.DuplicateWaveformListButton = uibutton(app.Panel, 'push');
            app.DuplicateWaveformListButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateWaveformListButtonPushed, true);
            app.DuplicateWaveformListButton.Text = 'Duplicate Waveform List';
            app.RenameWaveformListButton = uibutton(app.Panel, 'push');
            app.RenameWaveformListButton.ButtonPushedFcn = createCallbackFcn(app, @RenameWaveformListButtonPushed, true);
            app.RenameWaveformListButton.Text = 'Rename Waveform List';
            app.TransformFunctionEditFieldLabel = uilabel(app.Panel);
            app.TransformFunctionEditFieldLabel.Text = 'Transform Function:';
            app.TransformFunctionEditField = uieditfield(app.Panel, 'text');
            app.TransformFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @TransformFunctionEditFieldValueChanged, true);
            app.TransformFunctionEditField.Value = 'None';
            app.EditOverrideFunctionButton = uibutton(app.Panel, 'push');
            app.EditOverrideFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @EditOverrideFunctionButtonPushed, true);
            app.EditOverrideFunctionButton.Text = 'Edit Override Function';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            xlabel(app.UIAxes, 'Time', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Voltage', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveformListEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end