classdef AtomPreviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        TabGroup                matlab.ui.container.TabGroup
        OpticalDensityTab       matlab.ui.container.Tab
        ODMaxEditField          matlab.ui.control.NumericEditField
        ODmaxLabel              matlab.ui.control.Label
        ODMinEditField          matlab.ui.control.NumericEditField
        ODminLabel              matlab.ui.control.Label
        OdAxes                  matlab.ui.control.UIAxes
        OdXAxes                 matlab.ui.control.UIAxes
        OdYAxes                 matlab.ui.control.UIAxes
        RawImagesTab            matlab.ui.container.Tab
        GridLayout2             matlab.ui.container.GridLayout
        HistAxes                matlab.ui.control.UIAxes
        DarkAxes                matlab.ui.control.UIAxes
        LightAxes               matlab.ui.control.UIAxes
        AtomAxes                matlab.ui.control.UIAxes
        FringesTab              matlab.ui.container.Tab
        FringeAxes              matlab.ui.control.UIAxes
        Panel2                  matlab.ui.container.Panel
        ScannedVariableLabel    matlab.ui.control.Label
        BatchDeleteButton       matlab.ui.control.Button
        DeleteThisRunButton     matlab.ui.control.Button
        RunSpinner              matlab.ui.control.Spinner
        RunSpinnerLabel         matlab.ui.control.Label
        RunNumberSlider         matlab.ui.control.Slider
        Panel1                  matlab.ui.container.Panel
        MoveToReferenceButton   matlab.ui.control.Button
        SetSubROIsButton        matlab.ui.control.Button
        SaveFringeMaskButton    matlab.ui.control.Button
        SelectFringeMaskButton  matlab.ui.control.Button
        InfoLabel               matlab.ui.control.Label
        DragtoSelectROIButton   matlab.ui.control.Button
        ZoomOutButton           matlab.ui.control.Button
        ZoomInButton            matlab.ui.control.Button
        Y1EditFieldLabel_7      matlab.ui.control.Label
        RotateAngleEditField    matlab.ui.control.NumericEditField
        RotateButton            matlab.ui.control.Button
        Y1EditFieldLabel_6      matlab.ui.control.Label
        Y1EditFieldLabel_5      matlab.ui.control.Label
        Y1EditFieldLabel_4      matlab.ui.control.Label
        Y1EditFieldLabel_3      matlab.ui.control.Label
        Y1EditFieldLabel_2      matlab.ui.control.Label
        ROINameLabel            matlab.ui.control.Label
        SNLabel                 matlab.ui.control.Label
        OverwriteROIFileButton  matlab.ui.control.Button
        TrialLabel              matlab.ui.control.Label
        ROINameDropDown         matlab.ui.control.DropDown
        UpdateBecExpROIButton   matlab.ui.control.Button
        SaveasNewROIButton      matlab.ui.control.Button
        AngleEditField          matlab.ui.control.NumericEditField
        phiLabel                matlab.ui.control.Label
        X2EditField             matlab.ui.control.NumericEditField
        X2EditFieldLabel        matlab.ui.control.Label
        X1EditField             matlab.ui.control.NumericEditField
        X1EditFieldLabel        matlab.ui.control.Label
        Y2EditField             matlab.ui.control.NumericEditField
        Y2EditFieldLabel        matlab.ui.control.Label
        Y1EditField             matlab.ui.control.NumericEditField
        Y1EditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        OdImage matlab.graphics.primitive.Image % Description
        AtomImage
        LightImage
        DarkImage
        FringeImage
        Hist
        OdMarker
        MarkerAbsolutePosition
        BecExp % Description
        OdX % Description
        OdY % Description
        Roi
        ImageSize
        SubRoiLine
        RoiSetting
        BecExpSetting
        IsOd logical = false
    end
    
    properties (Access = private, Constant)
        YXWidth = 60 % Description
        YXGap = 15
        YXLabelSize = 43
    end

    properties (Access = private,Transient)
        TempOd
        TempAtom
        TempLight
        TempDark
        TempFringe
        RunNumber = 1;
    end

    properties (Dependent)
        DensityString
    end

    methods
        function str = get.DensityString(app)
            if app.IsOd
                str = "OD";
            else
                str = "AD";
            end
        end
        
        function moveMarkerToReference(app)
            becExp = app.BecExp;
            if becExp.CenterReferenceID ~= 0
                ref = becExp.BecExpData.readValue(becExp.CenterReferenceID,"CloudCenter","TrialID");
                ref = app.Roi.noRotationFull2Roi(ref);
                data = app.OdImage.CData;
                marker = app.OdMarker;
                marker.XData = ref(2);
                marker.YData = ref(1);
                app.OdX.YData = data(ref(1),:);
                app.OdY.XData = data(:,ref(2));
                pFull = app.Roi.roi2Full(ref);
                app.InfoLabel.Text = ["$x = " + num2str(pFull(2)) + "~\mathrm{Pixels}$",...
                    "$y = " + num2str(pFull(1)) + " ~\mathrm{Pixels}$",...
                    "$\mathrm{OD} = " + num2str(data(ref(1),ref(2))) + "$"];
                app.MarkerAbsolutePosition = app.Roi.roi2NoRotationFull(ref);
            end
        end
    end
    
    methods (Access = private)
            
        function updateRoiField(app)
            app.Y1EditField.Value = app.Roi.YXBoundary(1);
            app.Y2EditField.Value = app.Roi.YXBoundary(2);
            app.X1EditField.Value = app.Roi.YXBoundary(3);
            app.X2EditField.Value = app.Roi.YXBoundary(4);
            app.AngleEditField.Value = app.Roi.Angle;
        end
               
        function updateInfoLabel(app)
            if app.TabGroup.SelectedTab.Tag == "Od"
                markerPos = [app.OdMarker.YData,app.OdMarker.XData];
                markerPosFull = app.Roi.roi2Full(markerPos);
                if app.IsOd
                    dNumber = "$\mathrm{OD} = " + num2str(app.TempOd(markerPos(1),markerPos(2))) + "$";
                else
                    dNumber = "$\mathrm{AD} = " + num2str(app.TempOd(markerPos(1),markerPos(2)),'%.2f') +...
                    "\times 10^{"+num2str(log(app.BecExp.Ad.Unit)/log(10))+"}~"+...
                    "\mathrm{m}^{-2}$";
                end
                app.InfoLabel.Text = ["$x = " + num2str(markerPosFull(2)) + "~\mathrm{Pixels}$",...
                    "$y = " + num2str(markerPosFull(1)) + " ~\mathrm{Pixels}$",...
                    dNumber];
            elseif app.TabGroup.SelectedTab.Tag == "Raw"
                lightMean = mean(app.TempLight(:));
                lightDev = std(app.TempLight(:)) / lightMean;
                darkMean = mean(app.TempDark(:));
                darkDev = std(app.TempDark(:)) / darkMean;
                app.InfoLabel.Text = ["$\overline{\mathrm{Light}} = " + num2str(lightMean,'%.1f') + "$",...
                    "$\Delta{\mathrm{Light}} / \overline{\mathrm{Light}} = " + num2str(100*lightDev,'%.1f') + "\% $",...
                    "$\overline{\mathrm{Dark}} = " + num2str(darkMean,'%.1f') + "$",...
                    "$\Delta{\mathrm{Dark}} / \overline{\mathrm{Dark}} = " + num2str(100*darkDev,'%.1f') + "\% $"];
            elseif app.TabGroup.SelectedTab.Tag == "Fringe"
                app.InfoLabel.Text = "";
            end
        end
        
        function updateSubRoi(app)
            for ii = 1:numel(app.SubRoiLine)
                if isgraphics(app.SubRoiLine(ii))
                    delete(app.SubRoiLine(ii))
                end
            end
            if ~isempty(app.Roi.SubRoi)
                for ii = 1:numel(app.Roi.SubRoi)
                    cList = app.Roi.SubRoi(ii).CornerList;
                    cList = flip(cList,2);
                    cList = [cList;cList(1,:)];
                    app.SubRoiLine(ii) = line(app.OdAxes,cList(:,1),cList(:,2),'Color','w','LineWidth',1.5);
                end
            end
        end
    end

    methods
        function updateOd(app)
            if app.BecExp.NCompletedRun == 0
                app.TempOd = app.Roi.select(zeros(app.ImageSize));
                app.TempAtom = app.Roi.select(zeros(app.ImageSize));
                app.TempLight = app.Roi.select(zeros(app.ImageSize));
                app.TempDark = app.Roi.select(zeros(app.ImageSize));
            else
                roiData = app.Roi.select(app.BecExp.readRun(app.RunNumber));
                app.TempAtom = roiData(:,:,1);
                app.TempLight = roiData(:,:,2);
                app.TempDark = roiData(:,:,3);
                if app.IsOd
                    app.TempOd = absorption2Od(computeAbsorption(roiData));
                else
                    becExp = app.BecExp;
                    sigma0 = becExp.Atom.CyclerCrossSection;
                    sigmaData = becExp.Ad.CrossSectionData;
                    Od = absorption2Od(computeAbsorption(roiData));
                    ImageRatio=computeAbsorption(roiData);
                    pf = app.BecExp.Imaging.Prefactor;
                    qe = app.BecExp.Imaging.QuantumEfficiency;
                    t = becExp.CiceroData.t_image(app.RunNumber);

                    % Calculate Ad
                    switch becExp.Ad.AdMethod
                        case "TwoLevelWeakLight"
                            app.TempOd = Od / sigma0;
                        case "RandomPolarization"
                            app.TempOd = Od / sigma0 * 3;
                        case "UniformStrongLight"
                            sall = pf*(app.TempAtom + app.TempLight - 2*app.TempDark)/2/qe/t;
                            s = mean(sall,"all");
                            sigma = crossSec(s);
                            app.TempOd = Od / sigma;
                        case "StrongLight"
                            s = pf*(app.TempAtom + app.TempLight - 2*app.TempDark)/2/qe/t;
                            s(s<0) = 0;
                            s(s>10) = 10;
                            sigma = crossSec(s);
                            sigma = imgaussfilt(sigma,becExp.Ad.Blur);
                            app.TempOd = Od ./ sigma;
                        case "UniformStrongLight2"

                        case "StrongLight2"

                        case "PhaseContrastImaging"
                            %Needs to obtain phase plate for imaging
                            %should be between -pi and pi
                            phi=-pi/3; %Assumes additional thickness, use minus for etched

                            freqlistPCI_Imaging=app.BecExp.HardwareData.hw_ImagingPci;
                            freqPCI_Imaging=freqlistPCI_Imaging(app.RunNumber);

                            switch app.BecExp.Imaging.ImagingStage
                                case "NI"
                                    freqlist_Imaging=app.BecExp.HardwareData.hw_ImagingNi ;
                                case "LF"
                                    freqlist_Imaging=app.BecExp.HardwareData.hw_ImagingLf ;
                                case "HF"
                                    freqlist_Imaging=app.BecExp.HardwareData.hw_ImagingHf ;
                            end
                            freq_Imaging=freqlist_Imaging(app.RunNumber);
                            delta=freq_Imaging-freqPCI_Imaging; %Frequency Detuning (in Hz). Positive means red-detuned
                            app.TempOd=ImageRatio;

                            %Correct for spots that are outside of the expected
                            %ratios flor the given phase spot plate.
                            Imin=min((3-2*cos(phi)-4*sin(phi/2)), (3-2*cos(phi)+4*sin(phi/2)));
                            Imax=max((3-2*cos(phi)-4*sin(phi/2)), (3-2*cos(phi)+4*sin(phi/2)));
                            app.TempOd=min(app.TempOd, Imax);
                            app.TempOd=max(app.TempOd, Imin);

                            %Calculate phase shift;
                            app.TempOd=phi/2+asin((app.TempOd-(3-2*cos(phi)))/(4*sin(phi/2)));

                            %Convert phase shift to density (for within range);
                            epsilon0=8.8541878188e-12; %Si units for vacuum permitivity
                            electroncharge=1.602e-19; %Electron Charge in Coulombs
                            mLi=1.1649273e-26; %Mass of Li in kg
                            a0=5.29177210544e-11; %Bohr Radius (m)
                            % alpha=electroncharge^2/(mLi*2*pi*delta*(2*4.46784587e14)); %


                            %alternatecalculations2
                            omegaD1 = 2 * pi * becExp.Atom.D1.Frequency;
                            omegaD2 = 2 * pi * becExp.Atom.D2.Frequency;
                            omegaL = omegaD2-2*pi*delta;
                            dipoleD1 = becExp.Atom.D1.ReducedDipoleMatrixElement;
                            dipoleD2 = becExp.Atom.D2.ReducedDipoleMatrixElement;
                            hbar = Constants.SI("hbar");
                            alpha = 2/3/hbar * (abs(dipoleD1)^2 / (omegaD1 - omegaL) +...
                                abs(dipoleD2)^2 / (omegaD2 - omegaL));

                            app.TempOd=app.TempOd/(2*pi/(671e-9))*2*epsilon0/alpha;
                    end
                    app.TempOd = app.TempOd / becExp.Ad.Unit;

                end
            end

            function si = crossSec(s)
                si = sigma0 * interp1(sigmaData(:,1),sigmaData(:,2),s,'linear');
            end

            %% Resize OD image
            roiSize = app.Roi.CenterSize(3:4);
            roiCenter = app.Roi.CenterSize(1:2);
            roiYX = app.Roi.YXBoundary;
            position = app.UIFigure.Position;
            padding = app.GridLayout.Padding(1);
            gridWidth = position(3) - app.GridLayout.ColumnWidth{1} - 3*padding;
            gridHeight = position(4) - app.GridLayout.RowHeight{2} - 3*padding - 35;
            targetWidth = gridWidth - app.YXGap - app.YXLabelSize - app.YXWidth;
            targetHeight = gridHeight - app.YXGap - app.YXLabelSize - app.YXWidth;
            roiAspect = roiSize(1)/roiSize(2); %height/width

            if targetWidth > 0 && targetHeight>0
                if targetWidth > targetHeight/roiAspect
                    app.OdAxes.Position(4) = targetHeight-10;
                    app.OdAxes.Position(3) = app.OdAxes.Position(4) / roiAspect;
                elseif targetHeight > targetWidth*roiAspect
                    app.OdAxes.Position(3) = targetWidth-10;
                    app.OdAxes.Position(4) = app.OdAxes.Position(3) * roiAspect;
                end
            end
            app.OdAxes.XLim = [1,roiSize(2)];
            app.OdAxes.YLim = [1,roiSize(1)];
            odPos = tightPosition(app.OdAxes);
            app.OdImage.CData = app.TempOd;

            %% Reposition marker
            markerAbsPos = app.MarkerAbsolutePosition;
            if app.Roi.isNoRotationFullInRoi(markerAbsPos)
                markerPos = app.Roi.noRotationFull2Roi(markerAbsPos);
            else
                markerPos = round(app.Roi.full2Roi(roiCenter));
                app.MarkerAbsolutePosition = app.Roi.roi2NoRotationFull(markerPos);
            end
            app.OdMarker.XData = markerPos(2);
            app.OdMarker.YData = markerPos(1);

            %% Plot x OD curves
            app.OdXAxes.InnerPosition(1) = odPos(1);
            app.OdXAxes.InnerPosition(3) = odPos(3);
            app.OdXAxes.InnerPosition(4) = app.YXWidth;
            app.OdXAxes.InnerPosition(2) = odPos(2) - app.YXGap - app.YXWidth;
            app.OdX.XData = roiYX(3):roiYX(4);
            app.OdX.YData = app.TempOd(markerPos(1),:);
            app.OdXAxes.XLim = [roiYX(3),roiYX(4)];

            %% Plot y OD curves
            app.OdYAxes.InnerPosition(2) = odPos(2);
            app.OdYAxes.InnerPosition(4) = odPos(4);
            app.OdYAxes.InnerPosition(3) = app.YXWidth;
            app.OdYAxes.InnerPosition(1) = odPos(1) - app.YXGap - app.YXWidth;
            app.OdY.XData = app.TempOd(:,markerPos(2));
            app.OdY.YData = roiYX(1):roiYX(2);
            app.OdYAxes.YLim = [roiYX(1),roiYX(2)];
            
            %% Update scanned parameter
            app.ScannedVariableLabel.Text = app.BecExp.ScannedVariableLabel(app.RunNumber);

            %% Update raw images
            app.AtomImage.CData = app.TempAtom;
            app.AtomAxes.XLim = [1,roiSize(2)];
            app.AtomAxes.YLim = [1,roiSize(1)];
            renderTicks(app.AtomImage,roiYX(3):roiYX(4),roiYX(1):roiYX(2))
            pbaspect(app.AtomAxes,[roiSize(2)/roiSize(1),1,1])

            app.LightImage.CData = app.TempLight;
            app.LightAxes.CLim = app.AtomAxes.CLim;
            app.LightAxes.XLim = [1,roiSize(2)];
            app.LightAxes.YLim = [1,roiSize(1)];
            renderTicks(app.LightImage,roiYX(3):roiYX(4),roiYX(1):roiYX(2))
            pbaspect(app.LightAxes,[roiSize(2)/roiSize(1),1,1])

            app.DarkImage.CData = app.TempDark;
            app.DarkAxes.XLim = [1,roiSize(2)];
            app.DarkAxes.YLim = [1,roiSize(1)];
            renderTicks(app.DarkImage,roiYX(3):roiYX(4),roiYX(1):roiYX(2))
            pbaspect(app.DarkAxes,[roiSize(2)/roiSize(1),1,1])

            app.Hist.Data = app.TempLight;

            %% Update fringes
            if ~isempty(app.BecExp.Od.FringeRemovalMask)
                mask = app.Roi.createMask(app.BecExp.Od.FringeRemovalMask);
            else
                mask = 1;
            end
            app.TempFringe = mask.*(app.TempLight - app.TempAtom)./mean(app.TempLight);
            app.FringeImage.CData = app.TempFringe;
            app.FringeAxes.XLim = [1,roiSize(2)];
            app.FringeAxes.YLim = [1,roiSize(1)];
            renderTicks(app.FringeImage,roiYX(3):roiYX(4),roiYX(1):roiYX(2))
            pbaspect(app.FringeAxes,[roiSize(2)/roiSize(1),1,1])

            %% Update info
            app.updateInfoLabel

            %% Update sub-ROI
            app.updateSubRoi
            
        end
        
        function update(app)
            if app.BecExp.NCompletedRun <= 1
                app.RunSpinner.Limits = [1,1];
                app.RunNumberSlider.Limits = [1,1.1];
                app.RunNumber = 1;
            else
                app.RunSpinner.Limits = double([1,app.BecExp.NCompletedRun]);
                app.RunSpinner.Value = double(app.BecExp.NCompletedRun);
                app.RunNumberSlider.Limits = double([1,app.BecExp.NCompletedRun]);
                app.RunNumberSlider.Value = double(app.BecExp.NCompletedRun);
                app.RunNumberSlider.MajorTicks = findSliderTicks(double(1:app.BecExp.NCompletedRun));
                app.RunNumber = app.BecExp.NCompletedRun;
            end
            app.updateOd
        end

        function updateLabel(app)
            app.ODmaxLabel.Text = app.DensityString + " Max";
            app.ODminLabel.Text = app.DensityString + " Min";
            if app.IsOd
                app.OpticalDensityTab.Title = "Optical Density";
                app.ODMinEditField.Value = app.BecExp.Od.CLim(1);
                app.ODMaxEditField.Value = app.BecExp.Od.CLim(2);
                app.OdAxes.CLim = app.BecExp.Od.CLim;
                app.OdAxes.Colormap = app.BecExp.Od.Colormap;
            else
                app.OpticalDensityTab.Title = "Atomic Density";
                app.ODMinEditField.Value = app.BecExp.Ad.CLim(1);
                app.ODMaxEditField.Value = app.BecExp.Ad.CLim(2);
                app.OdAxes.CLim = app.BecExp.Ad.CLim;
                app.OdAxes.Colormap = app.BecExp.Od.Colormap;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, becExp)
            
            %% Load BecExp and Roi 
            app.BecExp = becExp;
            app.ImageSize = app.BecExp.Roi.ImageSize;
            app.Roi = Roi(becExp.Roi.Name,imageSize=app.ImageSize); % Have to create another instance because ROI is handle.
            app.Roi.YXBoundary = becExp.Roi.YXBoundary;
            app.Roi.Angle = becExp.Roi.Angle;
            app.Roi.SubRoiNRowColumn = becExp.Roi.SubRoiNRowColumn;
            app.Roi.SubRoiSeparation = becExp.Roi.SubRoiSeparation;
            app.Roi.SubRoiCenterSize = becExp.Roi.SubRoiCenterSize;

            %% Display BecExp info
            app.SNLabel.Text = "Trial #: " + num2str(becExp.SerialNumber);
            app.TrialLabel.Text = becExp.Name;

            %% Set spinner and slider
            app.RunNumberSlider.Value = 1;
            app.RunSpinner.Value = 1;

            %% Update OD axes CLim and colormap
            if app.IsOd
                app.OdAxes.CLim = becExp.Od.CLim;
                app.OdAxes.Colormap = becExp.Od.Colormap;
            else
                app.OdAxes.CLim = becExp.Ad.CLim;
                app.OdAxes.Colormap = becExp.Ad.Colormap;
            end
            app.ODMinEditField.Value = app.OdAxes.CLim(1);
            app.ODMaxEditField.Value = app.OdAxes.CLim(2);

            app.ODminLabel.Position(1:2) = [5 5];
            app.ODmaxLabel.Position(1:2) = [5 39];
            app.ODMinEditField.Position(1:2) = [55 5];
            app.ODMaxEditField.Position(1:2) = [55 39];
            
            %% Display ROI info
            app.BecExpSetting = BecExpSetting;
            app.RoiSetting = RoiSetting;
            roiSettingName = app.RoiSetting.readValueTwoKey(app.ImageSize(1),app.ImageSize(2),"Name","ImageSizeY","ImageSizeX");
            app.ROINameDropDown.Items = ["Full";roiSettingName];
            app.ROINameDropDown.Value = app.Roi.Name;
            app.updateRoiField;
            
            %% Initialize OD image plot
            roiCenter = app.Roi.CenterSize(1:2);
            roiSize = app.Roi.CenterSize(3:4);
            roiYX = app.Roi.YXBoundary;
            app.TempOd = zeros(roiSize);
            app.OdImage = imagesc(app.OdAxes,app.TempOd);

            %% Resize OD image
            app.OdAxes.Position(1) = app.YXWidth + app.YXLabelSize + app.YXGap;
            app.OdAxes.Position(2) = app.YXWidth + app.YXLabelSize + app.YXGap;

            %% Plot crosshair marker
            markerPos = round(app.Roi.full2Roi(roiCenter));
            app.MarkerAbsolutePosition = app.Roi.roi2NoRotationFull(markerPos);
            hold(app.OdAxes,"on")
            app.OdMarker = plot(app.OdAxes,markerPos(2),markerPos(1),'+','MarkerSize',20,'Color','w','LineWidth',1);
            hold(app.OdAxes,"off")

            %% Plot x OD curves
            app.OdXAxes.YLim = app.OdAxes.CLim;
            app.OdXAxes.XLabel.String = "$x$ position [Pixels]";
            app.OdXAxes.XLabel.Interpreter = "latex";
            app.OdX = plot(app.OdXAxes,roiYX(3):roiYX(4),app.TempOd(markerPos(1),:),'k','LineWidth',0.5);
            
            %% Plot y OD curves
            app.OdYAxes.XLim = app.OdAxes.CLim;
            app.OdYAxes.YLabel.String = "$y$ position [Pixels]";
            app.OdYAxes.YLabel.Interpreter = "latex";
            app.OdYAxes.YDir = "reverse";
            app.OdY = plot(app.OdYAxes,app.TempOd(:,markerPos(2)),roiYX(1):roiYX(2),'k','LineWidth',0.5);

            %% Maker button down callback
            app.OdImage.ButtonDownFcn = {@setClick,app.OdMarker,app.OdX,app.OdY,app.InfoLabel};
            function setClick(src,ev,marker,lx,ly,label)
                p = round(ev.IntersectionPoint);
                data = src.CData;
                marker.XData = p(1);
                marker.YData = p(2);
                lx.YData = data(p(2),:);
                ly.XData = data(:,p(1));
                pFull = app.Roi.roi2Full(flip(p(1:2)));
                label.Text = ["$x = " + num2str(pFull(2)) + "~\mathrm{Pixels}$",...
                "$y = " + num2str(pFull(1)) + " ~\mathrm{Pixels}$",...
                "$\mathrm{OD} = " + num2str(data(p(2),p(1))) + "$"];
                app.MarkerAbsolutePosition = app.Roi.roi2NoRotationFull(flip(p(1:2)));
            end
            
            %% Initialize Raw images
            app.TempAtom = zeros(roiSize);
            app.AtomImage = imagesc(app.AtomAxes,app.TempAtom);
            app.AtomAxes.XLabel.String = "$x$ position [Pixels]";
            app.AtomAxes.XLabel.Interpreter = "latex";
            app.AtomAxes.YLabel.String = "$y$ position [Pixels]";
            app.AtomAxes.YLabel.Interpreter = "latex";
            app.AtomAxes.Colormap = becExp.Od.Colormap;
            app.AtomAxes.Toolbar.Visible = "off";
            colorbar(app.AtomAxes)

            app.TempLight = zeros(roiSize);
            app.LightImage = imagesc(app.LightAxes,app.TempLight);
            app.LightAxes.XLabel.String = "$x$ position [Pixels]";
            app.LightAxes.XLabel.Interpreter = "latex";
            app.LightAxes.YLabel.String = "$y$ position [Pixels]";
            app.LightAxes.YLabel.Interpreter = "latex";
            app.LightAxes.Colormap = becExp.Od.Colormap;
            app.LightAxes.Toolbar.Visible = "off";
            colorbar(app.LightAxes)

            app.TempDark = zeros(roiSize);
            app.DarkImage = imagesc(app.DarkAxes,app.TempDark);
            app.DarkAxes.XLabel.String = "$x$ position [Pixels]";
            app.DarkAxes.XLabel.Interpreter = "latex";
            app.DarkAxes.YLabel.String = "$y$ position [Pixels]";
            app.DarkAxes.YLabel.Interpreter = "latex";
            app.DarkAxes.Colormap = becExp.Od.Colormap;
            app.DarkAxes.Toolbar.Visible = "off";
            colorbar(app.DarkAxes)

            app.Hist = histogram(app.HistAxes,app.TempLight);
            app.Hist.BinMethod = 'auto';
            app.HistAxes.XLabel.String = "Light camera counts";
            app.HistAxes.XLabel.Interpreter = "latex";
            app.HistAxes.YLabel.String = "Distribution [a.u.]";
            app.HistAxes.YLabel.Interpreter = "latex";
            app.HistAxes.Toolbar.Visible = "off";

            %% Info Label
            app.InfoLabel.Interpreter = "latex";

            %% Fringe
            app.TempFringe = (app.TempLight - app.TempAtom)./mean(app.TempLight);
            app.FringeImage = imagesc(app.FringeAxes,app.TempFringe);
            app.FringeAxes.Title.String = "$\mathrm{Mask}\times(\mathrm{Light}-\mathrm{Atom}) / \overline{\mathrm{Light}}$";
            app.FringeAxes.Title.Interpreter = "latex";
            app.FringeAxes.XLabel.String = "$x$ position [Pixels]";
            app.FringeAxes.XLabel.Interpreter = "latex";
            app.FringeAxes.YLabel.String = "$y$ position [Pixels]";
            app.FringeAxes.YLabel.Interpreter = "latex";
            app.FringeAxes.Colormap = bone;
            app.FringeAxes.Toolbar.Visible = "off";
            app.FringeAxes.CLim = [-0.4,0.4];
            app.FringeAxes.Position(1:2) = [10,10];
            colorbar(app.FringeAxes)

            app.update

            app.Panel1.AutoResizeChildren = 'on';
            app.Panel2.AutoResizeChildren = 'on';
            app.FringesTab.AutoResizeChildren = 'on';
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            padding = app.GridLayout.Padding(1);
            gridWidth = position(3) - app.GridLayout.ColumnWidth{1} - 3*padding;
            gridHeight = position(4) - app.GridLayout.RowHeight{2} - 3*padding - 35;
            targetWidth = gridWidth - app.YXGap - app.YXLabelSize - app.YXWidth;
            targetHeight = gridHeight - app.YXGap - app.YXLabelSize - app.YXWidth;
            if ~isempty(app.Roi)
                roiSize = app.Roi.CenterSize(3:4);
                roiAspect = roiSize(1)/roiSize(2); %height/width
            else
                roiAspect = 0.8438;
            end

            if targetWidth > 0 && targetHeight>0
                if targetWidth > targetHeight/roiAspect
                    app.OdAxes.Position(4) = targetHeight-10;
                    app.OdAxes.Position(3) = app.OdAxes.Position(4) / roiAspect;
                elseif targetHeight > targetWidth*roiAspect
                    app.OdAxes.Position(3) = targetWidth-10;
                    app.OdAxes.Position(4) = app.OdAxes.Position(3) * roiAspect;
                end
            end
            
            odPos = tightPosition(app.OdAxes);
            
            app.OdXAxes.InnerPosition(1) = odPos(1);
            app.OdXAxes.InnerPosition(3) = odPos(3);

            app.OdYAxes.InnerPosition(2) = odPos(2);
            app.OdYAxes.InnerPosition(4) = odPos(4);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            marker = app.OdMarker;
            data = app.TempOd;
            dataSize = size(data);
            switch key
                case "uparrow"
                    if marker.YData == 1
                        return
                    else
                        marker.YData = marker.YData - 1;
                    end
                case "downarrow"
                    if marker.YData == dataSize(1)
                        return
                    else
                        marker.YData = marker.YData + 1;
                    end
                case "leftarrow"
                    if marker.XData == 1
                        return
                    else
                        marker.XData = marker.XData - 1;
                    end
                case "rightarrow"
                    if marker.XData == dataSize(2)
                        return
                    else
                        marker.XData = marker.XData + 1;
                    end
                otherwise
                    return
            end
            p(1) = marker.XData;
            p(2) = marker.YData;
            app.OdX.YData = data(p(2),:);
            app.OdY.XData = data(:,p(1));
            pFull = app.Roi.roi2Full(flip(p(1:2)));

            app.InfoLabel.Text = ["$x = " + num2str(pFull(2)) + "~\mathrm{Pixels}$",...
                "$y = " + num2str(pFull(1)) + " ~\mathrm{Pixels}$",...
                "$\mathrm{OD} = " + num2str(data(p(2),p(1))) + "$"];
            app.MarkerAbsolutePosition = app.Roi.roi2NoRotationFull(flip(p(1:2)));
   
        end

        % Value changed function: ODMinEditField
        function ODMinEditFieldValueChanged(app, event)
            value = app.ODMinEditField.Value;
            if value < app.OdAxes.CLim(2)
                app.OdAxes.CLim(1) = value;
                app.OdYAxes.XLim = app.OdAxes.CLim;
                app.OdXAxes.YLim = app.OdAxes.CLim;
                if app.IsOd
                    app.BecExp.Od.CLim = app.OdAxes.CLim;
                else
                    app.BecExp.Ad.CLim = app.OdAxes.CLim;
                end
                if isvalid(app.BecExp.ControlApp)
                    app.BecExp.ControlApp.ODMinEditField.Value = value;
                end
            else
                warning(app.DensityString + " CLim min value should be smaller than max value.")
                app.ODMinEditField.Value = app.OdAxes.CLim(1);
            end
        end

        % Value changed function: ODMaxEditField
        function ODMaxEditFieldValueChanged(app, event)
            value = app.ODMaxEditField.Value;
            if value > app.OdAxes.CLim(1)
                app.OdAxes.CLim(2) = value;
                app.OdYAxes.XLim = app.OdAxes.CLim;
                app.OdXAxes.YLim = app.OdAxes.CLim;
                if app.IsOd
                    app.BecExp.Od.CLim = app.OdAxes.CLim;
                else
                    app.BecExp.Ad.CLim = app.OdAxes.CLim;
                end
                if isvalid(app.BecExp.ControlApp)
                    app.BecExp.ControlApp.ODMaxEditField.Value = value;
                end
            else
                app.ODMaxEditField.Value = app.OdAxes.CLim(2);
                warning(app.DensityString + " CLim max value should be larger than min value.")
            end
        end

        % Clicked callback: ROINameDropDown
        function ROINameDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item)
                return
            end
            value = string(app.ROINameDropDown.Items{item});
            roi = Roi(value,imageSize=app.ImageSize,angle=app.Roi.Angle);
            if ~all(roi.ImageSize == app.ImageSize)
                warning("Selected ROI has different image size.")
                app.ROINameDropDown.Value = app.Roi.Name;
                return
            end
            app.Roi = roi;
            app.updateRoiField;
            app.updateOd;
        end

        % Value changed function: Y1EditField
        function Y1EditFieldValueChanged(app, event)
            value = app.Y1EditField.Value;
            lastwarn('');
            app.Roi.YXBoundary(1) = value;
            [msgstr, ~] = lastwarn;
            if msgstr ~= ""
                app.Y1EditField.Value = app.Roi.YXBoundary(1);
            end
            app.updateOd;
        end

        % Value changed function: Y2EditField
        function Y2EditFieldValueChanged(app, event)
            value = app.Y2EditField.Value;
            lastwarn('');
            app.Roi.YXBoundary(2) = value;
            [msgstr, ~] = lastwarn;
            if msgstr ~= ""
                app.Y2EditField.Value = app.Roi.YXBoundary(2);
            end
            app.updateOd;
        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
            value = app.X1EditField.Value;
            lastwarn('');
            app.Roi.YXBoundary(3) = value;
            [msgstr, ~] = lastwarn;
            if msgstr ~= ""
                app.X1EditField.Value = app.Roi.YXBoundary(3);
            end
            app.updateOd;
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            value = app.X2EditField.Value;
            lastwarn('');
            app.Roi.YXBoundary(4) = value;
            [msgstr, ~] = lastwarn;
            if msgstr ~= ""
                app.X2EditField.Value = app.Roi.YXBoundary(4);
            end
            app.updateOd;
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            value = app.AngleEditField.Value;
            app.AngleEditField.Value = mod(value,360);
            lastwarn('');
            app.Roi.Angle = value;
            [msgstr, ~] = lastwarn;
            if msgstr ~= ""
                app.updateRoiField;
            end
            app.updateOd;
        end

        % Value changed function: RunSpinner
        function RunSpinnerValueChanged(app, event)
            value = app.RunSpinner.Value;
            app.RunNumber = value;
            app.RunNumberSlider.Value = app.RunNumber;
            app.updateOd;
        end

        % Value changed function: RunNumberSlider
        function RunNumberSliderValueChanged(app, event)
            value = app.RunNumberSlider.Value;
            app.RunNumber = round(value);
            app.RunNumberSlider.Value = app.RunNumber;
            app.RunSpinner.Value = app.RunNumber;
            app.updateOd;
        end

        % Button pushed function: UpdateBecExpROIButton
        function UpdateBecExpROIButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to update BecExp ROI? This will force a refresh.");
            delete(f)
            if answer == "Yes"
                app.BecExp.Roi.Name = app.Roi.Name;
                app.BecExp.Roi.YXBoundary = app.Roi.YXBoundary;
                app.BecExp.Roi.Angle = app.Roi.Angle;
                app.BecExp.Roi.SubRoiNRowColumn = app.Roi.SubRoiNRowColumn;
                app.BecExp.Roi.SubRoiSeparation = app.Roi.SubRoiSeparation;
                app.BecExp.Roi.SubRoiCenterSize = app.Roi.SubRoiCenterSize;
                if isvalid(app.BecExp.ControlApp)
                    app.BecExp.ControlApp.ROIDropDown.Value = app.Roi.Name;
                end
                app.BecExp.refresh
            end
        end

        % Button pushed function: SaveasNewROIButton
        function SaveasNewROIButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            Name = inputdlg("Please enter the name of the new ROI to be saved." + ...
                " Name can only contain alphabetic and numeric characters. " + ...
                "CamelCase recommended): ","Save as New ROI"); 
            delete(f);
            if ~isempty(Name)
                Name = string(Name);
                if ~isAlphaNum(Name)
                    error("The input name can only contain alphabetic and numeric characters.")
                elseif any(app.RoiSetting.readColumn("Name") == Name)
                    error("The input name has been used.")
                end
                Y1 = app.Roi.YXBoundary(1);
                Y2 = app.Roi.YXBoundary(2);
                X1 = app.Roi.YXBoundary(3);
                X2 = app.Roi.YXBoundary(4);
                ImageSizeY = app.Roi.ImageSize(1);
                ImageSizeX = app.Roi.ImageSize(2);
                Angle = app.Roi.Angle;
                SubRoiCenterSize = {app.Roi.SubRoiCenterSize};
                SubRoiNRowColumn = app.Roi.SubRoiNRowColumn;
                SubRoiSeparation = app.Roi.SubRoiSeparation;
                t = table(Name,Y1,Y2,X1,X2,ImageSizeY,ImageSizeX,Angle,...
                    SubRoiCenterSize,SubRoiNRowColumn,SubRoiSeparation);
                app.RoiSetting.writeEntry(t)

                roiList = app.ROINameDropDown.Items;
                roiList = [roiList,{char(Name)}];
                app.ROINameDropDown.Items = roiList;
                app.ROINameDropDown.Value = Name;
                if isvalid(app.BecExp.ControlApp)
                    app.BecExp.ControlApp.ROIDropDown.Items = roiList;
                    app.BecExp.ControlApp.ROIDropDown.Value = Name;
                end
            end
        end

        % Button pushed function: OverwriteROIFileButton
        function OverwriteROIFileButtonPushed(app, event)
            Name = string(app.ROINameDropDown.Value);
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to overwrite the ROI ["+ Name +"]?");
            delete(f)
            answer = string(answer);
            if answer == "Yes"
                Y1 = app.Roi.YXBoundary(1);
                Y2 = app.Roi.YXBoundary(2);
                X1 = app.Roi.YXBoundary(3);
                X2 = app.Roi.YXBoundary(4);
                ImageSizeY = app.Roi.ImageSize(1);
                ImageSizeX = app.Roi.ImageSize(2);
                Angle = app.Roi.Angle;
                SubRoiCenterSize = {app.Roi.SubRoiCenterSize};
                SubRoiNRowColumn = app.Roi.SubRoiNRowColumn;
                SubRoiSeparation = app.Roi.SubRoiSeparation;
                t = table(Name,Y1,Y2,X1,X2,ImageSizeY,ImageSizeX,Angle,...
                    SubRoiCenterSize,SubRoiNRowColumn,SubRoiSeparation);
                app.RoiSetting.updateEntry(t,"Name")
            end
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            app.Roi.rotate(app.RotateAngleEditField.Value)
            app.updateOd
            app.updateRoiField
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            centerSize = app.Roi.CenterSize;
            centerSize(3:4) = centerSize(3:4) - 50;
            app.Roi.CenterSize = centerSize;
            app.updateRoiField
            app.updateOd
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            centerSize = app.Roi.CenterSize;
            centerSize(3:4) = centerSize(3:4) + 50;
            app.Roi.CenterSize = centerSize;
            app.updateRoiField
            app.updateOd
        end

        % Button pushed function: DragtoSelectROIButton
        function DragtoSelectROIButtonPushed(app, event)
            if app.TabGroup.SelectedTab.Tag ~= "Od"
                app.TabGroup.SelectedTab = app.OpticalDensityTab;
            end
            rec = drawrectangle(app.OdAxes);
            pos = rec.Position;
            delete(rec);
            size = [pos(4),pos(3)];
            if any(round(size)<2)
                return
            end
            center = [pos(2) + pos(4)/2,pos(1) + pos(3)/2];
            center = app.Roi.roi2Full(center);
            app.Roi.CenterSize = [center,size];
            app.updateRoiField
            app.updateOd
        end

        % Button pushed function: BatchDeleteButton
        function BatchDeleteButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = inputdlg("Please enter the run numbers of the runs you want to delete." + ...
                " Separate the numbers by space. Note: this will force a refresh.");
            delete(f);
            if isempty(answer)
                return
            end
            runidx = str2num(answer{1});
            if isempty(runidx)
                warning("Empty run number input. Deletion aborted. Check your input.")
            else
                app.BecExp.deleteRun(runidx);
            end
        end

        % Button pushed function: DeleteThisRunButton
        function DeleteThisRunButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want delete the current run (run number = " + num2str(app.RunNumber) + ")? This will force a refresh.");
            delete(f)
            if answer == "Yes"
                app.BecExp.deleteRun(app.RunNumber);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.updateInfoLabel
        end

        % Button pushed function: SelectFringeMaskButton
        function SelectFringeMaskButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to select fringe mask? The BecExp fringe mask will be updated" + ...
                " and a refresh will be enforced.");
            delete(f)
            if answer == "Yes"
                app.TabGroup.SelectedTab = app.FringesTab;
                app.FringeImage.CData = (app.TempLight - app.TempAtom)./mean(app.TempLight);
                maskPoly = drawpolygon(app.FringeAxes);
                pos = maskPoly.Position;
                pos = flip(pos,2); % To yield the Roi convention.
                fullPos = zeros(size(pos));
                for ii = 1:size(pos,1)
                    fullPos(ii,:) = app.Roi.roi2NoRotationFull(pos(ii,:));
                end
                app.BecExp.Od.FringeRemovalMask = fullPos;
                mask = ~createMask(maskPoly);
                app.TempFringe = mask.*(app.TempLight - app.TempAtom)./mean(app.TempLight);
                app.FringeImage.CData = app.TempFringe;
                delete(maskPoly)
                app.BecExp.refresh
            end
        end

        % Button pushed function: SaveFringeMaskButton
        function SaveFringeMaskButtonPushed(app, event)
            trialName = app.BecExp.Name;
            id = app.BecExp.ConfigParameter.ID;
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to save/overwrite the fringe removal mask " + ...
                "for Trial ["+ trialName +"]?");
            delete(f)
            if answer == "Yes"
                frMask = app.BecExp.Od.FringeRemovalMask;
                if isempty(frMask)
                    return
                else
                    app.BecExpSetting.updateValue(id,"FringeRemovalMask",{frMask})
                    if isvalid(app.BecExp.ControlApp)
                        app.BecExp.ControlApp.TrialSetting.FringeRemovalMask = frMask;
                    end
                end
            end
        end

        % Value changed function: ROINameDropDown
        function ROINameDropDownValueChanged(app, event)
            value = app.ROINameDropDown.Value;
            if ~ismember(value,app.ROINameDropDown.Items)
                app.ROINameDropDown.Value = app.Roi.Name;
                return
            end
        end

        % Button pushed function: SetSubROIsButton
        function SetSubROIsButtonPushed(app, event)
            subRoiCenterSize = app.Roi.SubRoiCenterSize;
            if ~isempty(subRoiCenterSize)
                for ii = 1:size(subRoiCenterSize,1)
                    subRoiCenterSize(ii,1:2) = app.Roi.noRotationFull2Full(subRoiCenterSize(ii,1:2));
                end
            end
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            prompt = {['Enter sub ROI CenterSize as [Center_Y,Center_X,Size_Y,Size_X] ' ...
                '(multiple lines separated by ";" set multiple sub ROIs):'],...
                'Enter number of rows and columns of sub ROIs (must be odd numbers):',...
                'Enter separation between sub ROIs:'};
            answer = inputdlg(prompt,'Set Sub ROIs',1,...
                {mat2str(subRoiCenterSize),mat2str(app.Roi.SubRoiNRowColumn),mat2str(app.Roi.SubRoiSeparation)});
            delete(f);
            if ~isempty(answer)
                subRoiCenterSize = str2num(answer{1});
                for ii = 1:size(subRoiCenterSize,1)
                    subRoiCenterSize(ii,1:2) = app.Roi.full2NoRotationFull(subRoiCenterSize(ii,1:2));
                end

                app.Roi.SubRoiNRowColumn = str2num(answer{2});
                app.Roi.SubRoiSeparation = str2num(answer{3});
                app.Roi.SubRoiCenterSize = subRoiCenterSize;
                app.updateSubRoi
            end
        end

        % Button pushed function: MoveToReferenceButton
        function MoveToReferenceButtonPushed(app, event)
            app.moveMarkerToReference;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'AtomPreviewer';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.Tag = 'AtomPreviewer';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel1 = uipanel(app.GridLayout);
            app.Y1EditFieldLabel = uilabel(app.Panel1);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.Panel1, 'numeric');
            app.Y1EditField.RoundFractionalValues = 'on';
            app.Y1EditField.ValueChangedFcn = createCallbackFcn(app, @Y1EditFieldValueChanged, true);
            app.Y2EditFieldLabel = uilabel(app.Panel1);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.Panel1, 'numeric');
            app.Y2EditField.RoundFractionalValues = 'on';
            app.Y2EditField.ValueChangedFcn = createCallbackFcn(app, @Y2EditFieldValueChanged, true);
            app.X1EditFieldLabel = uilabel(app.Panel1);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.Panel1, 'numeric');
            app.X1EditField.RoundFractionalValues = 'on';
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X2EditFieldLabel = uilabel(app.Panel1);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.Panel1, 'numeric');
            app.X2EditField.RoundFractionalValues = 'on';
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.phiLabel = uilabel(app.Panel1);
            app.phiLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.Panel1, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.SaveasNewROIButton = uibutton(app.Panel1, 'push');
            app.SaveasNewROIButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasNewROIButtonPushed, true);
            app.SaveasNewROIButton.Text = 'Save as New ROI';
            app.UpdateBecExpROIButton = uibutton(app.Panel1, 'push');
            app.UpdateBecExpROIButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateBecExpROIButtonPushed, true);
            app.UpdateBecExpROIButton.Text = 'Update BecExp ROI';
            app.ROINameDropDown = uidropdown(app.Panel1);
            app.ROINameDropDown.Editable = 'on';
            app.ROINameDropDown.ValueChangedFcn = createCallbackFcn(app, @ROINameDropDownValueChanged, true);
            app.ROINameDropDown.ClickedFcn = createCallbackFcn(app, @ROINameDropDownClicked, true);
            app.TrialLabel = uilabel(app.Panel1);
            app.TrialLabel.Text = 'EvapDTof';
            app.OverwriteROIFileButton = uibutton(app.Panel1, 'push');
            app.OverwriteROIFileButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteROIFileButtonPushed, true);
            app.OverwriteROIFileButton.Text = 'Overwrite ROI File';
            app.SNLabel = uilabel(app.Panel1);
            app.SNLabel.Text = 'SN#: 1024';
            app.ROINameLabel = uilabel(app.Panel1);
            app.ROINameLabel.Text = 'ROI Name:';
            app.Y1EditFieldLabel_2 = uilabel(app.Panel1);
            app.Y1EditFieldLabel_2.Text = 'Pixels';
            app.Y1EditFieldLabel_3 = uilabel(app.Panel1);
            app.Y1EditFieldLabel_3.Text = 'Pixels';
            app.Y1EditFieldLabel_4 = uilabel(app.Panel1);
            app.Y1EditFieldLabel_4.Text = 'Pixels';
            app.Y1EditFieldLabel_5 = uilabel(app.Panel1);
            app.Y1EditFieldLabel_5.Text = 'Pixels';
            app.Y1EditFieldLabel_6 = uilabel(app.Panel1);
            app.Y1EditFieldLabel_6.Text = '°';
            app.RotateButton = uibutton(app.Panel1, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.RotateAngleEditField = uieditfield(app.Panel1, 'numeric');
            app.Y1EditFieldLabel_7 = uilabel(app.Panel1);
            app.Y1EditFieldLabel_7.Text = '°';
            app.ZoomInButton = uibutton(app.Panel1, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Text = 'Zoom In';
            app.ZoomOutButton = uibutton(app.Panel1, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Zoom Out';
            app.DragtoSelectROIButton = uibutton(app.Panel1, 'push');
            app.DragtoSelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @DragtoSelectROIButtonPushed, true);
            app.DragtoSelectROIButton.Text = 'Drag to Select ROI';
            app.InfoLabel = uilabel(app.Panel1);
            app.SelectFringeMaskButton = uibutton(app.Panel1, 'push');
            app.SelectFringeMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFringeMaskButtonPushed, true);
            app.SelectFringeMaskButton.Text = 'Select Fringe Mask';
            app.SaveFringeMaskButton = uibutton(app.Panel1, 'push');
            app.SaveFringeMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFringeMaskButtonPushed, true);
            app.SaveFringeMaskButton.Text = 'Save Fringe Mask';
            app.SetSubROIsButton = uibutton(app.Panel1, 'push');
            app.SetSubROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SetSubROIsButtonPushed, true);
            app.SetSubROIsButton.Text = 'Set Sub ROIs';
            app.MoveToReferenceButton = uibutton(app.Panel1, 'push');
            app.MoveToReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @MoveToReferenceButtonPushed, true);
            app.MoveToReferenceButton.Text = 'Move To Reference';
            app.Panel2 = uipanel(app.GridLayout);
            app.RunNumberSlider = uislider(app.Panel2);
            app.RunNumberSlider.Limits = [1 1.1];
            app.RunNumberSlider.MajorTicks = [1 2];
            app.RunNumberSlider.ValueChangedFcn = createCallbackFcn(app, @RunNumberSliderValueChanged, true);
            app.RunNumberSlider.MinorTicks = [];
            app.RunNumberSlider.Value = 1;
            app.RunSpinnerLabel = uilabel(app.Panel2);
            app.RunSpinnerLabel.Text = 'Run #';
            app.RunSpinner = uispinner(app.Panel2);
            app.RunSpinner.Limits = [1 1];
            app.RunSpinner.RoundFractionalValues = 'on';
            app.RunSpinner.ValueChangedFcn = createCallbackFcn(app, @RunSpinnerValueChanged, true);
            app.RunSpinner.Value = 1;
            app.DeleteThisRunButton = uibutton(app.Panel2, 'push');
            app.DeleteThisRunButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteThisRunButtonPushed, true);
            app.DeleteThisRunButton.Text = 'Delete This Run';
            app.BatchDeleteButton = uibutton(app.Panel2, 'push');
            app.BatchDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @BatchDeleteButtonPushed, true);
            app.BatchDeleteButton.Text = 'Batch Delete';
            app.ScannedVariableLabel = uilabel(app.Panel2);
            app.ScannedVariableLabel.Interpreter = 'latex';
            app.ScannedVariableLabel.Text = 'Scanned Variable';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.OpticalDensityTab = uitab(app.TabGroup);
            app.OpticalDensityTab.Title = 'Optical Density';
            app.OpticalDensityTab.Tag = 'Od';
            app.OdYAxes = uiaxes(app.OpticalDensityTab);
            ylabel(app.OdYAxes, 'Y')
            zlabel(app.OdYAxes, 'Z')
            app.OdYAxes.XAxisLocation = 'top';
            app.OdXAxes = uiaxes(app.OpticalDensityTab);
            xlabel(app.OdXAxes, 'X')
            zlabel(app.OdXAxes, 'Z')
            app.OdAxes = uiaxes(app.OpticalDensityTab);
            app.OdAxes.CLim = [0 4];
            app.OdAxes.XTickLabel = '';
            app.OdAxes.YTickLabel = '';
            colormap(app.OdAxes, 'jet')
            app.ODminLabel = uilabel(app.OpticalDensityTab);
            app.ODminLabel.Text = 'OD Min';
            app.ODMinEditField = uieditfield(app.OpticalDensityTab, 'numeric');
            app.ODMinEditField.ValueChangedFcn = createCallbackFcn(app, @ODMinEditFieldValueChanged, true);
            app.ODmaxLabel = uilabel(app.OpticalDensityTab);
            app.ODmaxLabel.Text = 'OD Max';
            app.ODMaxEditField = uieditfield(app.OpticalDensityTab, 'numeric');
            app.ODMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ODMaxEditFieldValueChanged, true);
            app.ODMaxEditField.Value = 4;
            app.RawImagesTab = uitab(app.TabGroup);
            app.RawImagesTab.Title = 'Raw Images';
            app.RawImagesTab.Tag = 'Raw';
            app.GridLayout2 = uigridlayout(app.RawImagesTab);
            app.AtomAxes = uiaxes(app.GridLayout2);
            title(app.AtomAxes, 'Atom Image')
            xlabel(app.AtomAxes, 'X')
            ylabel(app.AtomAxes, 'Y')
            zlabel(app.AtomAxes, 'Z')
            app.LightAxes = uiaxes(app.GridLayout2);
            title(app.LightAxes, 'Light Image')
            xlabel(app.LightAxes, 'X')
            ylabel(app.LightAxes, 'Y')
            zlabel(app.LightAxes, 'Z')
            app.DarkAxes = uiaxes(app.GridLayout2);
            title(app.DarkAxes, 'Dark Image')
            xlabel(app.DarkAxes, 'X')
            ylabel(app.DarkAxes, 'Y')
            zlabel(app.DarkAxes, 'Z')
            app.HistAxes = uiaxes(app.GridLayout2);
            title(app.HistAxes, 'Light Camera Counts')
            xlabel(app.HistAxes, 'Light Camera Counts')
            ylabel(app.HistAxes, 'Distribution')
            zlabel(app.HistAxes, 'Z')
            app.FringesTab = uitab(app.TabGroup);
            app.FringesTab.Title = 'Fringes';
            app.FringesTab.Tag = 'Fringe';
            app.FringeAxes = uiaxes(app.FringesTab);
            title(app.FringeAxes, 'Title')
            xlabel(app.FringeAxes, 'X')
            ylabel(app.FringeAxes, 'Y')
            zlabel(app.FringeAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AtomPreviewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end