classdef BecBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BecBrowserUIFigure        matlab.ui.Figure
        ValueLabel                matlab.ui.control.Label
        CiceroVariableDropDown    matlab.ui.control.DropDown
        CiceroVariableLabel       matlab.ui.control.Label
        UnloadButton              matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        AnalysisMethodsPanel      matlab.ui.container.Panel
        Tree                      matlab.ui.container.CheckBoxTree
        OdNode                    matlab.ui.container.TreeNode
        ImagingNode               matlab.ui.container.TreeNode
        AdNode                    matlab.ui.container.TreeNode
        DensityFitNode            matlab.ui.container.TreeNode
        AtomNumberNode            matlab.ui.container.TreeNode
        TofNode                   matlab.ui.container.TreeNode
        CenterFitNode             matlab.ui.container.TreeNode
        KapitzaDiracNode          matlab.ui.container.TreeNode
        CloseButton               matlab.ui.control.Button
        ShowButton                matlab.ui.control.Button
        ShowGIFButton             matlab.ui.control.Button
        StartDateDatePicker       matlab.ui.control.DatePicker
        StartDateDatePickerLabel  matlab.ui.control.Label
        LastWeekButton            matlab.ui.control.Button
        ThisWeekButton            matlab.ui.control.Button
        YesterdayButton           matlab.ui.control.Button
        TodayButton               matlab.ui.control.Button
        MaxRowEditField           matlab.ui.control.NumericEditField
        MaxofRowsLabel            matlab.ui.control.Label
        ServerButtonGroup         matlab.ui.container.ButtonGroup
        LocalButton               matlab.ui.control.ToggleButton
        RemoteButton              matlab.ui.control.ToggleButton
        CloseAllButton            matlab.ui.control.Button
        OpenDataPathButton        matlab.ui.control.Button
        ShowAllButton             matlab.ui.control.Button
        KeyWordsEditField         matlab.ui.control.EditField
        KeyWordsEditFieldLabel    matlab.ui.control.Label
        SerialEditField2          matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        SerialEditField           matlab.ui.control.NumericEditField
        SerialEditFieldLabel      matlab.ui.control.Label
        SerialCheckBox            matlab.ui.control.CheckBox
        TrialNameDropDown         matlab.ui.control.DropDown
        TrialNameDropDownLabel    matlab.ui.control.Label
        EndDateDatePicker         matlab.ui.control.DatePicker
        EndDateDatePickerLabel    matlab.ui.control.Label
        SearchButton              matlab.ui.control.Button
        DataTable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        BecExp BecExp % Description
        FetchedData
        ShownList
        BecExpDatabaseName
        BecExpDatabaseTableName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p1 = BecExpSetting;
            p2 = ComputerConfig;
            app.EndDateDatePicker.Value = datetime("today");
            app.TrialNameDropDown.Items = ["All";sortBecExpTrialName(p1.readColumn("TrialName"))];
            app.BecExpDatabaseName = p2.readValue(1,"BecExpDatabaseName");
            app.BecExpDatabaseTableName = p2.readValue(1,"BecExpDatabaseTableName");
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.ShowAllButton.Enable = "off";
            app.OpenDataPathButton.Enable = "off";
            app.ShowGIFButton.Enable = "off";
            if app.ServerButtonGroup.SelectedObject == app.RemoteButton
                databaseName = app.BecExpDatabaseName;
                databaseTableName = app.BecExpDatabaseTableName;
            else
                databaseName = app.BecExpDatabaseName + "_local";
                databaseTableName = app.BecExpDatabaseTableName;
            end
            startTime = "'" + string(app.StartDateDatePicker.Value) + "'";
            endTime = "'" + string(app.EndDateDatePicker.Value) + " 23:59:59'";
            conn = createReader(databaseName);
            query = "SELECT ""SerialNumber"", ""DateTime"", ""Name""," + ...
                " ""ScannedVariable"", ""Description"", ""DataPath"", ""ObjectPath"" FROM "...
                + databaseTableName +" WHERE ""DateTime"" >= " + startTime + ...
                " AND ""DateTime"" <= " + endTime;
            query = query + " AND ""IsCompleted"" = TRUE";
            if app.TrialNameDropDown.Value ~= "All"
                query = query + " AND ""Name"" = '" + ...
                    app.TrialNameDropDown.Value + "'";
            end
            if app.SerialCheckBox.Value
                query = query + " AND ""SerialNumber"" >= " +...
                    string(app.SerialEditField.Value) + ...
                    " AND ""SerialNumber"" <= " + string(app.SerialEditField2.Value);
            end
            if ~isempty(app.KeyWordsEditField.Value)
                keyWords = strtrim(split(app.KeyWordsEditField.Value,","));
                keyWords = "'%" + keyWords + "%'";
                keyWords = strjoin(keyWords,", ");
                keyWords = "(array[" + keyWords + "])";
                query = query + " AND ""Description"" ILIKE ALL" + keyWords;
            end
            query = query + " ORDER BY ""SerialNumber"" DESC LIMIT " + string(app.MaxRowEditField.Value);
            app.FetchedData = pgFetch(conn,query);
            app.DataTable.Data = app.FetchedData(:,1:5);
        end

        % Button pushed function: ShowAllButton
        function ShowAllButtonPushed(app, event)
            app.DataTable.Enable = "inactive";
            app.SearchButton.Enable = "off";
            % warning off
            % app.BecExp = loadVar(app.FetchedData.ObjectPath(app.DataTable.Selection),"obj");
            % warning on
            app.BecExp.browserShow;
            app.ShownList = app.Tree.CheckedNodes;
        end

        % Button pushed function: OpenDataPathButton
        function OpenDataPathButtonPushed(app, event)
            winopen(app.FetchedData.DataPath(app.DataTable.Selection));
        end

        % Clicked callback: DataTable
        function DataTableClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            if isempty(displayRow)
                app.LoadButton.Enable = "off";
                app.ShowButton.Enable = "off";
                app.OpenDataPathButton.Enable = "off";
            else
                app.LoadButton.Enable = "on";
                app.OpenDataPathButton.Enable = "on";
            end
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            if ~isempty(app.ShownList)
                aMethods = string({app.ShownList.Text});
                for ii = 1:numel(aMethods)
                    app.BecExp.(aMethods(ii)).close;
                end
            end

            app.ShownList = [];
        end

        % Value changed function: TrialNameDropDown
        function TrialNameDropDownValueChanged(app, event)
            value = app.TrialNameDropDown.Value;
            if ~ismember(value,app.TrialNameDropDown.Items)
                app.TrialNameDropDown.Value = "All";
            end
        end

        % Value changed function: SerialCheckBox
        function SerialCheckBoxValueChanged(app, event)
            value = app.SerialCheckBox.Value;
            if value
                app.SerialEditField.Enable = "on";
                app.SerialEditField2.Enable = "on";
            else
                app.SerialEditField.Enable = "off";
                app.SerialEditField2.Enable = "off";
            end
        end

        % Button pushed function: TodayButton
        function TodayButtonPushed(app, event)
            app.StartDateDatePicker.Value = datetime("today");
            app.EndDateDatePicker.Value = datetime("today");
        end

        % Button pushed function: YesterdayButton
        function YesterdayButtonPushed(app, event)
            app.StartDateDatePicker.Value = datetime("yesterday");
            app.EndDateDatePicker.Value = datetime("yesterday");
        end

        % Button pushed function: ThisWeekButton
        function ThisWeekButtonPushed(app, event)
            app.StartDateDatePicker.Value = dateshift(datetime("today"),'start','week');
            app.EndDateDatePicker.Value = datetime("today");
        end

        % Button pushed function: LastWeekButton
        function LastWeekButtonPushed(app, event)
            thisMonday = dateshift(datetime("today"),'start','week');
            lastMonday = thisMonday - days(7);
            app.StartDateDatePicker.Value = lastMonday;
            app.EndDateDatePicker.Value = lastMonday + days(6);
        end

        % Button pushed function: ShowGIFButton
        function ShowGIFButtonPushed(app, event)
            if isvalid(app.BecExp)
                try
                    app.BecExp.Ad.Chart(2).showGif
                catch
                    app.BecExp.Od.Chart(2).showGif
                end
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            if isvalid(app.BecExp)
                allnodes = findall(app.Tree,"Type","uitreenode");
                app.Tree.CheckedNodes = allnodes(ismember(string({allnodes.Text}),app.BecExp.AnalysisMethod));
            end
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    if ismember(selectedAnalysis,app.Tree.CheckedNodes)
                        selectedAnalysisName = string(selectedAnalysis.Text);
                        app.BecExp.(selectedAnalysisName).browserShow
                        if isempty(app.ShownList)
                            app.ShownList = selectedAnalysis;
                        elseif ~ismember(selectedAnalysis,app.ShownList)
                            app.ShownList(end+1) = selectedAnalysis;
                        end
                    end
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    if ismember(selectedAnalysis,app.Tree.CheckedNodes)
                        if ~isempty(app.ShownList)
                            if ismember(selectedAnalysis,app.ShownList)
                                selectedAnalysisName = string(selectedAnalysis.Text);
                                app.BecExp.(selectedAnalysisName).close
                                app.ShownList(app.ShownList == selectedAnalysis) = [];
                            end
                        end
                    end
                end
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            warning off
            app.BecExp = loadVar(app.FetchedData.ObjectPath(app.DataTable.Selection),"obj");
            warning on
            app.LoadButton.Enable = "off";
            app.SearchButton.Enable = "off";
            app.DataTable.Enable = "inactive";
            app.OpenDataPathButton.Enable = "on";
            app.CloseAllButton.Enable = "on";
            app.ShowAllButton.Enable = "on";
            app.UnloadButton.Enable = "on";
            app.ShowButton.Enable = "on";
            app.CloseButton.Enable = "on";
            app.ShowGIFButton.Enable = "on";
            allnodes = findall(app.Tree,"Type","uitreenode");
            app.Tree.CheckedNodes = allnodes(ismember(string({allnodes.Text}),app.BecExp.AnalysisMethod));
            app.CiceroVariableDropDown.Enable = "on";
            app.CiceroVariableDropDown.Items = fields(app.BecExp.CiceroData);

            % This is for backward compatibility
            if isprop(app.BecExp,"Od")
                if numel(app.BecExp.Od.Gui) == 2
                    app.BecExp.Od.Gui(1) = [];
                    if isprop(app.BecExp,"Ad")
                        app.BecExp.IsOdPreview = ~app.BecExp.Ad.Gui(1).IsEnabled;
                        app.BecExp.Ad.Gui(1).IsEnabled = true;
                        app.BecExp.Ad.Gui(1).Name = "AtomPreviewer";
                    end
                end
            end
        end

        % Button pushed function: UnloadButton
        function UnloadButtonPushed(app, event)
            if ~isempty(app.ShownList)
                aMethods = string({app.ShownList.Text});
                for ii = 1:numel(aMethods)
                    app.BecExp.(aMethods(ii)).close;
                end
            end
            delete(app.BecExp)
            
            % Change buttons
            app.DataTable.Enable = "on";
            app.SearchButton.Enable = "on";
            app.OpenDataPathButton.Enable = "off";
            app.ShowGIFButton.Enable = "off";
            app.CloseAllButton.Enable = "off";
            app.ShowAllButton.Enable = "off";
            app.UnloadButton.Enable = "off";
            app.ShowButton.Enable = "off";
            app.CloseButton.Enable = "off";
            app.ShowGIFButton.Enable = "off";
            app.CiceroVariableDropDown.Enable = "off";
            app.ValueLabel.Text = 'Value: ';
            app.ShownList = [];
            
        end

        % Value changed function: CiceroVariableDropDown
        function CiceroVariableDropDownValueChanged(app, event)
            value = app.CiceroVariableDropDown.Value;
            if ~ismember(value,app.CiceroVariableDropDown.Items)
                app.CiceroVariableDropDown.Value = "IterationNum";
            end
            val = app.BecExp.CiceroData.(app.CiceroVariableDropDown.Value);
            if all(val==val(1))
                val = val(1);
                app.ValueLabel.Text = "Value: " + string(val);
            else
                app.ValueLabel.Text = "Value: " + string(min(val)) + ' ~ ' + string(max(val));
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BecBrowserUIFigure = uifigure('Visible', 'off');
            app.BecBrowserUIFigure.Name = 'BecBrowser';
            app.BecBrowserUIFigure.Tag = 'BecBrowser';
            app.DataTable = uitable(app.BecBrowserUIFigure);
            app.DataTable.ColumnName = {'SerialNumber'; 'DateTime'; 'Name'; 'ScannedVariable'; 'Description'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnSortable = [true true true true false];
            app.DataTable.SelectionType = 'row';
            app.DataTable.ClickedFcn = createCallbackFcn(app, @DataTableClicked, true);
            app.DataTable.Multiselect = 'off';
            app.SearchButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.EndDateDatePickerLabel = uilabel(app.BecBrowserUIFigure);
            app.EndDateDatePickerLabel.Text = 'End Date:';
            app.EndDateDatePicker = uidatepicker(app.BecBrowserUIFigure);
            app.EndDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.TrialNameDropDownLabel = uilabel(app.BecBrowserUIFigure);
            app.TrialNameDropDownLabel.Text = 'Trial Name:';
            app.TrialNameDropDown = uidropdown(app.BecBrowserUIFigure);
            app.TrialNameDropDown.Editable = 'on';
            app.TrialNameDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialNameDropDownValueChanged, true);
            app.SerialCheckBox = uicheckbox(app.BecBrowserUIFigure);
            app.SerialCheckBox.ValueChangedFcn = createCallbackFcn(app, @SerialCheckBoxValueChanged, true);
            app.SerialCheckBox.Text = '';
            app.SerialEditFieldLabel = uilabel(app.BecBrowserUIFigure);
            app.SerialEditFieldLabel.Text = 'Serial #';
            app.SerialEditField = uieditfield(app.BecBrowserUIFigure, 'numeric');
            app.SerialEditField.Limits = [1 Inf];
            app.SerialEditField.RoundFractionalValues = 'on';
            app.SerialEditField.Enable = 'off';
            app.SerialEditField.Value = 1;
            app.Label = uilabel(app.BecBrowserUIFigure);
            app.Label.Text = '~';
            app.SerialEditField2 = uieditfield(app.BecBrowserUIFigure, 'numeric');
            app.SerialEditField2.Limits = [1 Inf];
            app.SerialEditField2.Enable = 'off';
            app.SerialEditField2.Value = 1;
            app.KeyWordsEditFieldLabel = uilabel(app.BecBrowserUIFigure);
            app.KeyWordsEditFieldLabel.Text = 'Key Words:';
            app.KeyWordsEditField = uieditfield(app.BecBrowserUIFigure, 'text');
            app.ShowAllButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.ShowAllButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButtonPushed, true);
            app.ShowAllButton.Enable = 'off';
            app.ShowAllButton.Text = 'Show All';
            app.OpenDataPathButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.OpenDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataPathButtonPushed, true);
            app.OpenDataPathButton.Enable = 'off';
            app.OpenDataPathButton.Text = 'Open Data Path';
            app.CloseAllButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Enable = 'off';
            app.CloseAllButton.Text = 'Close All';
            app.ServerButtonGroup = uibuttongroup(app.BecBrowserUIFigure);
            app.ServerButtonGroup.Title = 'Database';
            app.RemoteButton = uitogglebutton(app.ServerButtonGroup);
            app.RemoteButton.Text = 'Remote';
            app.RemoteButton.Value = true;
            app.LocalButton = uitogglebutton(app.ServerButtonGroup);
            app.LocalButton.Text = 'Local';
            app.MaxofRowsLabel = uilabel(app.BecBrowserUIFigure);
            app.MaxofRowsLabel.Text = 'Max Row #:';
            app.MaxRowEditField = uieditfield(app.BecBrowserUIFigure, 'numeric');
            app.MaxRowEditField.Limits = [1 Inf];
            app.MaxRowEditField.RoundFractionalValues = 'on';
            app.MaxRowEditField.Value = 2000;
            app.TodayButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.TodayButton.ButtonPushedFcn = createCallbackFcn(app, @TodayButtonPushed, true);
            app.TodayButton.Text = 'Today';
            app.YesterdayButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.YesterdayButton.ButtonPushedFcn = createCallbackFcn(app, @YesterdayButtonPushed, true);
            app.YesterdayButton.Text = 'Yesterday';
            app.ThisWeekButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.ThisWeekButton.ButtonPushedFcn = createCallbackFcn(app, @ThisWeekButtonPushed, true);
            app.ThisWeekButton.Text = 'This Week';
            app.LastWeekButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.LastWeekButton.ButtonPushedFcn = createCallbackFcn(app, @LastWeekButtonPushed, true);
            app.LastWeekButton.Text = 'Last Week';
            app.StartDateDatePickerLabel = uilabel(app.BecBrowserUIFigure);
            app.StartDateDatePickerLabel.Text = 'Start Date:';
            app.StartDateDatePicker = uidatepicker(app.BecBrowserUIFigure);
            app.StartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.StartDateDatePicker.Value = datetime([2024 1 1]);
            app.ShowGIFButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.ShowGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ShowGIFButtonPushed, true);
            app.ShowGIFButton.Enable = 'off';
            app.ShowGIFButton.Text = 'Show GIF';
            app.AnalysisMethodsPanel = uipanel(app.BecBrowserUIFigure);
            app.AnalysisMethodsPanel.Title = 'Analysis Methods';
            app.ShowButton = uibutton(app.AnalysisMethodsPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Enable = 'off';
            app.ShowButton.Text = 'Show';
            app.CloseButton = uibutton(app.AnalysisMethodsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Text = 'Close';
            app.Tree = uitree(app.AnalysisMethodsPanel, 'checkbox');
            app.OdNode = uitreenode(app.Tree);
            app.OdNode.Text = 'Od';
            app.ImagingNode = uitreenode(app.Tree);
            app.ImagingNode.Text = 'Imaging';
            app.AdNode = uitreenode(app.Tree);
            app.AdNode.Text = 'Ad';
            app.DensityFitNode = uitreenode(app.Tree);
            app.DensityFitNode.Text = 'DensityFit';
            app.AtomNumberNode = uitreenode(app.Tree);
            app.AtomNumberNode.Text = 'AtomNumber';
            app.TofNode = uitreenode(app.Tree);
            app.TofNode.Text = 'Tof';
            app.CenterFitNode = uitreenode(app.Tree);
            app.CenterFitNode.Text = 'CenterFit';
            app.KapitzaDiracNode = uitreenode(app.Tree);
            app.KapitzaDiracNode.Text = 'KapitzaDirac';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.OdNode, app.ImagingNode, app.AdNode];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.LoadButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.UnloadButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.UnloadButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadButtonPushed, true);
            app.UnloadButton.Enable = 'off';
            app.UnloadButton.Text = 'Unload';
            app.CiceroVariableLabel = uilabel(app.BecBrowserUIFigure);
            app.CiceroVariableLabel.Text = 'Cicero Variable:';
            app.CiceroVariableDropDown = uidropdown(app.BecBrowserUIFigure);
            app.CiceroVariableDropDown.Editable = 'on';
            app.CiceroVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @CiceroVariableDropDownValueChanged, true);
            app.CiceroVariableDropDown.Enable = 'off';
            app.ValueLabel = uilabel(app.BecBrowserUIFigure);
            app.ValueLabel.Interpreter = 'latex';
            app.ValueLabel.Text = 'Value: ';
            % Show the figure after all components are created
            app.BecBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BecBrowser

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BecBrowserUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BecBrowserUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BecBrowserUIFigure)
        end
    end
end