classdef BecControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BECExperimentControlPanelUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        RunExperimentTab               matlab.ui.container.Tab
        TestDataPathEditField          matlab.ui.control.EditField
        LocalTestPanel                 matlab.ui.container.Panel
        SelectTestDataPathButton       matlab.ui.control.Button
        RunTimeIntervalEditField       matlab.ui.control.NumericEditField
        RunTimeIntervalEditFieldLabel  matlab.ui.control.Label
        secondsLabel                   matlab.ui.control.Label
        LocalTestCheckBox              matlab.ui.control.CheckBox
        PlotHistogramofScannedVariableButton  matlab.ui.control.Button
        PlotMixandAnimationButton      matlab.ui.control.Button
        FASTSTOPButton                 matlab.ui.control.Button
        HoldButton                     matlab.ui.control.StateButton
        RESUMEButton                   matlab.ui.control.Button
        DeletedRunVariableButton       matlab.ui.control.Button
        OpenDataPathButton             matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        PAUSEButton                    matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        UpdateButton                   matlab.ui.control.Button
        LoadSavedExperimentTab         matlab.ui.container.Tab
        ServerButtonGroup              matlab.ui.container.ButtonGroup
        LocalButton                    matlab.ui.control.ToggleButton
        RemoteButton                   matlab.ui.control.ToggleButton
        KeyWordsEditField              matlab.ui.control.EditField
        KeyWordsEditFieldLabel         matlab.ui.control.Label
        HoldButton_3                   matlab.ui.control.StateButton
        RefreshButton_3                matlab.ui.control.Button
        UnloadButton                   matlab.ui.control.Button
        OpenDataPathButton_3           matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        SearchButton                   matlab.ui.control.Button
        SerialEditField2               matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        SerialCheckBox                 matlab.ui.control.CheckBox
        SerialEditField                matlab.ui.control.NumericEditField
        SerialLabel                    matlab.ui.control.Label
        TrialNameDropDown_2            matlab.ui.control.DropDown
        TrialNameLabel_2               matlab.ui.control.Label
        DataTable                      matlab.ui.control.Table
        EndDateDatePicker              matlab.ui.control.DatePicker
        EndDateLabel                   matlab.ui.control.Label
        StartDateDatePicker            matlab.ui.control.DatePicker
        StartDateLabel                 matlab.ui.control.Label
        MoreFunctionsTab               matlab.ui.container.Tab
        CreateParallelPoolButton       matlab.ui.control.Button
        ResetImageAcquisitionButton    matlab.ui.control.Button
        AssignBecExptoWorkSpaceButton  matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        WarningsandErrorsTextArea      matlab.ui.control.TextArea
        WarningsandErrorsLabel         matlab.ui.control.Label
        OpenCiceroOriginButton         matlab.ui.control.Button
        OpenmmConfigButton             matlab.ui.control.Button
        RunSetParameterButton          matlab.ui.control.Button
        RunLogTextArea                 matlab.ui.control.TextArea
        RunLogLabel                    matlab.ui.control.Label
        BECExperimentPropertiesPanel   matlab.ui.container.Panel
        ShowCenterReferenceCheckBox    matlab.ui.control.CheckBox
        CenterReferenceDropDown        matlab.ui.control.DropDown
        CloudCenterReferenceLabel      matlab.ui.control.Label
        AverageDensityCheckBox         matlab.ui.control.CheckBox
        RenameTrialTypeButton          matlab.ui.control.Button
        ScannedVariable2DropDown       matlab.ui.control.DropDown
        ScannedVariable2Label          matlab.ui.control.Label
        EditHardwareAssociationButton  matlab.ui.control.Button
        AveragingMethodDropDown        matlab.ui.control.DropDown
        AveragingMethodLabel           matlab.ui.control.Label
        DescriptionTextArea            matlab.ui.control.TextArea
        DescriptionTextAreaLabel       matlab.ui.control.Label
        AddNewVariableButton           matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        OdTab                          matlab.ui.container.Tab
        ODMinEditField                 matlab.ui.control.NumericEditField
        ODMaxLabel                     matlab.ui.control.Label
        ODMaxEditField                 matlab.ui.control.NumericEditField
        ODMinLabel                     matlab.ui.control.Label
        FringeRemovalMethodDropDown    matlab.ui.control.DropDown
        FringeRemovalMethodLabel       matlab.ui.control.Label
        ShowFringeRemovalDataCheckBox  matlab.ui.control.CheckBox
        ImagingTab                     matlab.ui.container.Tab
        ImagingMethodDropDown          matlab.ui.control.DropDown
        ImagingMethodDropDownLabel     matlab.ui.control.Label
        ImagingStageDropDown           matlab.ui.control.DropDown
        ImagingStageLabel              matlab.ui.control.Label
        AdTab                          matlab.ui.container.Tab
        CustomXLabelEditField          matlab.ui.control.EditField
        XaxisLabel                     matlab.ui.control.Label
        times1013m2Label_2             matlab.ui.control.Label
        times1013m2Label               matlab.ui.control.Label
        ADMinEditField                 matlab.ui.control.NumericEditField
        ADMaxLabel                     matlab.ui.control.Label
        ADMaxEditField                 matlab.ui.control.NumericEditField
        ADMinLabel                     matlab.ui.control.Label
        ADMethodDropDown               matlab.ui.control.DropDown
        ADMethodLabel                  matlab.ui.control.Label
        DensityFitTab                  matlab.ui.container.Tab
        DensityFitMethodDropDown       matlab.ui.control.DropDown
        DensityFitMethodLabel          matlab.ui.control.Label
        AtomNumberTab                  matlab.ui.container.Tab
        AtomNumberFitMethodDropDown    matlab.ui.control.DropDown
        AtomNumberFitMethodDropDownLabel  matlab.ui.control.Label
        ShowNormalizedAtomNumberCheckBox  matlab.ui.control.CheckBox
        ShowThermalCheckBox            matlab.ui.control.CheckBox
        ShowRawCheckBox                matlab.ui.control.CheckBox
        times106Label                  matlab.ui.control.Label
        AtomNumberMaxEditField         matlab.ui.control.NumericEditField
        AtomNumberMaxLabel             matlab.ui.control.Label
        TofTab                         matlab.ui.container.Tab
        CenterFitTab                   matlab.ui.container.Tab
        ModifyYThermalFitButton        matlab.ui.control.Button
        ModifyXThermalFitButton        matlab.ui.control.Button
        SaveCloudCenterCheckBox        matlab.ui.control.CheckBox
        CenterFitMethodDropDown        matlab.ui.control.DropDown
        CenterFitMethodDropDownLabel   matlab.ui.control.Label
        ScopeValueTab                  matlab.ui.container.Tab
        ScopeValueNameTextArea         matlab.ui.control.TextArea
        ScopeValueNameLabel            matlab.ui.control.Label
        KapitzaDiracTab                matlab.ui.container.Tab
        mumLabel_2                     matlab.ui.control.Label
        mumLabel                       matlab.ui.control.Label
        CloudSizeEditField             matlab.ui.control.NumericEditField
        CloudSizeEditFieldLabel        matlab.ui.control.Label
        WaistEditField                 matlab.ui.control.NumericEditField
        WaistEditFieldLabel            matlab.ui.control.Label
        RegenerateROIButton            matlab.ui.control.Button
        DoKdFitButton                  matlab.ui.control.Button
        ScopeChannelEditField          matlab.ui.control.EditField
        ScopeChannelEditFieldLabel     matlab.ui.control.Label
        ScanTypeDropDown               matlab.ui.control.DropDown
        ScanTypeDropDownLabel          matlab.ui.control.Label
        ROIMethodDropDown              matlab.ui.control.DropDown
        ROIMethodDropDownLabel         matlab.ui.control.Label
        nmLabel                        matlab.ui.control.Label
        ROISizeEditField               matlab.ui.control.NumericEditField
        ROISizeEditFieldLabel          matlab.ui.control.Label
        MaxOrderEditField              matlab.ui.control.NumericEditField
        MaxOrderLabel                  matlab.ui.control.Label
        LatticeAxisDropDown            matlab.ui.control.DropDown
        LatticeAxisDropDownLabel       matlab.ui.control.Label
        WavelengthEditField            matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel       matlab.ui.control.Label
        ParameterMethodDropDown        matlab.ui.control.DropDown
        ParameterMethodDropDownLabel   matlab.ui.control.Label
        KdFitMethodDropDown            matlab.ui.control.DropDown
        KdFitMethodDropDownLabel       matlab.ui.control.Label
        KapitzaPhaseDiagramTab         matlab.ui.container.Tab
        DimensionlessVariables         matlab.ui.control.CheckBox
        DrawTheoryButton               matlab.ui.control.Button
        KapitzaInvertCheckBox          matlab.ui.control.CheckBox
        KapitzaNoiseFloorEditField     matlab.ui.control.NumericEditField
        NoiseFloorLabel                matlab.ui.control.Label
        KapitzaMetricDropDown          matlab.ui.control.DropDown
        KapitzaMetricLabel             matlab.ui.control.Label
        ModulationMonitorTab           matlab.ui.container.Tab
        ChirpEndFreqVarDropDown        matlab.ui.control.DropDown
        ModulationFrequencyLabel_2     matlab.ui.control.Label
        ChirpCheckBox                  matlab.ui.control.CheckBox
        Ch1CorrectionEditField         matlab.ui.control.NumericEditField
        OffsetLabel                    matlab.ui.control.Label
        Ch2CorrectionEditField         matlab.ui.control.NumericEditField
        OffsetLabel_2                  matlab.ui.control.Label
        Ch2KdTrialEditField            matlab.ui.control.NumericEditField
        CH2multiplierLabel             matlab.ui.control.Label
        Ch1KdTrialEditField            matlab.ui.control.NumericEditField
        Ch1multiplierLabel             matlab.ui.control.Label
        UnitEditField                  matlab.ui.control.EditField
        IfyesunitsLabel                matlab.ui.control.Label
        ConvertVoltsCheckBox           matlab.ui.control.CheckBox
        TargetphaseEditField           matlab.ui.control.NumericEditField
        TargetphaseEditFieldLabel      matlab.ui.control.Label
        CalcRelativePhaseCheckBox      matlab.ui.control.CheckBox
        ModFreqVariableDropDown        matlab.ui.control.DropDown
        ModulationFrequencyLabel       matlab.ui.control.Label
        ScopenameEditField             matlab.ui.control.EditField
        ScopeNameLabel                 matlab.ui.control.Label
        AnalysisMethodsPanel           matlab.ui.container.Panel
        Tree                           matlab.ui.container.CheckBoxTree
        OdNode                         matlab.ui.container.TreeNode
        ImagingNode                    matlab.ui.container.TreeNode
        AdNode                         matlab.ui.container.TreeNode
        ScopeValueNode                 matlab.ui.container.TreeNode
        DensityFitNode                 matlab.ui.container.TreeNode
        AtomNumberNode                 matlab.ui.container.TreeNode
        TofNode                        matlab.ui.container.TreeNode
        CenterFitNode                  matlab.ui.container.TreeNode
        KapitzaDiracNode               matlab.ui.container.TreeNode
        KapitzaPhaseDiagramNode        matlab.ui.container.TreeNode
        ModulationMonitorNode          matlab.ui.container.TreeNode
        SaveButton                     matlab.ui.control.Button
        ShowButton                     matlab.ui.control.Button
        RefreshButton_4                matlab.ui.control.Button
        InitializeButton               matlab.ui.control.Button
        SaveAsNewTrialTypeButton       matlab.ui.control.Button
        AtomLabel                      matlab.ui.control.Label
        OverwriteTrialTypeButton       matlab.ui.control.Button
        ScannedVariableDropDown        matlab.ui.control.DropDown
        ScannedParameterLabel          matlab.ui.control.Label
        TrialNameDropDown              matlab.ui.control.DropDown
        TrialNameLabel                 matlab.ui.control.Label
        SerialNumberLabel              matlab.ui.control.Label
        AutoAcquireImagesCheckBox      matlab.ui.control.CheckBox
        ROIDropDown                    matlab.ui.control.DropDown
        ROILabel                       matlab.ui.control.Label
        AcquisitionDropDown            matlab.ui.control.DropDown
        AcquisitionLabel               matlab.ui.control.Label
        PreviewerButtonGroup           matlab.ui.container.ButtonGroup
        AtomicDensityButton            matlab.ui.control.ToggleButton
        OpticalDensityButton           matlab.ui.control.ToggleButton
        TimeLabel                      matlab.ui.control.Label
    end


    properties (Access = private)
        RepoPath % The path to the MuscleMuseum package
        IsMainComputer % If the computer is the main experiment computer
    end

    properties
        BecExp BecExp = BecExp.empty % Description
        IsStarted logical = false
        BecExpSetting BecExpSetting
        BecExpConfig BecExpConfig
        BecExpData BecExpData
        RoiSetting RoiSetting
        AcquisitionSetting AcquisitionSetting
        BecExpVariableUnit BecExpVariableUnit
        HardwareAssociation HardwareAssociation
        TrialSetting
    end

    methods (Access = private)
        
        function displaySettingProperty(app)
            % Configuration of the selected trial
            trialSetting = app.BecExpSetting.readEntry(string(app.TrialNameDropDown.Value),"TrialName");

            % Display general properties according to the configuration
            app.AtomLabel.Text = "Atom: " + trialSetting.AtomName;
            app.AcquisitionDropDown.Value = trialSetting.AcquisitionName;
            app.ROIDropDown.Value = trialSetting.RoiName;
            app.AutoAcquireImagesCheckBox.Value = trialSetting.IsAutoAcquire;
            app.DescriptionTextArea.Value = trialSetting.Description;
            app.FringeRemovalMethodDropDown.Value = trialSetting.FringeRemovalMethod;
            app.ImagingStageDropDown.Value = trialSetting.ImagingStage;
            app.ImagingMethodDropDown.Value = trialSetting.ImagingMethod;
            app.ADMethodDropDown.Value = trialSetting.AdMethod;
            app.DensityFitMethodDropDown.Value = trialSetting.DensityFitMethod;
            app.AtomNumberMaxEditField.Value = trialSetting.AtomNumberYLim;
            app.AtomNumberFitMethodDropDown.Value = trialSetting.AtomNumberFitMethod;
            app.ADMaxEditField.Value = trialSetting.AdCLim;
            app.ADMinEditField.Value = 0;
            app.ODMaxEditField.Value = trialSetting.OdCLim;
            app.ODMinEditField.Value = 0;
            app.CenterFitMethodDropDown.Value = trialSetting.CenterFitMethod;
            app.ScopeValueNameTextArea.Value = trialSetting.ScopeValueName;
            app.AverageDensityCheckBox.Value = trialSetting.IsDensityAverage;
            app.ShowCenterReferenceCheckBox.Value = trialSetting.IsShowCenterReference;
            try
                app.AveragingMethodDropDown.Value=trialSetting.AveragingMethod;
            catch
                app.AveragingMethodDropDown.Value="StdErr";
            end

            % Display Kd Parameter
            app.WavelengthEditField.Value = trialSetting.KdParameter.Wavelength * 1e9;
            app.LatticeAxisDropDown.Value = trialSetting.KdParameter.LatticeAxis;
            app.MaxOrderEditField.Value = trialSetting.KdParameter.OrderMax;
            app.ROISizeEditField.Value = trialSetting.KdParameter.RoiSize;
            app.ROIMethodDropDown.Value = trialSetting.KdParameter.RoiMethod;
            app.KdFitMethodDropDown.Value = trialSetting.KdParameter.KdFitMethod;
            app.ParameterMethodDropDown.Value = trialSetting.KdParameter.ParameterMethod;
            app.ScanTypeDropDown.Value = trialSetting.KdParameter.ScanType;
            app.ScopeChannelEditField.Value = trialSetting.KdParameter.ScopeChannel;
            if isfield(trialSetting.KdParameter,'Waist')
                app.WaistEditField.Value = trialSetting.KdParameter.Waist * 1e6;
            end
            if isfield(trialSetting.KdParameter,'CloudSize')
                app.CloudSizeEditField.Value = trialSetting.KdParameter.CloudSize * 1e6;
            end

            % Display Kapitza Phase Diagram Parameter
            if isfield(trialSetting, 'KapitzaParameter')
                app.KapitzaMetricDropDown.Value = trialSetting.KapitzaParameter.MetricName;
                app.KapitzaNoiseFloorEditField.Value = trialSetting.KapitzaParameter.NoiseFloor;
                app.KapitzaInvertCheckBox.Value = trialSetting.KapitzaParameter.InvertOmegaAxis;
            end

            % Display Modulation Monitor Parameter
            if isfield(trialSetting, 'ModulationParameter')

                v = char(trialSetting.ModulationParameter.ExpectedFrequencyVar);
                if ~any(strcmp(v, string(app.ModFreqVariableDropDown.Items)))
                    app.ModFreqVariableDropDown.Items = [string(app.ModFreqVariableDropDown.Items(:)); string(v)];
                end
                app.ModFreqVariableDropDown.Value = v;

                app.TargetphaseEditField.Value = trialSetting.ModulationParameter.PhaseTarget;
                app.CalcRelativePhaseCheckBox.Value = trialSetting.ModulationParameter.CalculatePhase;
                app.ScopenameEditField.Value = trialSetting.ModulationParameter.ScopeName;
                if isfield(trialSetting.ModulationParameter, 'IsConvertVolts')
                    app.ConvertVoltsCheckBox.Value = trialSetting.ModulationParameter.IsConvertVolts;
                    app.UnitEditField.Value = char(trialSetting.ModulationParameter.VoltUnit);
                    % Settings saved before the KD-calibration change have no KdTrial or
                    % CorrectionDivisor field. Fall back to the uncalibrated defaults rather
                    % than throwing, or the panel will not open at all on an old settings file.
                    if isfield(trialSetting.ModulationParameter, 'KdTrial')
                        kdT = ModulationMonitor.padCal(trialSetting.ModulationParameter.KdTrial, 0);
                    else
                        kdT = [0 0 0 0];
                    end
                    if isfield(trialSetting.ModulationParameter, 'CorrectionDivisor')
                        corrD = ModulationMonitor.padCal(trialSetting.ModulationParameter.CorrectionDivisor, 1);
                    else
                        corrD = [1 1 1 1];
                    end

                    app.Ch1KdTrialEditField.Value    = kdT(1);
                    app.Ch2KdTrialEditField.Value    = kdT(2);
                    app.Ch1CorrectionEditField.Value = corrD(1);
                    app.Ch2CorrectionEditField.Value = corrD(2);
                end
                if isfield(trialSetting.ModulationParameter, 'IsChirp')
                    app.ChirpCheckBox.Value = trialSetting.ModulationParameter.IsChirp;
                    v = char(trialSetting.ModulationParameter.ChirpEndFreqVar);
                    if isempty(v), v = 'None'; end
                    if ~any(strcmp(v, string(app.ChirpEndFreqVarDropDown.Items)))
                        app.ChirpEndFreqVarDropDown.Items = [string(app.ChirpEndFreqVarDropDown.Items(:)); string(v)];
                    end
                    app.ChirpEndFreqVarDropDown.Value = v;
                end
            end

            % Handle second scanned parameter for 2D scans
            app.ScannedVariableDropDown.Value = ...
                app.BecExpVariableUnit.readValue(trialSetting.ScannedVariableID,"ScannedVariable");
            if trialSetting.ScannedVariableID2 ~= 0
                app.ScannedVariable2DropDown.Value = ...
                    app.BecExpVariableUnit.readValue(trialSetting.ScannedVariableID2,"ScannedVariable");
            else
                app.ScannedVariable2DropDown.Value = "None";
            end


            % Display cloud center reference
            if trialSetting.CenterReferenceID
                app.CenterReferenceDropDown.Value = app.BecExpSetting.readValue(trialSetting.CenterReferenceID,"TrialName");
            else
                app.CenterReferenceDropDown.Value = "None";
            end

            % Display data analysis
            allnodes = findall(app.Tree,"Type","uitreenode");
            aMethods = rmmissing(["Od";"Imaging";"Ad";... %Od, Imaging and Ad as default analyses
                trialSetting.AnalysisMethod(:)]);
            aMethods(aMethods == "None") = [];
            app.Tree.CheckedNodes = allnodes(ismember(string({allnodes.Text}),aMethods));

            % Update TrialConfig
            app.TrialSetting = trialSetting;
        end

        function aMethods = getAnalysisMethod(app)
            % This function is for reformattomg the analysis methods
            aMethods = string({app.Tree.CheckedNodes.Text});
            aMethods(ismember(aMethods,["Od";"Imaging";"Ad"])) = [];
            aMethods = aMethods(:);
        end

        function setBecExpProperty(app)
            % Set previewers
            if app.PreviewerButtonGroup.SelectedObject == app.OpticalDensityButton
                app.BecExp.IsOdPreview = true;
            else
                app.BecExp.IsOdPreview = false;
            end

            % We do not want to have mix and animation plots when we
            % refresh during the experiments.
            app.BecExp.Od.Chart(1).IsEnabled = false;
            app.BecExp.Od.Chart(2).IsEnabled = false;
            app.BecExp.Ad.Chart(1).IsEnabled = false;
            app.BecExp.Ad.Chart(2).IsEnabled = false;

            % Show fringe removal plots.
            app.BecExp.Od.Gui(1).IsEnabled = app.ShowFringeRemovalDataCheckBox.Value;

            % Set if we want to save the cloud center
            if isprop(app.BecExp,'CenterFit')
                app.BecExp.CenterFit.IsSaveCenter = app.SaveCloudCenterCheckBox.Value;
            end

            % Set atom number plots display
            if isprop(app.BecExp,'AtomNumber')
                app.BecExp.AtomNumber.IsShowNormalized = app.ShowNormalizedAtomNumberCheckBox.Value;
                app.BecExp.AtomNumber.IsShowRaw = app.ShowRawCheckBox.Value;
                app.BecExp.AtomNumber.IsShowThermal = app.ShowThermalCheckBox.Value;
            end
        end

        function toggleButtonStart(app)
            % Disable unwanted buttons when running the experiment.
            app.AutoAcquireImagesCheckBox.Enable = "off";
            app.AcquisitionDropDown.Enable = "off";
            app.TrialNameDropDown.Enable = "off";
            app.ROIDropDown.Enable = "off";
            app.RunSetParameterButton.Enable = "off";
            app.LoadButton.Enable = "off";
            app.LocalTestCheckBox.Enable = "off";
            app.EditHardwareAssociationButton.Enable = "off";
            app.RenameTrialTypeButton.Enable = "off";
            app.PlotHistogramofScannedVariableButton.Enable = "on";
        end

        function toggleButtonStop(app)
            % Enable button when finishing running the experiment.
            app.AutoAcquireImagesCheckBox.Enable = "on";
            app.AcquisitionDropDown.Enable = "on";
            app.TrialNameDropDown.Enable = "on";
            app.ROIDropDown.Enable = "on";
            app.RunSetParameterButton.Enable = "on";
            app.LocalTestCheckBox.Enable = "on";
            app.EditHardwareAssociationButton.Enable = "on";
            app.RenameTrialTypeButton.Enable = "on";
            app.PlotHistogramofScannedVariableButton.Enable = "off";
        end

        function displayBecExpProperty(app)
            % Display general properties
            app.ScannedVariableDropDown.Value = app.BecExp.ScannedVariable;
            app.ScannedVariable2DropDown.Value = app.BecExp.ScannedVariable2;
            app.AcquisitionDropDown.Value = app.BecExp.Acquisition.Name;
            app.ROIDropDown.Value = app.BecExp.Roi.Name;
            app.DescriptionTextArea.Value = app.BecExp.Description;
            app.TimeLabel.Text = "Time: " + string(app.BecExp.DateTime);
            app.SerialNumberLabel.Text = "Serial Number: " + string(app.BecExp.SerialNumber);
            app.AverageDensityCheckBox.Value = app.BecExp.IsDensityAverage;
            app.ShowCenterReferenceCheckBox.Value = app.BecExp.IsShowCenterReference;
            if ~app.BecExp.CenterReferenceID
                app.CenterReferenceDropDown.Value = "None";
            else
                app.CenterReferenceDropDown.Value = ...
                    app.BecExpSetting.readValue(app.BecExp.CenterReferenceID,"TrialName");
            end

            % Choose Od or Ad previewer. Choose if we want to show Fringe
            % removal
            if app.BecExp.IsOdPreview
                app.PreviewerButtonGroup.SelectedObject = app.OpticalDensityButton;
            else
                app.PreviewerButtonGroup.SelectedObject = app.AtomicDensityButton;
            end
            app.ShowFringeRemovalDataCheckBox.Value = app.BecExp.Od.Gui(1).IsEnabled;

            % Display analysis properties
            allnodes = findall(app.Tree,"Type","uitreenode");
            app.Tree.CheckedNodes = allnodes(ismember(string({allnodes.Text}),app.BecExp.AnalysisMethod));
            app.FringeRemovalMethodDropDown.Value = app.BecExp.Od.FringeRemovalMethod;
            app.ODMinEditField.Value = app.BecExp.Od.CLim(1);
            app.ODMaxEditField.Value = app.BecExp.Od.CLim(2);
            app.ImagingStageDropDown.Value = app.BecExp.Imaging.ImagingStage;
            app.ImagingMethodDropDown.Value = app.BecExp.Imaging.ImagingMethod;
            app.ADMethodDropDown.Value = app.BecExp.Ad.AdMethod;
            app.ADMinEditField.Value = app.BecExp.Ad.CLim(1);
            app.ADMaxEditField.Value = app.BecExp.Ad.CLim(2);
            app.AutoAcquireImagesCheckBox.Value = app.BecExp.IsAutoAcquire;
            if isprop(app.BecExp,'DensityFit')
                app.DensityFitMethodDropDown.Value = app.BecExp.DensityFit.FitMethod;
            end
            if isprop(app.BecExp,'AtomNumber')
                app.AtomNumberMaxEditField.Value = app.BecExp.AtomNumber.YLim(2);
                app.AtomNumberFitMethodDropDown.Value = app.BecExp.AtomNumber.FitMethod;
                app.ShowRawCheckBox.Value = app.BecExp.AtomNumber.IsShowRaw;
                app.ShowThermalCheckBox.Value = app.BecExp.AtomNumber.IsShowThermal;
                app.ShowNormalizedAtomNumberCheckBox.Value = app.BecExp.AtomNumber.IsShowNormalized;
            end
            if isprop(app.BecExp,'CenterFit')
                app.CenterFitMethodDropDown.Value = app.BecExp.CenterFit.FitMethod;
            end
            if isprop(app.BecExp,'ScopeValue')
                app.ScopeValueNameTextArea.Value = strjoin(app.BecExp.ScopeValue.FullValueName,";");
            end
            if isprop(app.BecExp,"KapitzaDirac")
                app.WavelengthEditField.Value = app.BecExp.KapitzaDirac.Wavelength * 1e9;
                app.LatticeAxisDropDown.Value = app.BecExp.KapitzaDirac.LatticeAxis;
                app.MaxOrderEditField.Value = app.BecExp.KapitzaDirac.OrderMax;
                app.ROISizeEditField.Value = app.BecExp.KapitzaDirac.RoiSize;
                app.ROIMethodDropDown.Value = app.BecExp.KapitzaDirac.RoiMethod;
                app.KdFitMethodDropDown.Value = app.BecExp.KapitzaDirac.KdFitMethod;
                app.ParameterMethodDropDown.Value = app.BecExp.KapitzaDirac.ParameterMethod;
                app.ScanTypeDropDown.Value = app.BecExp.KapitzaDirac.ScanType;
                app.ScopeChannelEditField.Value = app.BecExp.KapitzaDirac.ScopeChannel;
                app.WaistEditField.Value = app.BecExp.KapitzaDirac.Waist * 1e6;
                app.CloudSizeEditField.Value = app.BecExp.KapitzaDirac.CloudSize * 1e6;
            end
            if isprop(app.BecExp, "KapitzaPhaseDiagram")
                app.KapitzaMetricDropDown.Value = app.BecExp.KapitzaPhaseDiagram.MetricName;
                app.KapitzaNoiseFloorEditField.Value = app.BecExp.KapitzaPhaseDiagram.NoiseFloor;
                app.KapitzaInvertCheckBox.Value = app.BecExp.KapitzaPhaseDiagram.InvertOmegaAxis;
            end

            if isprop(app.BecExp, 'ModulationMonitor')
                v = char(app.BecExp.ModulationMonitor.ExpectedFrequencyVar);
                if ~any(strcmp(v, string(app.ModFreqVariableDropDown.Items)))
                    app.ModFreqVariableDropDown.Items = [string(app.ModFreqVariableDropDown.Items(:)); string(v)];
                end
                app.ModFreqVariableDropDown.Value = v;
                app.TargetphaseEditField.Value = app.BecExp.ModulationMonitor.PhaseTarget;
                app.CalcRelativePhaseCheckBox.Value = app.BecExp.ModulationMonitor.CalculatePhase;
                app.ScopenameEditField.Value = app.BecExp.ModulationMonitor.ScopeName;
                app.ConvertVoltsCheckBox.Value = app.BecExp.ModulationMonitor.IsConvertVolts;
                app.UnitEditField.Value = char(app.BecExp.ModulationMonitor.VoltUnit);
                app.Ch1KdTrialEditField.Value = app.BecExp.ModulationMonitor.KdTrial(1);
                app.Ch2KdTrialEditField.Value = app.BecExp.ModulationMonitor.KdTrial(2);
                app.Ch1CorrectionEditField.Value = app.BecExp.ModulationMonitor.CorrectionDivisor(1);
                app.Ch2CorrectionEditField.Value = app.BecExp.ModulationMonitor.CorrectionDivisor(2);
                app.ChirpCheckBox.Value = app.BecExp.ModulationMonitor.IsChirp;
                v = char(app.BecExp.ModulationMonitor.ChirpEndFreqVar);
                if isempty(v), v = 'None'; end
                if ~any(strcmp(v, string(app.ChirpEndFreqVarDropDown.Items)))
                    app.ChirpEndFreqVarDropDown.Items = [string(app.ChirpEndFreqVarDropDown.Items(:)); string(v)];
                end
                app.ChirpEndFreqVarDropDown.Value = v;
            end
            
        end

        function updateCloudCenterList(app)
            ids = app.BecExpData.readColumn("TrialID");
            if ~isempty(ids)
                trialName = app.BecExpSetting.readColumn(["ID","TrialName"]);
                trialName = trialName(ismember(trialName.ID,ids),:).TrialName;
            else
                trialName = string.empty;
            end
            app.CenterReferenceDropDown.Items = ["None";trialName];
            if isempty(app.TrialSetting) || app.TrialSetting.CenterReferenceID == 0
                app.CenterReferenceDropDown.Value = "None";
            else
                app.CenterReferenceDropDown.Value = app.BecExpSetting.readValue(app.TrialSetting.CenterReferenceID,"TrialName");
            end
        end

        function resetButton(app)
            % For some buttons we want to reset them to the default
            % settings after finishing an experiment
            app.ShowFringeRemovalDataCheckBox.Value = false;
            app.SaveCloudCenterCheckBox.Value = false;
            app.ShowNormalizedAtomNumberCheckBox.Value = true;
            app.ShowRawCheckBox.Value = true;
            app.ShowThermalCheckBox.Value = true;
        end

        function updateBecExpScannedVariableFromDropdowns(app)
            if isvalid(app.BecExp)
                var1 = string(app.ScannedVariableDropDown.Value);
                var2 = string(app.ScannedVariable2DropDown.Value);
                id1 = app.BecExpVariableUnit.readValue(var1,"ID","ScannedVariable");
                if var2 == "None"
                    id2 = 0;
                else
                    id2 = app.BecExpVariableUnit.readValue(var2,"ID","ScannedVariable");
                end
                app.BecExp.ScannedVariableID = id1;
                app.BecExp.ScannedVariableID2 = id2;
                app.BecExp.refresh;
            end
        end

        function updateTrialSettingScannedVariableFromDropdowns(app)
            var1 = string(app.ScannedVariableDropDown.Value);
            var2 = string(app.ScannedVariable2DropDown.Value);
            id1 = app.BecExpVariableUnit.readValue(var1,"ID","ScannedVariable");
            if var2 == "None"
                id2 = 0;
            else
                id2 = app.BecExpVariableUnit.readValue(var2,"ID","ScannedVariable");
            end
            app.TrialSetting.ScannedVariableID = id1;
            app.TrialSetting.ScannedVariableID2 = id2;
        end
        
        function applyKdCalibration(app)
            % Gather both channels' trial numbers and correction divisors and push
            % them to the monitor. Loading a KD trial walks the data tree, so that
            % half only happens when the conversion is actually switched on --
            % otherwise typing a trial number would stall the panel for nothing.

            % --- validate correction divisors: zero would blow up the scale ---
            c1 = app.Ch1CorrectionEditField.Value;
            if ~isfinite(c1) || c1 == 0
                c1 = 1;  app.Ch1CorrectionEditField.Value = c1;
            end
            c2 = app.Ch2CorrectionEditField.Value;
            if ~isfinite(c2) || c2 == 0
                c2 = 1;  app.Ch2CorrectionEditField.Value = c2;
            end

            % --- trial numbers: 0 means "not calibrated", negatives are typos --
            t1 = round(app.Ch1KdTrialEditField.Value);
            if ~isfinite(t1) || t1 < 0
                t1 = 0;  app.Ch1KdTrialEditField.Value = t1;
            end
            t2 = round(app.Ch2KdTrialEditField.Value);
            if ~isfinite(t2) || t2 < 0
                t2 = 0;  app.Ch2KdTrialEditField.Value = t2;
            end

            trials = [t1, t2, 0, 0];
            divs   = [c1, c2, 1, 1];

            % --- persist for future trials, always -----------------------------
            app.TrialSetting.ModulationParameter.KdTrial           = trials;
            app.TrialSetting.ModulationParameter.CorrectionDivisor = divs;

            % --- push to the live monitor --------------------------------------
            if isempty(app.BecExp) || ~isvalid(app.BecExp) ...
                    || ~isprop(app.BecExp, 'ModulationMonitor') ...
                    || isempty(app.BecExp.ModulationMonitor)
                return
            end
            mm = app.BecExp.ModulationMonitor;

            % Assigning these only marks the calibration dirty; the class resolves
            % it lazily on the next convert().
            mm.KdTrial           = trials;
            mm.CorrectionDivisor = divs;

            if ~mm.IsConvertVolts
                % Conversion is off: nothing on screen depends on these numbers
                % yet, so do not pay for loadBecExp or a redraw.
                app.setCalibrationStatus('Stored. Tick "Convert volts?" to apply.', false);
                return
            end

            fig = ancestor(app.Ch1KdTrialEditField, 'figure');
            oldPtr = fig.Pointer;
            fig.Pointer = 'watch';  drawnow limitrate
            cleanupPtr = onCleanup(@() set(fig, 'Pointer', oldPtr)); %#ok<NASGU>

            % Resolve now rather than on the next draw, so a bad trial number is
            % reported while the user is still looking at the field.
            mm.resolveCalibration(true);
            app.setCalibrationStatus(mm.calibrationSummary(), ~mm.calibrationOk());

            % Conversion is display-only: redraw, never re-measure.
            mm.updateFigure(app.BecExp.NCompletedRun);
        end
        
        function setCalibrationStatus(app, msg, isBad)
            if ~isprop(app,'CalibrationStatusLabel') || ~isvalid(app.CalibrationStatusLabel)
                return
            end
            app.CalibrationStatusLabel.Text = msg;
            if isBad
                app.CalibrationStatusLabel.FontColor = [0.80 0.20 0.15];
            else
                app.CalibrationStatusLabel.FontColor = [0.25 0.25 0.25];
            end
        end
    end

    methods (Access = public)

        function displayLog(app,str,logType)
            % Display log in log box. [str] must be a string scalar.
            arguments
                app
                str string
                logType string = "normal"
            end

            t = datetime("now");
            if logType == "normal"
                if str == " "
                    app.RunLogTextArea.Value = [app.RunLogTextArea.Value;str];
                else
                    app.RunLogTextArea.Value = [app.RunLogTextArea.Value;string(t) + " " + str];
                end
                scroll(app.RunLogTextArea, 'bottom')
            else
                app.WarningsandErrorsTextArea.Value = ...
                    [app.WarningsandErrorsTextArea.Value;string(t) + " " + logType + ": " + str];
                scroll(app.WarningsandErrorsTextArea, 'bottom')
                if logType == "error"
                    error(str)
                end
            end
        end
        
        function resetVariableDropDown(app)
            app.ScannedVariableDropDown.Items = sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"));
            app.ScannedVariable2DropDown.Items = ["None"; sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"))];

            app.ModFreqVariableDropDown.Items = sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"));
            app.ChirpEndFreqVarDropDown.Items = ["None"; sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"))];

            id = app.TrialSetting(app.TrialSetting.TrialName == app.TrialNameDropDown.Value,:).ScannedVariableID;
            app.ScannedVariableDropDown.Value = app.BecExpVariableUnit.readValue(id,"ScannedVariable");
            id2 = app.TrialSetting(app.TrialSetting.TrialName == app.TrialNameDropDown.Value,:).ScannedVariableID2;
            if id2 ~= 0
                app.ScannedVariable2DropDown.Value = app.BecExpVariableUnit.readValue(id2,"ScannedVariable");
            else
                app.ScannedVariable2DropDown.Value = "None";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Initialize property display
            app.BecExpSetting = BecExpSetting;
            app.BecExpConfig = BecExpConfig;
            app.BecExpData = BecExpData;
            app.BecExpVariableUnit = BecExpVariableUnit;
            app.AcquisitionSetting = AcquisitionSetting;
            app.RoiSetting = RoiSetting;
            app.HardwareAssociation = HardwareAssociation;
            p = ComputerConfig;
            computerConfig = p.readTable;

            %% Check computer
            computerName = string(getenv('computername'));
            app.IsMainComputer = computerName == computerConfig.BecExpControlComputerName;

            %% Display setting options
            app.TrialNameDropDown.Items = sortBecExpTrialName(app.BecExpSetting.readColumn("TrialName"));
            app.TrialNameDropDown.Value = "Test";

            app.ScannedVariableDropDown.Items = sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"));
            app.ScannedVariable2DropDown.Items = ["None"; sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"))];
            app.ScannedVariable2DropDown.Value = "None";
            app.AcquisitionDropDown.Items = app.AcquisitionSetting.readColumn("Name");
            app.ROIDropDown.Items = ["Full";app.RoiSetting.readColumn("Name")];
            app.ImagingStageDropDown.Items = app.BecExpSetting.readValue(1,"ImagingStageList");
            app.ScannedVariableDropDown.Items = sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"));
            app.ScannedVariable2DropDown.Items = ["None"; sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"))];
            app.ScannedVariable2DropDown.Value = "None";
            app.ModFreqVariableDropDown.Items = sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"));
            app.ChirpEndFreqVarDropDown.Items = ["None"; sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"))];
            app.ChirpEndFreqVarDropDown.Value = "None";
            
            app.updateCloudCenterList;
            app.PreviewerButtonGroup.SelectedObject = app.AtomicDensityButton;
            app.ShowFringeRemovalDataCheckBox.Value = false;

            %% Initialize test
            app.RepoPath = computerConfig.RepoPath;
            app.TestDataPathEditField.Value = fullfile(app.RepoPath,"test","testData","sampleData");
            app.RunTimeIntervalEditField.Value = 5;

            %% Initialize loading
            app.EndDateDatePicker.Value = datetime("today");
            app.TrialNameDropDown_2.Items = ["All";sortBecExpTrialName(app.BecExpSetting.readColumn("TrialName"))];

            %% Initialize log box
            t = datetime("now");
            t.Format = "HH:mm:ss";
            if app.IsMainComputer
                str = ["This computer [" + computerName + "] is the main control computer.";...
                    "Loading onsite experiment configuration settings."];
            else
                str = ["This computer [" + computerName + "] is not the main control computer. " + ...
                    "Onsite experiment will be disabled.";...
                    "Loading local test configuration settings."];
                app.LocalTestCheckBox.Value = true;
                app.RunTimeIntervalEditField.Enable = "on";
                app.SelectTestDataPathButton.Enable = "on";
                app.TestDataPathEditField.Enable = "on";
                app.LocalTestCheckBox.Enable = "off";
            end
            str = [str; "Date: " + string(datetime("today"));...
                "Time: " + string(t);...
                "Default parent path: " + app.BecExpSetting.readValue(1,"ParentPath");...
                "Default database: " + app.BecExpSetting.readValue(1,"DatabaseName");...
                "Default database table: " + app.BecExpSetting.readValue(1,"DatabaseTableName");...
                "Cicero log origin path: " + app.BecExpSetting.readValue(1,"CiceroLogOrigin")
                ];
            if strlength(string(app.RunLogTextArea.Value)) == 0
                app.RunLogTextArea.Value = str;
            else
                app.RunLogTextArea.Value = [app.RunLogTextArea.Value;" ";str];
            end
            app.BecExpSetting.updateColumn("IsLocalTest",app.LocalTestCheckBox.Value)

            %% Display setting properties
            app.displaySettingProperty;

            %% Other settings
            app.SaveCloudCenterCheckBox.Value = false;

        end

        % Value changed function: TrialNameDropDown
        function TrialNameDropDownValueChanged(app, event)
            value = app.TrialNameDropDown.Value;
            if ~ismember(value,app.TrialNameDropDown.Items)
                app.TrialNameDropDown.Value = "Test";
            end
            app.displaySettingProperty
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % Od, Imaging and Ad are always enabled
            allnodes = findall(app.Tree,"Type","uitreenode");
            default = allnodes(ismember(string({allnodes.Text}),["Od";"Imaging";"Ad"]));
            checkedNodes = app.Tree.CheckedNodes;
            checkedNodes = union(checkedNodes,default);
            app.Tree.CheckedNodes = checkedNodes;
            app.TrialSetting.AnalysisMethod = app.getAnalysisMethod;

            if isvalid(app.BecExp)
                aMethods = app.getAnalysisMethod;
                becExpAMethods = app.BecExp.AnalysisMethod;
                becExpAMethods(ismember(becExpAMethods,["Od";"Imaging";"Ad"])) = [];

                % add new methods
                newMethods = aMethods(~ismember(aMethods,becExpAMethods));
                app.BecExp.addAnalysis(newMethods);

                % close old methods plots
                oldMethods = becExpAMethods(~ismember(becExpAMethods,aMethods));
                app.BecExp.removeAnalysis(oldMethods);

                if ~isempty(newMethods)
                    newMethods = sortBecAnalysis(newMethods);
                    app.BecExp.refresh(newMethods(1))
                end

            end
        end

        % Button pushed function: OverwriteTrialTypeButton
        function OverwriteTrialTypeButtonPushed(app, event)
            trialName = string(app.TrialNameDropDown.Value);
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to overwrite the Trial type ["+ trialName +"]?");
            delete(f)
            answer = string(answer);
            if answer == "Yes"
                app.BecExpSetting.updateEntry(app.TrialSetting)
            end
        end

        % Button pushed function: SaveAsNewTrialTypeButton
        function SaveAsNewTrialTypeButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            trialName = inputdlg("Please enter the name of the new Trial type to be saved." + ...
                " Name can only contain alphabetic and numeric characters. " + ...
                "CamelCase recommended): ","Save as New Trial Type");
            delete(f);
            if ~isempty(trialName)
                trialName = string(trialName);
                if ~isAlphaNum(trialName)
                    error("The input name can only contain alphabetic and numeric characters.")
                elseif any(app.BecExpSetting.readColumn("TrialName") == trialName)
                    error("The input name has been used.")
                end

                % Update TrialConfig
                app.TrialSetting.TrialName = trialName;

                % Update BecExpSetting
                app.BecExpSetting.writeEntry(app.TrialSetting)

                % Get the new setting ID number
                oldId = app.TrialSetting.ID;
                app.TrialSetting.ID = app.BecExpSetting.readValue(trialName,"ID","TrialName");

                % Copy the hardware association
                t = app.HardwareAssociation.readEntry(oldId,"TrialID");
                t.TrialID = repmat(app.TrialSetting.ID,height(t),1);
                app.HardwareAssociation.writeEntry(t);

                % Update app display
                trialNameList = app.TrialNameDropDown.Items;
                trialNameList = [trialNameList,{char(trialName)}];
                app.TrialNameDropDown.Items = trialNameList;
                app.TrialNameDropDown.Value = trialName;
                app.TrialNameDropDown_2.Items = [{'All'},trialNameList];
            end
        end

        % Button pushed function: AddNewVariableButton
        function AddNewVariableButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = inputdlg(["Enter variable name:" ...
                , "Eneter variable unit (LaTex format supported, no $$):"]);
            delete(f);
            answer = string(answer);
            if ~isempty(answer)
                if ~isAlphaNumUnderscore(answer(1))
                    error("The input name can only contain alphabetic characters, numeric characters, and underscores.")
                elseif any(app.BecExpVariableUnit.readColumn("ScannedVariable") == answer(1))
                    error("The input name has been used.")
                end
                ScannedVariable = answer(1);
                ScannedVariableUnit = answer(2);
                t = table(ScannedVariable,ScannedVariableUnit);
                app.BecExpVariableUnit.writeEntry(t)
                app.ScannedVariableDropDown.Items = sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"));
                app.ScannedVariable2DropDown.Items = ["None"; sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"))];

                app.ModFreqVariableDropDown.Items = sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"));
                app.ChirpEndFreqVarDropDown.Items = ["None"; sortBecExpParameter(app.BecExpVariableUnit.readColumn("ScannedVariable"))];
            end
        end

        % Button pushed function: SelectTestDataPathButton
        function SelectTestDataPathButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            folder = uigetdir();
            delete(f);
            if ischar(folder)
                app.TestDataPathEditField.Value = folder;
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if app.IsStarted
                return
            end
            app.IsStarted = true;

            % Change buttons
            app.STARTButton.Enable = "off";
            app.STOPButton.Enable = "on";
            app.FASTSTOPButton.Enable = "on";
            app.PAUSEButton.Enable = "on";
            app.UpdateButton.Enable = "on";
            app.HoldButton.Enable = "on";
            app.RefreshButton.Enable = "on";
            app.OpenDataPathButton.Enable = "on";
            app.DeletedRunVariableButton.Enable = "on";
            app.PlotMixandAnimationButton.Enable = "on";
            app.toggleButtonStart

            if ~app.LocalTestCheckBox.Value
                app.displayLog(" ")
                app.displayLog("Starting onsite experiment...")

                % Construct BecExp
                trialName = app.TrialNameDropDown.Value;
                app.BecExp = BecExp(trialName,app.TrialSetting);
                app.setBecExpProperty;

                % Start exp
                app.BecExp.start
                app.TimeLabel.Text = "Time: " + string(app.BecExp.DateTime);
                app.SerialNumberLabel.Text = "Serial Number: " + string(app.BecExp.SerialNumber);
            else
                app.displayLog(" ")
                app.displayLog("Starting local test...")

                % Change buttons
                app.RunTimeIntervalEditField.Editable = "off";
                app.SelectTestDataPathButton.Enable = "off";
                app.TestDataPathEditField.Editable = "off";
                app.AutoAcquireImagesCheckBox.Value = false;

                % Set test folders
                testCiceroLogOrigin = fullfile(app.RepoPath,"test","testData","testLogFiles");
                emptyFolder(testCiceroLogOrigin);
                createFolder(testCiceroLogOrigin);
                samplePath = string(app.TestDataPathEditField.Value);

                % Construct test BecExp
                trialName = app.TrialNameDropDown.Value;
                trialConfig = app.TrialSetting;
                trialConfig.CiceroLogOrigin = testCiceroLogOrigin;
                trialConfig.IsAutoAcquire = false;
                createFolder(trialConfig.ParentPath);
                app.BecExp = BecExp(trialName,trialConfig);
                app.setBecExpProperty;

                % Start exp
                app.BecExp.start
                app.TimeLabel.Text = "Time: " + string(app.BecExp.DateTime);
                app.SerialNumberLabel.Text = "Serial Number: " + string(app.BecExp.SerialNumber);

                % Move image and clg files in the background to simulate
                % experiments
                pauseTime = app.RunTimeIntervalEditField.Value;
                dataPath = app.BecExp.DataPath;
                parfeval(backgroundPool,@runLocalTest,0,testCiceroLogOrigin,samplePath,dataPath,pauseTime);
            end
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if ~app.IsStarted
                return
            end
            app.IsStarted = false;

            % Enable Od and Ad mix/animation when stop
            if app.PreviewerButtonGroup.SelectedObject == app.OpticalDensityButton
                app.BecExp.Od.Chart(1).IsEnabled = true;
                app.BecExp.Od.Chart(2).IsEnabled = true;
            else
                app.BecExp.Ad.Chart(1).IsEnabled = true;
                app.BecExp.Ad.Chart(2).IsEnabled = true;
            end

            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp,'KapitzaPhaseDiagram')
                app.BecExp.displayLog("Plotting Kapitza theory curves...", "info");
                app.BecExp.KapitzaPhaseDiagram.drawTheoryCurves();
                if ~isempty(app.BecExp.KapitzaPhaseDiagram.Chart)
                    app.BecExp.KapitzaPhaseDiagram.Chart(1).save();
                end
            end

            % Stop and delete
            app.BecExp.stop;
            if isprop(app.BecExp,'CenterFit') % If Cloud center is saved, reset the list.
                if app.BecExp.CenterFit.IsSaveCenter
                    app.updateCloudCenterList;
                end
            end
            app.BecExp.delete;

            % Change buttons
            app.STARTButton.Enable = "on";
            app.STOPButton.Enable = "off";
            app.FASTSTOPButton.Enable = "off";
            app.PAUSEButton.Enable = "off";
            app.RESUMEButton.Enable = "off";
            app.UpdateButton.Enable = "off";
            app.HoldButton.Enable = "off";
            app.RefreshButton.Enable = "off";
            app.OpenDataPathButton.Enable = "off";
            app.DeletedRunVariableButton.Enable = "off";
            app.PlotMixandAnimationButton.Enable = "off";
            app.toggleButtonStop;
            app.resetButton;

            if ~app.LocalTestCheckBox.Value
                app.displayLog("Onsite experiment is stopped.")
            else
                % Change buttons
                app.RunTimeIntervalEditField.Editable = "on";
                app.SelectTestDataPathButton.Enable = "on";
                app.TestDataPathEditField.Editable = "on";

                app.displayLog("Local test is stopped.")
            end
        end

        % Button pushed function: FASTSTOPButton
        function FASTSTOPButtonPushed(app, event)
            if ~app.IsStarted
                return
            end
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to stop with out refreshing and saving" ...
                + "Od/Ad mix and animation?",'Fast Stop','Yes','No','No');
            delete(f);
            answer = string(answer);
            if answer == "Yes"
                app.IsStarted = false;

                % Stop and delete
                app.BecExp.fastStop;
                if isprop(app.BecExp,'CenterFit') % If Cloud center is saved, reset the list.
                    if app.BecExp.CenterFit.IsSaveCenter
                        app.updateCloudCenterList;
                    end
                end
                app.BecExp.delete;

                % Change buttons
                app.STARTButton.Enable = "on";
                app.STOPButton.Enable = "off";
                app.FASTSTOPButton.Enable = "off";
                app.PAUSEButton.Enable = "off";
                app.RESUMEButton.Enable = "off";
                app.UpdateButton.Enable = "off";
                app.HoldButton.Enable = "off";
                app.RefreshButton.Enable = "off";
                app.OpenDataPathButton.Enable = "off";
                app.DeletedRunVariableButton.Enable = "off";
                app.PlotMixandAnimationButton.Enable = "off";
                app.toggleButtonStop;
                app.resetButton;

                if ~app.LocalTestCheckBox.Value
                    app.displayLog("Onsite experiment is stopped.")
                else
                    % Change buttons
                    app.RunTimeIntervalEditField.Editable = "on";
                    app.SelectTestDataPathButton.Enable = "on";
                    app.TestDataPathEditField.Editable = "on";

                    app.displayLog("Local test is stopped.")
                end
            end
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.pause;
                app.RESUMEButton.Enable = "on";
                app.PAUSEButton.Enable = "off";
            end
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.resume;
                app.PAUSEButton.Enable = "on";
                app.RESUMEButton.Enable = "off";
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.update
            end
        end

        % Value changed function: HoldButton
        function HoldButtonValueChanged(app, event)
            value = app.HoldButton.Value;
            if isvalid(app.BecExp)
                app.BecExp.IsHoldRefresh = value;
            end
            if value
                app.RefreshButton.Enable = "off";
            else
                app.RefreshButton.Enable = "on";
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.refresh
            end
        end

        % Button pushed function: OpenDataPathButton
        function OpenDataPathButtonPushed(app, event)
            if isvalid(app.BecExp)
                winopen(app.BecExp.DataPath);
            end
        end

        % Button pushed function: DeletedRunVariableButton
        function DeletedRunVariableButtonPushed(app, event)
            if isvalid(app.BecExp)
                app.displayLog("Deleted run variable values: " + num2str(app.BecExp.DeletedRunVariableList))
            end
        end

        % Button pushed function: OpenCiceroOriginButton
        function OpenCiceroOriginButtonPushed(app, event)
            if isvalid(app.BecExp)
                winopen(app.BecExp.CiceroLogOrigin)
            elseif app.IsMainComputer
                winopen(app.BecExpConfig.readValue(false,"CiceroLogOrigin","IsLocalTest"))
            else
                winopen(app.BecExpConfig.readValue(true,"CiceroLogOrigin","IsLocalTest"))
            end
        end

        % Button pushed function: OpenmmConfigButton
        function OpenmmConfigButtonPushed(app, event)
            open("mmConfig.m")
        end

        % Button pushed function: RunSetParameterButton
        function RunSetParameterButtonPushed(app, event)
            setParameter;
            app.displayLog(" ")
            app.displayLog("Resetting parameters.")
            app.startupFcn;
        end

        % Value changed function: DescriptionTextArea
        function DescriptionTextAreaValueChanged(app, event)
            value = string(app.DescriptionTextArea.Value);
            app.TrialSetting.Description = value;
            if isvalid(app.BecExp)
                app.BecExp.Description = value;
            end
        end

        % Value changed function: ScannedVariableDropDown
        function ScannedVariableDropDownValueChanged(app, event)
            value = app.ScannedVariableDropDown.Value;
            if ~ismember(value,app.ScannedVariableDropDown.Items)
                if isvalid(app.BecExp)
                    app.ScannedVariableDropDown.Value = app.BecExp.ScannedVariable;
                else
                    id = app.TrialSetting(app.TrialSetting.TrialName == app.TrialNameDropDown.Value,:).ScannedVariableID;
                    app.ScannedVariableDropDown.Value = app.BecExpVariableUnit.readValue(id,"ScannedVariable");
                end
            end
            app.updateTrialSettingScannedVariableFromDropdowns
        end

        % Clicked callback: ScannedVariableDropDown
        function ScannedVariableDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item)
                return
            end
            app.updateBecExpScannedVariableFromDropdowns
        end

        % Value changed function: AcquisitionDropDown
        function AcquisitionDropDownValueChanged(app, event)
            value = app.AcquisitionDropDown.Value;
            if ~ismember(value,app.AcquisitionDropDown.Items)
                app.AcquisitionDropDown.Value = ...
                    app.BecExpSetting.readValue(app.TrialSetting.ID,"AcquisitionName");
            end
            app.TrialSetting.AcquisitionName = string(app.AcquisitionDropDown.Value);
        end

        % Value changed function: AutoAcquireImagesCheckBox
        function AutoAcquireImagesCheckBoxValueChanged(app, event)
            value = app.AutoAcquireImagesCheckBox.Value;
            app.TrialSetting.IsAutoAcquire = value;
        end

        % Value changed function: ROIDropDown
        function ROIDropDownValueChanged(app, event)
            value = app.ROIDropDown.Value;
            if ~ismember(value,app.ROIDropDown.Items)
                if isvalid(app.BecExp)
                    app.ROIDropDown.Value = app.BecExp.Roi.Name;
                else
                    app.ROIDropDown.Value = ...
                        app.BecExpSetting.readValue(app.TrialSetting.ID,"RoiName");
                end
            end
            app.TrialSetting.RoiName = string(app.ROIDropDown.Value);
        end

        % Selection changed function: PreviewerButtonGroup
        function PreviewerButtonGroupSelectionChanged(app, event)
            selectedButton = app.PreviewerButtonGroup.SelectedObject;
            if isvalid(app.BecExp)
                if selectedButton == app.OpticalDensityButton
                    app.BecExp.Ad.Gui(1).App.IsOd = true;
                    app.BecExp.IsOdPreview = true;
                else
                    app.BecExp.Ad.Gui(1).App.IsOd = false;
                    app.BecExp.IsOdPreview = false;
                end
                app.BecExp.Ad.Gui(1).App.updateLabel
                app.BecExp.Ad.Gui(1).App.update
                
            end
            if selectedButton == app.OpticalDensityButton
                app.TrialSetting.IsOdPreview = true;
            else
                app.TrialSetting.IsOdPreview = false;
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            name = selectedNodes.Text + "Tab";
            app.TabGroup2.SelectedTab = app.(name) ;
        end

        % Value changed function: CenterReferenceDropDown
        function CenterReferenceDropDownValueChanged(app, event)
            value = string(app.CenterReferenceDropDown.Value);
            if value == "None"
                value = 0;
            else
                value = app.BecExpSetting.readValue(value,"ID","TrialName");
            end
            app.TrialSetting.CenterReferenceID = value;
            if isvalid(app.BecExp)
                app.BecExp.CenterReferenceID = value;
            end
        end

        % Value changed function: ShowFringeRemovalDataCheckBox
        function ShowFringeRemovalDataCheckBoxValueChanged(app, event)
            value = app.ShowFringeRemovalDataCheckBox.Value;
            if isvalid(app.BecExp)
                app.BecExp.Od.Gui(1).IsEnabled = value;
                if value
                    app.BecExp.Od.doFringeRemoval
                elseif isvalid(app.BecExp.Od.Gui(1).App)
                    app.BecExp.Od.Gui(1).App.delete
                end
            end
        end

        % Value changed function: FringeRemovalMethodDropDown
        function FringeRemovalMethodDropDownValueChanged(app, event)
            value = string(app.FringeRemovalMethodDropDown.Value);
            if isvalid(app.BecExp)
                app.BecExp.Od.FringeRemovalMethod = value;
                app.BecExp.refresh;
            end
            app.TrialSetting.FringeRemovalMethod = value;
        end

        % Value changed function: ODMaxEditField
        function ODMaxEditFieldValueChanged(app, event)
            value = app.ODMaxEditField.Value;
            if value > app.ODMinEditField.Value
                if isvalid(app.BecExp)
                    app.BecExp.Od.CLim(2) = value;
                end
            else
                if isvalid(app.BecExp)
                    app.ODMaxEditField.Value = app.BecExp.Od.CLim(2);
                else
                    app.ODMaxEditField.Value = ...
                        app.BecExpSetting.readValue(app.TrialSetting.ID,"OdCLim");
                end
                app.displayLog("OD CLim max value should be larger than min value.","warning")
            end
            app.TrialSetting.OdCLim = app.ODMaxEditField.Value;
        end

        % Value changed function: ODMinEditField
        function ODMinEditFieldValueChanged(app, event)
            value = app.ODMinEditField.Value;
            if value < app.ODMaxEditField.Value
                if isvalid(app.BecExp)
                    app.BecExp.Od.CLim(1) = value;
                end
            else
                if isvalid(app.BecExp)
                    app.ODMinEditField.Value = app.BecExp.Od.CLim(1);
                else
                    app.ODMinEditField.Value = 0;
                end
                app.displayLog("OD CLim max value should be larger than min value.","warning")
            end
        end

        % Value changed function: ImagingStageDropDown
        function ImagingStageDropDownValueChanged(app, event)
            value = string(app.ImagingStageDropDown.Value);
            app.TrialSetting.ImagingStage = value;
            if isvalid(app.BecExp)
                app.BecExp.Imaging.ImagingStage = value;
                app.BecExp.refresh("Imaging");
            end
        end

        % Value changed function: ImagingMethodDropDown
        function ImagingMethodDropDownValueChanged(app, event)
            value = string(app.ImagingMethodDropDown.Value);
            app.TrialSetting.ImagingMethod = value;
            if isvalid(app.BecExp)
                app.BecExp.Imaging.ImagingMethod = value;
                app.BecExp.refresh("Imaging");
            end
        end

        % Value changed function: ADMethodDropDown
        function ADMethodDropDownValueChanged(app, event)
            value = string(app.ADMethodDropDown.Value);
            app.TrialSetting.AdMethod = value;
            if isvalid(app.BecExp)
                app.BecExp.Ad.AdMethod = value;
                app.BecExp.refresh("Ad");
            end
        end

        % Value changed function: ADMaxEditField
        function ADMaxEditFieldValueChanged(app, event)
            value = app.ADMaxEditField.Value;
            if value > app.ADMinEditField.Value
                if isvalid(app.BecExp)
                    app.BecExp.Ad.CLim(2) = value;
                end
            else
                if isvalid(app.BecExp)
                    app.ADMaxEditField.Value = app.BecExp.Ad.CLim(2);
                else
                    app.ADMaxEditField.Value = ...
                        app.BecExpSetting.readValue(app.TrialSetting.ID,"AdCLim");
                end
                app.displayLog("AD CLim max value should be larger than min value.","warning")
            end
            app.TrialSetting.AdCLim = app.ADMaxEditField.Value;
        end

        % Value changed function: ADMinEditField
        function ADMinEditFieldValueChanged(app, event)
            value = app.ADMinEditField.Value;
            if value < app.ADMaxEditField.Value
                if isvalid(app.BecExp)
                    app.BecExp.Ad.CLim(1) = value;
                end
            else
                if isvalid(app.BecExp)
                    app.ADMinEditField.Value = app.BecExp.Ad.CLim(1);
                else
                    app.ADMinEditField.Value = 0;
                end
                app.displayLog("AD CLim max value should be larger than min value.","warning")
            end
        end

        % Value changed function: DensityFitMethodDropDown
        function DensityFitMethodDropDownValueChanged(app, event)
            value = string(app.DensityFitMethodDropDown.Value);
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'DensityFit')
                    app.BecExp.DensityFit.FitMethod = value;
                    app.BecExp.refresh("DensityFit");
                end
            end
            app.TrialSetting.DensityFitMethod = value;
        end

        % Value changed function: AtomNumberMaxEditField
        function AtomNumberMaxEditFieldValueChanged(app, event)
            value = app.AtomNumberMaxEditField.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.YLim = [0,value];
                end
            end
            app.TrialSetting.AtomNumberYLim = value;
        end

        % Value changed function: ShowRawCheckBox
        function ShowRawCheckBoxValueChanged(app, event)
            value = app.ShowRawCheckBox.Value;
            if isvalid(app.BecExp)
                if isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.IsShowRaw = value;
                end
            end
        end

        % Value changed function: ShowThermalCheckBox
        function ShowThermalCheckBoxValueChanged(app, event)
            value = app.ShowThermalCheckBox.Value;
            if isvalid(app.BecExp)
                if isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.IsShowThermal = value;
                end
            end
        end

        % Value changed function: ShowNormalizedAtomNumberCheckBox
        function ShowNormalizedAtomNumberCheckBoxValueChanged(app, event)
            value = app.ShowNormalizedAtomNumberCheckBox.Value;
            if isvalid(app.BecExp)
                if isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.IsShowNormalized = value;
                    app.BecExp.refresh("AtomNumber")
                end
            end
        end

        % Value changed function: CenterFitMethodDropDown
        function CenterFitMethodDropDownValueChanged(app, event)
            value = string(app.CenterFitMethodDropDown.Value);
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'CenterFit')
                    app.BecExp.CenterFit.FitMethod = value;
                    app.BecExp.refresh("CenterFit");
                end
            end
            app.TrialSetting.CenterFitMethod = value;
        end

        % Value changed function: SaveCloudCenterCheckBox
        function SaveCloudCenterCheckBoxValueChanged(app, event)
            value = app.SaveCloudCenterCheckBox.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'CenterFit')
                    app.BecExp.CenterFit.IsSaveCenter = value;
                end
            end
        end

        % Value changed function: SerialCheckBox
        function SerialCheckBoxValueChanged(app, event)
            value = app.SerialCheckBox.Value;
            if value
                app.SerialEditField.Enable = "on";
                app.SerialEditField2.Enable = "on";
            else
                app.SerialEditField.Enable = "off";
                app.SerialEditField2.Enable = "off";
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)

            app.LoadButton.Enable = "off";
            app.OpenDataPathButton_3.Enable = "off";
            if app.ServerButtonGroup.SelectedObject == app.RemoteButton
                databaseName = app.BecExpConfig.readValue(false,"DatabaseName","IsLocalTest");
                databaseTableName = app.BecExpConfig.readValue(false,"DatabaseTableName","IsLocalTest");
            else
                databaseName = app.BecExpConfig.readValue(true,"DatabaseName","IsLocalTest");
                databaseTableName = app.BecExpConfig.readValue(true,"DatabaseTableName","IsLocalTest");
            end
            startTime = "'" + string(app.StartDateDatePicker.Value) + "'";
            endTime = "'" + string(app.EndDateDatePicker.Value) + " 23:59:59'";
            conn = createReader(databaseName);
            query = "SELECT ""SerialNumber"", ""DateTime"", ""Name""," + ...
                " ""ScannedVariable"", ""Description"", ""DataPath"" FROM "...
                + databaseTableName +" WHERE ""DateTime"" >= " + startTime + ...
                " AND ""DateTime"" <= " + endTime;
            if app.TrialNameDropDown_2.Value ~= "All"
                query = query + " AND ""Name"" = '" + ...
                    app.TrialNameDropDown_2.Value + "'";
            end
            if app.SerialCheckBox.Value
                query = query + " AND ""SerialNumber"" >= " +...
                    string(app.SerialEditField.Value) + ...
                    " AND ""SerialNumber"" <= " + string(app.SerialEditField2.Value);
            end
            if ~isempty(app.KeyWordsEditField.Value)
                keyWords = strtrim(split(app.KeyWordsEditField.Value,","));
                keyWords = "'%" + keyWords + "%'";
                keyWords = strjoin(keyWords,", ");
                keyWords = "(array[" + keyWords + "])";
                query = query + " AND ""Description"" ILIKE ALL" + keyWords;
            end
            query = query + " ORDER BY ""SerialNumber"" DESC LIMIT 2000";
            data = pgFetch(conn,query);
            app.DataTable.Data = data;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.UnloadButton.Enable = "on";
            app.STARTButton.Enable = "off";
            app.DataTable.Enable = "inactive";
            app.SearchButton.Enable = "off";
            app.HoldButton_3.Enable = "on";
            app.RefreshButton_3.Enable = "on";
            app.OverwriteTrialTypeButton.Enable = "off";
            app.SaveAsNewTrialTypeButton.Enable = "off";
            app.toggleButtonStart;
            if app.ServerButtonGroup.SelectedObject == app.RemoteButton
                databaseName = app.BecExpConfig.readValue(false,"DatabaseName","IsLocalTest");
                databaseTableName = app.BecExpConfig.readValue(false,"DatabaseTableName","IsLocalTest");
            else
                databaseName = app.BecExpConfig.readValue(true,"DatabaseName","IsLocalTest");
                databaseTableName = app.BecExpConfig.readValue(true,"DatabaseTableName","IsLocalTest");
            end
            conn = createReader(databaseName);

            app.BecExp = loadTrial(conn,databaseTableName,app.DataTable.Data.SerialNumber(app.DataTable.Selection));
            % This is for backward compatibility
            if isprop(app.BecExp,"Od")
                if numel(app.BecExp.Od.Gui) == 2
                    app.BecExp.Od.Gui(1) = [];
                    if isprop(app.BecExp,"Ad")
                        app.BecExp.IsOdPreview = ~app.BecExp.Ad.Gui(1).IsEnabled;
                        app.BecExp.Ad.Gui(1).IsEnabled = true;
                        app.BecExp.Ad.Gui(1).Name = "AtomPreviewer";
                    end
                end
            end

            app.displayBecExpProperty;
            app.BecExp.refresh;
            app.BecExp.show;
        end

        % Button pushed function: UnloadButton
        function UnloadButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            answer = questdlg("Do you want to save changes for Trial #"+ string(app.BecExp.SerialNumber) +"?");
            delete(f)
            if string(answer) == "Yes"
                for ii = 1:numel(app.BecExp.AnalysisMethod)
                    app.BecExp.(app.BecExp.AnalysisMethod(ii)).refresh;
                    app.BecExp.(app.BecExp.AnalysisMethod(ii)).save;
                    app.BecExp.(app.BecExp.AnalysisMethod(ii)).close;
                end
                app.BecExp.update;
            elseif string(answer) == "No"
                for ii = 1:numel(app.BecExp.AnalysisMethod)
                    app.BecExp.(app.BecExp.AnalysisMethod(ii)).close;
                end
            else
                return
            end

            % Change buttons
            app.UnloadButton.Enable = "off";
            app.DataTable.Enable = "on";
            app.STARTButton.Enable = "on";
            app.SearchButton.Enable = "on";
            app.OpenDataPathButton_3.Enable = "off";
            app.HoldButton_3.Enable = "off";
            app.RefreshButton_3.Enable = "off";
            app.OverwriteTrialTypeButton.Enable = "on";
            app.SaveAsNewTrialTypeButton.Enable = "on";
            app.toggleButtonStop;
            app.resetButton;

            % Delete
            app.BecExp.delete;

            % Change the config back to the default
            app.TrialNameDropDown.Value = "Test";
            app.displaySettingProperty;
        end

        % Button pushed function: OpenDataPathButton_3
        function OpenDataPathButton_3Pushed(app, event)
            winopen(app.DataTable.Data.DataPath(app.DataTable.Selection));
        end

        % Value changed function: HoldButton_3
        function HoldButton_3ValueChanged(app, event)
            value = app.HoldButton_3.Value;
            if isvalid(app.BecExp)
                app.BecExp.IsHoldRefresh = value;
            end
            if value
                app.RefreshButton_3.Enable = "off";
            else
                app.RefreshButton_3.Enable = "on";
            end
        end

        % Button pushed function: RefreshButton_3
        function RefreshButton_3Pushed(app, event)
            if isvalid(app.BecExp)
                app.BecExp.refresh
            end
        end

        % Clicked callback: DataTable
        function DataTableClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            if isempty(displayRow)
                app.LoadButton.Enable = "off";
                app.OpenDataPathButton_3.Enable = "off";
            else
                app.OpenDataPathButton_3.Enable = "on";
                if app.STOPButton.Enable == "off" && app.UnloadButton.Enable == "off"
                    app.LoadButton.Enable = "on";
                end
            end
        end

        % Close request function: BECExperimentControlPanelUIFigure
        function BECExperimentControlPanelUIFigureCloseRequest(app, event)
            if app.STOPButton.Enable == "on"
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                answer = questdlg("A BecExp is still running. Do you want to close the program? " + ...
                    "Will stop the BecExp before closing the program.","",'Yes','No','Force Close','No');
                delete(f)
                if string(answer) == "Yes"
                    app.BecExp.stop;
                    app.BecExp.delete;
                    if app.LocalTestCheckBox.Value
                        app.displayLog("Local test is stopped.")
                    else
                        app.displayLog("Onsite experiment is stopped.")
                    end
                    delete(app)
                elseif string(answer) == "Force Close"
                    try
                        app.BecExp.Acquisition.stopCamera;
                    catch
                    end
                    delete(app)
                end
            elseif app.UnloadButton.Enable == "on"
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                answer = questdlg("A BecExp is still loaded. " + ...
                    "Do you want to save the changes before closing the program? ",...
                    "",'Yes','No','Force Close','No');
                delete(f)
                if string(answer) == "Yes"
                    for ii = 1:numel(app.BecExp.AnalysisMethod)
                        app.BecExp.(app.BecExp.AnalysisMethod(ii)).refresh;
                        app.BecExp.(app.BecExp.AnalysisMethod(ii)).save;
                        app.BecExp.(app.BecExp.AnalysisMethod(ii)).close;
                    end
                    delete(app)
                elseif string(answer) == "No"
                    for ii = 1:numel(app.BecExp.AnalysisMethod)
                        app.BecExp.(app.BecExp.AnalysisMethod(ii)).close;
                    end
                    delete(app)
                elseif string(answer) == "Force Close"
                    delete(app)
                end
            else
                delete(app)
            end

        end

        % Value changed function: TrialNameDropDown_2
        function TrialNameDropDown_2ValueChanged(app, event)
            value = app.TrialNameDropDown_2.Value;
            if ~ismember(value,app.TrialNameDropDown_2.Items)
                app.TrialNameDropDown_2.Value = "All";
            end
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    selectedAnalysisName = string(selectedAnalysis.Text);
                    app.BecExp.(selectedAnalysisName).initialize
                end
            end
        end

        % Button pushed function: RefreshButton_4
        function RefreshButton_4Pushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    selectedAnalysisName = string(selectedAnalysis.Text);
                    app.BecExp.(selectedAnalysisName).refresh
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    selectedAnalysisName = string(selectedAnalysis.Text);
                    app.BecExp.(selectedAnalysisName).save
                end
            end
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            if isvalid(app.BecExp)
                selectedAnalysis = app.Tree.SelectedNodes;
                if ~isempty(selectedAnalysis)
                    selectedAnalysisName = string(selectedAnalysis.Text);
                    app.BecExp.(selectedAnalysisName).show
                end
            end
        end

        % Button pushed function: AssignBecExptoWorkSpaceButton
        function AssignBecExptoWorkSpaceButtonPushed(app, event)
            if isvalid(app.BecExp)
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                varName = inputdlg("Please enter the name of the variable you want to assign BecExp as.",...
                    "Assign BecExp to the base work-space",1,"becExp");
                delete(f);
                if ~isempty(varName)
                    assignin('base',varName{1},app.BecExp);
                end
            end
        end

        % Button pushed function: ResetImageAcquisitionButton
        function ResetImageAcquisitionButtonPushed(app, event)
            if app.IsMainComputer
                imaqreset;
            end
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isa(app.BecExp.Acquisition,'Andor')
                    app.BecExp.Acquisition.stopCamera
                    pause(0.5)
                    poolobj = gcp('nocreate');
                    delete(poolobj);
                    AndorInitialize('');
                    AndorShutDown;
                    app.BecExp.Acquisition.connectCamera;
                    switch app.BecExp.Imaging.ImagingMethod
                        case  "Absorption"
                            app.BecExp.Acquisition.setCameraParameterAbsorption;
                            app.BecExp.Acquisition.setCallback(@(src,evt) saveBecImageAbsorption(src,evt,app.BecExp));
                        case "Dispersive"

                        case "Fluorescence"

                    end
                    app.BecExp.Acquisition.startCamera;
                end
            end
        end

        % Button pushed function: CreateParallelPoolButton
        function CreateParallelPoolButtonPushed(app, event)
            parpool;
        end

        % Value changed function: AveragingMethodDropDown
        function AveragingMethodDropDownValueChanged(app, event)
            value = app.AveragingMethodDropDown.Value;
            if ~ismember(value,app.AveragingMethodDropDown.Items)
                if isvalid(app.BecExp)
                    app.AveragingMethodDropDown.Value = app.BecExp.AveragingMethod;
                else
                    app.AveragingMethod.Value = ...
                        app.BecExpSetting.readValue(app.TrialSetting.ID,"AveragingMethod");
                end
            end
            app.TrialSetting.AveragingMethod = string(app.AveragingMethodDropDown.Value);
            
        end

        % Clicked callback: AveragingMethodDropDown
        function AveragingMethodDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item)
                return
            end
            if isvalid(app.BecExp)
                app.BecExp.AveragingMethod = app.AveragingMethodDropDown.Value;
                app.BecExp.refreshFigure;
            end
        end

        % Value changed function: ScopeValueNameTextArea
        function ScopeValueNameTextAreaValueChanged(app, event)
            value = string(app.ScopeValueNameTextArea.Value);
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'ScopeValue')
                    app.BecExp.ScopeValue.FullValueName = strsplit(value,";");
                    app.BecExp.refresh("ScopeValue");
                end
            end
            app.TrialSetting.ScopeValueName = value;
        end

        % Button pushed function: EditHardwareAssociationButton
        function EditHardwareAssociationButtonPushed(app, event)
            HardwareAssociationEditor(app.TrialSetting.ID);
        end

        % Value changed function: AtomNumberFitMethodDropDown
        function AtomNumberFitMethodDropDownValueChanged(app, event)
            value = app.AtomNumberFitMethodDropDown.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'AtomNumber')
                    app.BecExp.AtomNumber.FitMethod = value;
                    app.BecExp.refresh("AtomNumber");
                end
            end
            app.TrialSetting.AtomNumberFitMethod = value;
        end

        % Button pushed function: PlotMixandAnimationButton
        function PlotMixandAnimationButtonPushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    selectedButton = app.PreviewerButtonGroup.SelectedObject;
                    if selectedButton == app.OpticalDensityButton
                        app.BecExp.Od.Chart(1).IsEnabled = true;
                        app.BecExp.Od.Chart(2).IsEnabled = true;
                        app.BecExp.refresh("Od")
                        app.BecExp.Od.Chart(2).showGif
                        app.BecExp.Od.Chart(1).IsEnabled = false;
                        app.BecExp.Od.Chart(2).IsEnabled = false;
                    else
                        app.BecExp.Ad.Chart(1).IsEnabled = true;
                        app.BecExp.Ad.Chart(2).IsEnabled = true;
                        app.BecExp.refresh("Ad")
                        app.BecExp.Ad.Chart(2).showGif
                        app.BecExp.Ad.Chart(1).IsEnabled = false;
                        app.BecExp.Ad.Chart(2).IsEnabled = false;
                    end
                end
            end
        end

        % Button pushed function: ModifyXThermalFitButton
        function ModifyXThermalFitButtonPushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'CenterFit')
                    FitOverride(app.BecExp.CenterFit.FitDataThermal(1),app)
                end
            end
        end

        % Button pushed function: ModifyYThermalFitButton
        function ModifyYThermalFitButtonPushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp) && isprop(app.BecExp,'CenterFit')
                    FitOverride(app.BecExp.CenterFit.FitDataThermal(2),app)
                end
            end
        end

        % Button pushed function: PlotHistogramofScannedVariableButton
        function PlotHistogramofScannedVariableButtonPushed(app, event)
            %Steps: Check if becExp object exists, grab scanned
            %parameterlist, counts instances of each unique item in
            %parameterlist,
            %and then plot the histogram as a figure.
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    data=app.BecExp.ScannedVariableList;
                    uniquedata=unique(data,'sorted');
                    uniquedatacounts=zeros(size(uniquedata));
                    for ii=1:length(uniquedata)
                        uniquedatacounts(ii)=sum(data==uniquedata(ii));
                    end
                    fcounts=figure();
                    axcounts=axes(fcounts);
                    bar(axcounts, uniquedata, uniquedatacounts);
                    xlabel(axcounts,app.BecExp.ScannedVariable);
                    ylabel(axcounts,'Counts of Shots');
                    title(axcounts, 'Histogram of Scanned Variable');
                    
                end
            end

        end

        % Value changed function: ScannedVariable2DropDown
        function ScannedVariable2DropDownValueChanged(app, event)
            value = app.ScannedVariable2DropDown.Value;
            if ~ismember(value, app.ScannedVariable2DropDown.Items)
                if isvalid(app.BecExp)
                    if app.BecExp.Is2dScan
                        app.ScannedVariable2DropDown.Value = app.BecExp.ScannedVariable2;
                    else
                        app.ScannedVariable2DropDown.Value = "None";
                    end
                else
                    id = app.BecExpSetting(app.BecExpSetting.TrialName == app.TrialNameDropDown.Value,:).ScannedVariableID2;
                    if id ~= 0
                        app.ScannedVariable2DropDown.Value = app.BecExpVariableUnit.readValue(id,"ScannedVariable"); 
                    else
                        app.ScannedVariable2DropDown.Value = "None";
                    end
                end
            end
            % Update the ScannedParameter based on both dropdowns
            app.updateTrialSettingScannedVariableFromDropdowns;   
        end

        % Clicked callback: ScannedVariable2DropDown
        function ScannedVariable2DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(item)
                return
            end
            % Update the ScannedParameter based on both dropdowns
            app.updateBecExpScannedVariableFromDropdowns;
        end

        % Value changed function: LocalTestCheckBox
        function LocalTestCheckBoxValueChanged(app, event)
            value = app.LocalTestCheckBox.Value;
            app.BecExpSetting.updateColumn("IsLocalTest",value)
            if value
                app.RunTimeIntervalEditField.Enable = "on";
                app.SelectTestDataPathButton.Enable = "on";
                app.TestDataPathEditField.Enable = "on";
            else
                app.RunTimeIntervalEditField.Enable = "off";
                app.SelectTestDataPathButton.Enable = "off";
                app.TestDataPathEditField.Enable = "off";
            end
            app.displaySettingProperty %To reset TrialSetting
        end

        % Button pushed function: RenameTrialTypeButton
        function RenameTrialTypeButtonPushed(app, event)
            newName = enterName("new Trial name");
            if ~isempty(newName)
                allName = app.BecExpSetting.readColumn("TrialName");
                if ~ismember(newName,allName)
                    app.TrialSetting.TrialName = newName;
                    app.BecExpSetting.updateValue(app.TrialSetting.ID,"TrialName",newName)
                    app.TrialNameDropDown.Items = sortBecExpTrialName(app.BecExpSetting.readColumn("TrialName"));
                    app.TrialNameDropDown.Value = newName;
                    app.TrialNameDropDown_2.Items = ["All";sortBecExpTrialName(app.BecExpSetting.readColumn("TrialName"))];
                    app.TrialNameDropDown_2.Value = "All";
                    app.updateCloudCenterList;
                else
                    msgbox("This name has been used")
                end
            end
        end

        % Value changed function: AverageDensityCheckBox
        function AverageDensityCheckBoxValueChanged(app, event)
            value = app.AverageDensityCheckBox.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    app.BecExp.IsDensityAverage = value;
                end
            end
            app.TrialSetting.IsDensityAverage = value;
        end

        % Value changed function: ShowCenterReferenceCheckBox
        function ShowCenterReferenceCheckBoxValueChanged(app, event)
            value = app.ShowCenterReferenceCheckBox.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    app.BecExp.IsShowCenterReference = value;
                    if isprop(app.BecExp,'CenterFit')
                        app.BecExp.CenterFit.refresh;
                    end
                    if value
                        app.BecExp.Ad.Gui(1).App.moveMarkerToReference
                    end
                end
            end
            app.TrialSetting.IsShowCenterReference = value;
        end

        % Value changed function: WavelengthEditField
        function WavelengthEditFieldValueChanged(app, event)
            value = app.WavelengthEditField.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.Wavelength = value * 1e-9;
                        app.BecExp.KapitzaDirac.generateRoi
                    end
                end
            end
            app.TrialSetting.KdParameter.Wavelength = value * 1e-9;
        end

        % Value changed function: LatticeAxisDropDown
        function LatticeAxisDropDownValueChanged(app, event)
            value = app.LatticeAxisDropDown.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.LatticeAxis = string(value);
                        app.BecExp.KapitzaDirac.generateRoi
                    end
                end
            end
            app.TrialSetting.KdParameter.LatticeAxis = string(value); 
        end

        % Value changed function: MaxOrderEditField
        function MaxOrderEditFieldValueChanged(app, event)
            value = app.MaxOrderEditField.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.OrderMax = value;
                        app.BecExp.KapitzaDirac.generateRoi
                    end
                end
            end
            app.TrialSetting.KdParameter.OrderMax = value;
        end

        % Value changed function: ROISizeEditField
        function ROISizeEditFieldValueChanged(app, event)
            value = app.ROISizeEditField.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.RoiSize = value;
                        app.BecExp.KapitzaDirac.generateRoi
                    end
                end
            end
            app.TrialSetting.KdParameter.RoiSize = value;
        end

        % Value changed function: ROIMethodDropDown
        function ROIMethodDropDownValueChanged(app, event)
            value = app.ROIMethodDropDown.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.RoiMethod = string(value);
                        app.BecExp.KapitzaDirac.generateRoi
                    end
                end
            end
            app.TrialSetting.KdParameter.RoiMethod = string(value);
        end

        % Value changed function: KdFitMethodDropDown
        function KdFitMethodDropDownValueChanged(app, event)
            value = app.KdFitMethodDropDown.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.KdFitMethod = string(value);
                    end
                end
            end
            app.TrialSetting.KdParameter.KdFitMethod = string(value); 
        end

        % Value changed function: ParameterMethodDropDown
        function ParameterMethodDropDownValueChanged(app, event)
            value = app.ParameterMethodDropDown.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.ParameterMethod = string(value);
                        app.BecExp.refresh("KapitzaDirac")
                    end
                end
            end
            app.TrialSetting.KdParameter.ParameterMethod = string(value); 
        end

        % Value changed function: ScanTypeDropDown
        function ScanTypeDropDownValueChanged(app, event)
            value = app.ScanTypeDropDown.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.ScanType = string(value);
                        app.BecExp.refresh("KapitzaDirac")
                    end
                end
            end
            app.TrialSetting.KdParameter.ScanType = value; 
        end

        % Value changed function: ScopeChannelEditField
        function ScopeChannelEditFieldValueChanged(app, event)
            value = app.ScopeChannelEditField.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.ScopeChannel = string(value);
                        if app.BecExp.KapitzaDirac.ParameterMethod == "FitScope"
                            app.BecExp.refresh("KapitzaDirac")
                        end
                    end
                end
            end
            app.TrialSetting.KdParameter.ScopeChannel = string(value); 
        end

        % Button pushed function: RegenerateROIButton
        function RegenerateROIButtonPushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.generateRoi
                    end
                end
            end
        end

        % Button pushed function: DoKdFitButton
        function DoKdFitButtonPushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.fit
                    end
                end
            end
        end

        % Value changed function: WaistEditField
        function WaistEditFieldValueChanged(app, event)
            value = app.WaistEditField.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.Waist = value * 1e-6;
                    end
                end
            end
            app.TrialSetting.KdParameter.Waist = value * 1e-6; 
        end

        % Value changed function: CloudSizeEditField
        function CloudSizeEditFieldValueChanged(app, event)
            value = app.CloudSizeEditField.Value;
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaDirac')
                        app.BecExp.KapitzaDirac.CloudSize = value * 1e-6;
                    end
                end
            end
            app.TrialSetting.KdParameter.CloudSize = value * 1e-6; 
        end

        % Value changed function: KapitzaMetricDropDown
        function KapitzaMetricDropDownValueChanged(app, event)
            value = app.KapitzaMetricDropDown.Value;
            % Update live experiment if running/loaded
            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp, 'KapitzaPhaseDiagram')
                app.BecExp.KapitzaPhaseDiagram.MetricName = value;
                app.BecExp.refresh("KapitzaPhaseDiagram"); % Instantly update the plot
            end
            
            % Update background trial settings for future runs
            app.TrialSetting.KapitzaParameter.MetricName = value;
        end

        % Callback function
        function KapitzaMetricDropDownOpening(app, event)
            
        end

        % Value changed function: KapitzaNoiseFloorEditField
        function KapitzaNoiseFloorEditFieldValueChanged(app, event)
            value = app.KapitzaNoiseFloorEditField.Value;
            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp, 'KapitzaPhaseDiagram')
                app.BecExp.KapitzaPhaseDiagram.NoiseFloor = value;
                app.BecExp.refresh("KapitzaPhaseDiagram");
            end
            
            app.TrialSetting.KapitzaParameter.NoiseFloor = value;
        end

        % Value changed function: KapitzaInvertCheckBox
        function KapitzaInvertCheckBoxValueChanged(app, event)
            value = app.KapitzaInvertCheckBox.Value;
            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp, 'KapitzaPhaseDiagram')
                app.BecExp.KapitzaPhaseDiagram.InvertOmegaAxis = value;
                app.BecExp.refresh("KapitzaPhaseDiagram");
            end
            
            app.TrialSetting.KapitzaParameter.InvertOmegaAxis = value;
        end

        % Value changed function: DimensionlessVariables
        function DimensionlessVariablesValueChanged(app, event)
            value = app.DimensionlessVariables.Value;
            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp, 'KapitzaPhaseDiagram')
                app.BecExp.KapitzaPhaseDiagram.UseDimensionlessAxes = value;
                app.BecExp.refresh("KapitzaPhaseDiagram")
            end

            app.TrialSetting.KapitzaParameter.UseDimensionlessAxes = value;
        end

        % Button pushed function: DrawTheoryButton
        function DrawTheoryButtonPushed(app, event)
            if ~isempty(app.BecExp)
                if isvalid(app.BecExp)
                    if isprop(app.BecExp,'KapitzaPhaseDiagram')
                        app.BecExp.KapitzaPhaseDiagram.drawTheoryCurves()
                    end
                end
            end
        end

        % Value changed function: TargetphaseEditField
        function TargetphaseEditFieldValueChanged(app, event)
            value = app.TargetphaseEditField.Value;
            % Update live experiment if running/loaded
            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp, 'ModulationMonitor')
                app.BecExp.ModulationMonitor.PhaseTarget = value;
                app.BecExp.refresh("ModulationMonitor"); % Instantly update the plot
            end
    
            % Update background trial settings for future runs
            app.TrialSetting.ModulationParameter.PhaseTarget = value;
        
        end

        % Value changed function: CalcRelativePhaseCheckBox
        function CalcRelativePhaseCheckBoxValueChanged(app, event)
            value = app.CalcRelativePhaseCheckBox.Value;
            % Update live experiment if running/loaded
            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp, 'ModulationMonitor')
            app.BecExp.ModulationMonitor.CalculatePhase = value;
            app.BecExp.refresh("ModulationMonitor");
            end
        % Update background trial settings for future runs
        app.TrialSetting.ModulationParameter.CalculatePhase = value;
    
        end

        % Value changed function: ScopenameEditField
        function ScopenameEditFieldValueChanged(app, event)
            value = app.ConvertVoltsCheckBox.Value;
            if ~isempty(app.BecExp) && isvalid(app.BecExp) ...
                    && isprop(app.BecExp, 'ModulationMonitor') ...
                    && ~isempty(app.BecExp.ModulationMonitor)
                mm = app.BecExp.ModulationMonitor;
                mm.IsConvertVolts = value;

                if value
                    fig = ancestor(app.ConvertVoltsCheckBox, 'figure');
                    oldPtr = fig.Pointer;
                    fig.Pointer = 'watch';  drawnow limitrate
                    cleanupPtr = onCleanup(@() set(fig, 'Pointer', oldPtr)); 
                    app.setCalibrationStatus(mm.calibrationSummary(), ~mm.calibrationOk());
                else
                    app.setCalibrationStatus('Conversion off - showing volts.', false);
                end
                mm.ScopeName= app.ScopenameEditField.Value;

                % Always redraw: this toggle IS the change.
                mm.updateFigure(app.BecExp.NCompletedRun);
            end
            app.TrialSetting.ModulationParameter.IsConvertVolts = value;
        end

        % Value changed function: ModFreqVariableDropDown
        function ModFreqVariableDropDownValueChanged(app, event)
            disp("ModFreqVariableDropDownValueChanged fired: " + string(app.ModFreqVariableDropDown.Value)) %remove later, for debugging
            value = app.ModFreqVariableDropDown.Value;
            % Update live experiment if running/loaded
            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp, 'ModulationMonitor')
            app.BecExp.ModulationMonitor.ExpectedFrequencyVar = value;
            app.BecExp.refresh("ModulationMonitor");
            end
        % Update background trial settings for future runs
        app.TrialSetting.ModulationParameter.ExpectedFrequencyVar = value;
        end

        % Value changed function: ConvertVoltsCheckBox
        function ConvertVoltsCheckBoxValueChanged(app, event)
            value = app.ConvertVoltsCheckBox.Value;
            if ~isempty(app.BecExp) && isvalid(app.BecExp) ...
                    && isprop(app.BecExp, 'ModulationMonitor') ...
                    && ~isempty(app.BecExp.ModulationMonitor)
                mm = app.BecExp.ModulationMonitor;
                mm.IsConvertVolts = value;

                if value
                    fig = ancestor(app.ConvertVoltsCheckBox, 'figure');
                    oldPtr = fig.Pointer;
                    fig.Pointer = 'watch';  drawnow limitrate
                    cleanupPtr = onCleanup(@() set(fig, 'Pointer', oldPtr)); 
                    app.setCalibrationStatus(mm.calibrationSummary(), ~mm.calibrationOk());
                else
                    app.setCalibrationStatus('Conversion off - showing volts.', false);
                end

                % Always redraw: this toggle IS the change.
                mm.updateFigure(app.BecExp.NCompletedRun);
            end
            app.TrialSetting.ModulationParameter.IsConvertVolts = value;
        end

        % Value changed function: UnitEditField
        function UnitEditFieldValueChanged(app, event)
            value = string(app.UnitEditField.Value);
            if strlength(value) == 0
                value = "Er";  app.UnitEditField.Value = "Er";
            end
            if ~isempty(app.BecExp) && isvalid(app.BecExp) ...
                    && isprop(app.BecExp, 'ModulationMonitor') ...
                    && ~isempty(app.BecExp.ModulationMonitor)
                mm = app.BecExp.ModulationMonitor;
                mm.VoltUnit = value;
                if mm.IsConvertVolts
                    mm.updateFigure(app.BecExp.NCompletedRun);
                end
            end
            app.TrialSetting.ModulationParameter.VoltUnit = value;
        end

        % Value changed function: Ch1KdTrialEditField
        function Ch1KdTrialEditFieldValueChanged(app, event)
            app.applyKdCalibration();
        end

        % Value changed function: Ch2KdTrialEditField
        function Ch2KdTrialEditFieldValueChanged(app, event)
            app.applyKdCalibration();
        end

        % Value changed function: Ch1CorrectionEditField
        function Ch1CorrectionEditFieldValueChanged(app, event)
            app.applyKdCalibration();
        end

        % Value changed function: Ch2CorrectionEditField
        function Ch2CorrectionEditFieldValueChanged(app, event)
            app.applyKdCalibration();
        end

        % Value changed function: ChirpCheckBox
        function ChirpCheckBoxValueChanged(app, event)
            value = app.ChirpCheckBox.Value;
            % Update live experiment if running/loaded
            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp, 'ModulationMonitor')
                    app.BecExp.ModulationMonitor.IsChirp = value;
                    app.BecExp.refresh("ModulationMonitor");
            end
        % Update background trial settings for future runs
        app.TrialSetting.ModulationParameter.IsChirp = value;
        end

        % Value changed function: ChirpEndFreqVarDropDown
        function ChirpEndFreqVarDropDownValueChanged(app, event)
            disp("ChirpEndFreqVarDropDownValueChanged fired: " + string(app.ChirpEndFreqVarDropDown.Value))
            value = app.ChirpEndFreqVarDropDown.Value;
            if value == "None"
                value = "";
            end
            % Update live experiment if running/loaded
            if ~isempty(app.BecExp) && isvalid(app.BecExp) && isprop(app.BecExp, 'ModulationMonitor')
            app.BecExp.ModulationMonitor.ChirpEndFreqVar = value;
            app.BecExp.refresh("ModulationMonitor");
            end
        % Update background trial settings for future runs
        app.TrialSetting.ModulationParameter.ChirpEndFreqVar = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BECExperimentControlPanelUIFigure = uifigure('Visible', 'off');
            app.BECExperimentControlPanelUIFigure.Name = 'BEC Experiment Control Panel';
            app.BECExperimentControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @BECExperimentControlPanelUIFigureCloseRequest, true);
            app.BECExperimentControlPanelUIFigure.Tag = 'BecControl';
            app.GridLayout = uigridlayout(app.BECExperimentControlPanelUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.BECExperimentPropertiesPanel = uipanel(app.GridLayout2);
            app.BECExperimentPropertiesPanel.Title = 'BEC Experiment Properties';
            app.TimeLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.TimeLabel.Text = 'Time:';
            app.PreviewerButtonGroup = uibuttongroup(app.BECExperimentPropertiesPanel);
            app.PreviewerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PreviewerButtonGroupSelectionChanged, true);
            app.PreviewerButtonGroup.Title = 'Previewer';
            app.OpticalDensityButton = uitogglebutton(app.PreviewerButtonGroup);
            app.OpticalDensityButton.Text = 'Optical Density';
            app.OpticalDensityButton.Value = true;
            app.AtomicDensityButton = uitogglebutton(app.PreviewerButtonGroup);
            app.AtomicDensityButton.Text = 'Atomic Density';
            app.AcquisitionLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.AcquisitionLabel.Text = 'Acquisition:';
            app.AcquisitionDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.AcquisitionDropDown.Editable = 'on';
            app.AcquisitionDropDown.ValueChangedFcn = createCallbackFcn(app, @AcquisitionDropDownValueChanged, true);
            app.ROILabel = uilabel(app.BECExperimentPropertiesPanel);
            app.ROILabel.Text = 'ROI:';
            app.ROIDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.ROIDropDown.Editable = 'on';
            app.ROIDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIDropDownValueChanged, true);
            app.AutoAcquireImagesCheckBox = uicheckbox(app.BECExperimentPropertiesPanel);
            app.AutoAcquireImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoAcquireImagesCheckBoxValueChanged, true);
            app.AutoAcquireImagesCheckBox.Text = 'Auto Acquire Images';
            app.SerialNumberLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.SerialNumberLabel.Text = 'Serial Number:';
            app.TrialNameLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.TrialNameLabel.Text = 'Trial Name:';
            app.TrialNameDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.TrialNameDropDown.Editable = 'on';
            app.TrialNameDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialNameDropDownValueChanged, true);
            app.ScannedParameterLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.ScannedParameterLabel.Text = 'Scanned Variable:';
            app.ScannedVariableDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.ScannedVariableDropDown.Editable = 'on';
            app.ScannedVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @ScannedVariableDropDownValueChanged, true);
            app.ScannedVariableDropDown.ClickedFcn = createCallbackFcn(app, @ScannedVariableDropDownClicked, true);
            app.OverwriteTrialTypeButton = uibutton(app.BECExperimentPropertiesPanel, 'push');
            app.OverwriteTrialTypeButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteTrialTypeButtonPushed, true);
            app.OverwriteTrialTypeButton.Text = 'Overwrite Trial Type';
            app.AtomLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.AtomLabel.Text = 'Atom:';
            app.SaveAsNewTrialTypeButton = uibutton(app.BECExperimentPropertiesPanel, 'push');
            app.SaveAsNewTrialTypeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsNewTrialTypeButtonPushed, true);
            app.SaveAsNewTrialTypeButton.Text = 'Save As New Trial Type';
            app.AnalysisMethodsPanel = uipanel(app.BECExperimentPropertiesPanel);
            app.AnalysisMethodsPanel.Title = 'Analysis Methods';
            app.InitializeButton = uibutton(app.AnalysisMethodsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.RefreshButton_4 = uibutton(app.AnalysisMethodsPanel, 'push');
            app.RefreshButton_4.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_4Pushed, true);
            app.RefreshButton_4.Text = 'Refresh';
            app.ShowButton = uibutton(app.AnalysisMethodsPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.SaveButton = uibutton(app.AnalysisMethodsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Tree = uitree(app.AnalysisMethodsPanel, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.OdNode = uitreenode(app.Tree);
            app.OdNode.Text = 'Od';
            app.ImagingNode = uitreenode(app.Tree);
            app.ImagingNode.Text = 'Imaging';
            app.AdNode = uitreenode(app.Tree);
            app.AdNode.Text = 'Ad';
            app.ScopeValueNode = uitreenode(app.Tree);
            app.ScopeValueNode.Text = 'ScopeValue';
            app.DensityFitNode = uitreenode(app.Tree);
            app.DensityFitNode.Text = 'DensityFit';
            app.AtomNumberNode = uitreenode(app.Tree);
            app.AtomNumberNode.Text = 'AtomNumber';
            app.TofNode = uitreenode(app.Tree);
            app.TofNode.Text = 'Tof';
            app.CenterFitNode = uitreenode(app.Tree);
            app.CenterFitNode.Text = 'CenterFit';
            app.KapitzaDiracNode = uitreenode(app.Tree);
            app.KapitzaDiracNode.Text = 'KapitzaDirac';
            app.KapitzaPhaseDiagramNode = uitreenode(app.Tree);
            app.KapitzaPhaseDiagramNode.Text = 'KapitzaPhaseDiagram';
            app.ModulationMonitorNode = uitreenode(app.Tree);
            app.ModulationMonitorNode.Text = 'ModulationMonitor';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.TabGroup2 = uitabgroup(app.BECExperimentPropertiesPanel);
            app.OdTab = uitab(app.TabGroup2);
            app.OdTab.Title = 'Od';
            app.ShowFringeRemovalDataCheckBox = uicheckbox(app.OdTab);
            app.ShowFringeRemovalDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFringeRemovalDataCheckBoxValueChanged, true);
            app.ShowFringeRemovalDataCheckBox.Text = 'Show Fringe Removal Data';
            app.FringeRemovalMethodLabel = uilabel(app.OdTab);
            app.FringeRemovalMethodLabel.Text = 'Fringe Removal Method:';
            app.FringeRemovalMethodDropDown = uidropdown(app.OdTab);
            app.FringeRemovalMethodDropDown.Items = {'None', 'LSR'};
            app.FringeRemovalMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FringeRemovalMethodDropDownValueChanged, true);
            app.FringeRemovalMethodDropDown.Value = 'None';
            app.ODMinLabel = uilabel(app.OdTab);
            app.ODMinLabel.Text = 'OD Max:';
            app.ODMaxEditField = uieditfield(app.OdTab, 'numeric');
            app.ODMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ODMaxEditFieldValueChanged, true);
            app.ODMaxEditField.Value = 4;
            app.ODMaxLabel = uilabel(app.OdTab);
            app.ODMaxLabel.Text = 'OD Min:';
            app.ODMinEditField = uieditfield(app.OdTab, 'numeric');
            app.ODMinEditField.ValueChangedFcn = createCallbackFcn(app, @ODMinEditFieldValueChanged, true);
            app.ImagingTab = uitab(app.TabGroup2);
            app.ImagingTab.Title = 'Imaging';
            app.ImagingStageLabel = uilabel(app.ImagingTab);
            app.ImagingStageLabel.Text = 'Imaging Stage:';
            app.ImagingStageDropDown = uidropdown(app.ImagingTab);
            app.ImagingStageDropDown.Items = {'LF', 'HF', 'NI'};
            app.ImagingStageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagingStageDropDownValueChanged, true);
            app.ImagingStageDropDown.Value = 'LF';
            app.ImagingMethodDropDownLabel = uilabel(app.ImagingTab);
            app.ImagingMethodDropDownLabel.Text = 'Imaging Method:';
            app.ImagingMethodDropDown = uidropdown(app.ImagingTab);
            app.ImagingMethodDropDown.Items = {'Absorption', 'Dispersive', 'Flourescence'};
            app.ImagingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagingMethodDropDownValueChanged, true);
            app.ImagingMethodDropDown.Value = 'Absorption';
            app.AdTab = uitab(app.TabGroup2);
            app.AdTab.Title = 'Ad';
            app.ADMethodLabel = uilabel(app.AdTab);
            app.ADMethodLabel.Text = 'AD Method:';
            app.ADMethodDropDown = uidropdown(app.AdTab);
            app.ADMethodDropDown.Items = {'TwoLevelWeakLight', 'RandomPolarization', 'UniformStrongLight', 'StrongLight', 'PhaseContrastImaging'};
            app.ADMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ADMethodDropDownValueChanged, true);
            app.ADMethodDropDown.Value = 'TwoLevelWeakLight';
            app.ADMinLabel = uilabel(app.AdTab);
            app.ADMinLabel.Text = 'AD Max:';
            app.ADMaxEditField = uieditfield(app.AdTab, 'numeric');
            app.ADMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ADMaxEditFieldValueChanged, true);
            app.ADMaxEditField.Value = 8;
            app.ADMaxLabel = uilabel(app.AdTab);
            app.ADMaxLabel.Text = 'AD Min:';
            app.ADMinEditField = uieditfield(app.AdTab, 'numeric');
            app.ADMinEditField.ValueChangedFcn = createCallbackFcn(app, @ADMinEditFieldValueChanged, true);
            app.times1013m2Label = uilabel(app.AdTab);
            app.times1013m2Label.Interpreter = 'tex';
            app.times1013m2Label.Text = '\times 10^{13} m^{-2}';
            app.times1013m2Label_2 = uilabel(app.AdTab);
            app.times1013m2Label_2.Interpreter = 'tex';
            app.times1013m2Label_2.Text = '\times 10^{13} m^{-2}';
            app.XaxisLabel = uilabel(app.AdTab);
            app.XaxisLabel.Text = 'X-axis Label:';
            app.CustomXLabelEditField = uieditfield(app.AdTab, 'text');
            app.DensityFitTab = uitab(app.TabGroup2);
            app.DensityFitTab.Title = 'DensityFit';
            app.DensityFitMethodLabel = uilabel(app.DensityFitTab);
            app.DensityFitMethodLabel.Text = 'Density Fit Method:';
            app.DensityFitMethodDropDown = uidropdown(app.DensityFitTab);
            app.DensityFitMethodDropDown.Items = {'GaussianFit1D', 'BosonicGaussianFit1D'};
            app.DensityFitMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DensityFitMethodDropDownValueChanged, true);
            app.DensityFitMethodDropDown.Value = 'GaussianFit1D';
            app.AtomNumberTab = uitab(app.TabGroup2);
            app.AtomNumberTab.Title = 'AtomNumber';
            app.AtomNumberMaxLabel = uilabel(app.AtomNumberTab);
            app.AtomNumberMaxLabel.Text = 'Atom Number  Max:';
            app.AtomNumberMaxEditField = uieditfield(app.AtomNumberTab, 'numeric');
            app.AtomNumberMaxEditField.Limits = [0 Inf];
            app.AtomNumberMaxEditField.ValueChangedFcn = createCallbackFcn(app, @AtomNumberMaxEditFieldValueChanged, true);
            app.AtomNumberMaxEditField.Value = 30;
            app.times106Label = uilabel(app.AtomNumberTab);
            app.times106Label.Interpreter = 'tex';
            app.times106Label.Text = '\times 10^6';
            app.ShowRawCheckBox = uicheckbox(app.AtomNumberTab);
            app.ShowRawCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRawCheckBoxValueChanged, true);
            app.ShowRawCheckBox.Text = 'Show Raw';
            app.ShowRawCheckBox.Value = true;
            app.ShowThermalCheckBox = uicheckbox(app.AtomNumberTab);
            app.ShowThermalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowThermalCheckBoxValueChanged, true);
            app.ShowThermalCheckBox.Text = 'Show Thermal';
            app.ShowThermalCheckBox.Value = true;
            app.ShowNormalizedAtomNumberCheckBox = uicheckbox(app.AtomNumberTab);
            app.ShowNormalizedAtomNumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNormalizedAtomNumberCheckBoxValueChanged, true);
            app.ShowNormalizedAtomNumberCheckBox.Text = 'Show Normalized Atom Number';
            app.ShowNormalizedAtomNumberCheckBox.Value = true;
            app.AtomNumberFitMethodDropDownLabel = uilabel(app.AtomNumberTab);
            app.AtomNumberFitMethodDropDownLabel.Text = 'Atom Number Fit Method:';
            app.AtomNumberFitMethodDropDown = uidropdown(app.AtomNumberTab);
            app.AtomNumberFitMethodDropDown.Items = {'None', 'GaussianFit1D', 'ParabolicFit1D'};
            app.AtomNumberFitMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @AtomNumberFitMethodDropDownValueChanged, true);
            app.AtomNumberFitMethodDropDown.Value = 'GaussianFit1D';
            app.TofTab = uitab(app.TabGroup2);
            app.TofTab.Title = 'Tof';
            app.CenterFitTab = uitab(app.TabGroup2);
            app.CenterFitTab.Title = 'CenterFit';
            app.CenterFitMethodDropDownLabel = uilabel(app.CenterFitTab);
            app.CenterFitMethodDropDownLabel.Text = 'Center Fit Method:';
            app.CenterFitMethodDropDown = uidropdown(app.CenterFitTab);
            app.CenterFitMethodDropDown.Items = {'LinearFit1D', 'ParabolicFit1D', 'SineFit1D', 'TriangleFit1D', 'IsoscelesTriangleFit1D'};
            app.CenterFitMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CenterFitMethodDropDownValueChanged, true);
            app.CenterFitMethodDropDown.Value = 'LinearFit1D';
            app.SaveCloudCenterCheckBox = uicheckbox(app.CenterFitTab);
            app.SaveCloudCenterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCloudCenterCheckBoxValueChanged, true);
            app.SaveCloudCenterCheckBox.Text = 'Save Cloud Center';
            app.ModifyXThermalFitButton = uibutton(app.CenterFitTab, 'push');
            app.ModifyXThermalFitButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyXThermalFitButtonPushed, true);
            app.ModifyXThermalFitButton.Text = 'Modify X Thermal Fit';
            app.ModifyYThermalFitButton = uibutton(app.CenterFitTab, 'push');
            app.ModifyYThermalFitButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyYThermalFitButtonPushed, true);
            app.ModifyYThermalFitButton.Text = 'Modify Y Thermal Fit';
            app.ScopeValueTab = uitab(app.TabGroup2);
            app.ScopeValueTab.Title = 'ScopeValue';
            app.ScopeValueNameLabel = uilabel(app.ScopeValueTab);
            app.ScopeValueNameLabel.Text = 'Scope Value Name';
            app.ScopeValueNameTextArea = uitextarea(app.ScopeValueTab);
            app.ScopeValueNameTextArea.ValueChangedFcn = createCallbackFcn(app, @ScopeValueNameTextAreaValueChanged, true);
            app.KapitzaDiracTab = uitab(app.TabGroup2);
            app.KapitzaDiracTab.Title = 'KapitzaDirac';
            app.KdFitMethodDropDownLabel = uilabel(app.KapitzaDiracTab);
            app.KdFitMethodDropDownLabel.Text = 'Kd Fit Method:';
            app.KdFitMethodDropDown = uidropdown(app.KapitzaDiracTab);
            app.KdFitMethodDropDown.Items = {'TDSE', 'TDSEAverage', 'RamanNath'};
            app.KdFitMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @KdFitMethodDropDownValueChanged, true);
            app.KdFitMethodDropDown.Value = 'TDSE';
            app.ParameterMethodDropDownLabel = uilabel(app.KapitzaDiracTab);
            app.ParameterMethodDropDownLabel.Text = 'Parameter Method:';
            app.ParameterMethodDropDown = uidropdown(app.KapitzaDiracTab);
            app.ParameterMethodDropDown.Items = {'ReadVariable', 'FitScope'};
            app.ParameterMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterMethodDropDownValueChanged, true);
            app.ParameterMethodDropDown.Value = 'ReadVariable';
            app.WavelengthEditFieldLabel = uilabel(app.KapitzaDiracTab);
            app.WavelengthEditFieldLabel.Text = 'Wavelength:';
            app.WavelengthEditField = uieditfield(app.KapitzaDiracTab, 'numeric');
            app.WavelengthEditField.Limits = [0 Inf];
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditFieldValueChanged, true);
            app.LatticeAxisDropDownLabel = uilabel(app.KapitzaDiracTab);
            app.LatticeAxisDropDownLabel.Text = 'Lattice Axis:';
            app.LatticeAxisDropDown = uidropdown(app.KapitzaDiracTab);
            app.LatticeAxisDropDown.Items = {'X', 'Y'};
            app.LatticeAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @LatticeAxisDropDownValueChanged, true);
            app.LatticeAxisDropDown.Value = 'Y';
            app.MaxOrderLabel = uilabel(app.KapitzaDiracTab);
            app.MaxOrderLabel.Text = 'Max Order:';
            app.MaxOrderEditField = uieditfield(app.KapitzaDiracTab, 'numeric');
            app.MaxOrderEditField.Limits = [1 Inf];
            app.MaxOrderEditField.RoundFractionalValues = 'on';
            app.MaxOrderEditField.ValueChangedFcn = createCallbackFcn(app, @MaxOrderEditFieldValueChanged, true);
            app.MaxOrderEditField.Value = 1;
            app.ROISizeEditFieldLabel = uilabel(app.KapitzaDiracTab);
            app.ROISizeEditFieldLabel.Text = 'ROI Size:';
            app.ROISizeEditField = uieditfield(app.KapitzaDiracTab, 'numeric');
            app.ROISizeEditField.Limits = [1 Inf];
            app.ROISizeEditField.RoundFractionalValues = 'on';
            app.ROISizeEditField.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldValueChanged, true);
            app.ROISizeEditField.Value = 1;
            app.nmLabel = uilabel(app.KapitzaDiracTab);
            app.nmLabel.Interpreter = 'tex';
            app.nmLabel.Text = 'nm';
            app.ROIMethodDropDownLabel = uilabel(app.KapitzaDiracTab);
            app.ROIMethodDropDownLabel.Text = 'ROI Method:';
            app.ROIMethodDropDown = uidropdown(app.KapitzaDiracTab);
            app.ROIMethodDropDown.Items = {'Auto', 'Manual'};
            app.ROIMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIMethodDropDownValueChanged, true);
            app.ROIMethodDropDown.Value = 'Auto';
            app.ScanTypeDropDownLabel = uilabel(app.KapitzaDiracTab);
            app.ScanTypeDropDownLabel.Text = 'Scan Type:';
            app.ScanTypeDropDown = uidropdown(app.KapitzaDiracTab);
            app.ScanTypeDropDown.Items = {'Power', 'Time'};
            app.ScanTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScanTypeDropDownValueChanged, true);
            app.ScanTypeDropDown.Value = 'Power';
            app.ScopeChannelEditFieldLabel = uilabel(app.KapitzaDiracTab);
            app.ScopeChannelEditFieldLabel.Text = 'Scope Channel:';
            app.ScopeChannelEditField = uieditfield(app.KapitzaDiracTab, 'text');
            app.ScopeChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ScopeChannelEditFieldValueChanged, true);
            app.DoKdFitButton = uibutton(app.KapitzaDiracTab, 'push');
            app.DoKdFitButton.ButtonPushedFcn = createCallbackFcn(app, @DoKdFitButtonPushed, true);
            app.DoKdFitButton.Text = 'Do Kd Fit';
            app.RegenerateROIButton = uibutton(app.KapitzaDiracTab, 'push');
            app.RegenerateROIButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateROIButtonPushed, true);
            app.RegenerateROIButton.Text = 'Regenerate ROI';
            app.WaistEditFieldLabel = uilabel(app.KapitzaDiracTab);
            app.WaistEditFieldLabel.Text = 'Waist:';
            app.WaistEditField = uieditfield(app.KapitzaDiracTab, 'numeric');
            app.WaistEditField.ValueChangedFcn = createCallbackFcn(app, @WaistEditFieldValueChanged, true);
            app.CloudSizeEditFieldLabel = uilabel(app.KapitzaDiracTab);
            app.CloudSizeEditFieldLabel.Text = 'Cloud Size:';
            app.CloudSizeEditField = uieditfield(app.KapitzaDiracTab, 'numeric');
            app.CloudSizeEditField.ValueChangedFcn = createCallbackFcn(app, @CloudSizeEditFieldValueChanged, true);
            app.mumLabel = uilabel(app.KapitzaDiracTab);
            app.mumLabel.Interpreter = 'tex';
            app.mumLabel.Text = '\mum';
            app.mumLabel_2 = uilabel(app.KapitzaDiracTab);
            app.mumLabel_2.Interpreter = 'tex';
            app.mumLabel_2.Text = '\mum';
            app.KapitzaPhaseDiagramTab = uitab(app.TabGroup2);
            app.KapitzaPhaseDiagramTab.Title = 'KapitzaPhaseDiagram';
            app.KapitzaMetricLabel = uilabel(app.KapitzaPhaseDiagramTab);
            app.KapitzaMetricLabel.Text = 'Kapitza Metric:';
            app.KapitzaMetricDropDown = uidropdown(app.KapitzaPhaseDiagramTab);
            app.KapitzaMetricDropDown.Items = {'IPR', 'StdDev', 'StdDev2', 'CentralAtomFraction'};
            app.KapitzaMetricDropDown.ValueChangedFcn = createCallbackFcn(app, @KapitzaMetricDropDownValueChanged, true);
            app.KapitzaMetricDropDown.Tooltip = {'• IPR: Inverse Participation Ratio'; '• StdDev: Raw cloud width (RMS) '; '• StdDev2: Cloud width with background noise subtraction'; '• CentralAtomFraction: Ratio of atoms remaining in the central region of trial ROI (±10px) -- somewhat outdated'};
            app.KapitzaMetricDropDown.Value = 'StdDev2';
            app.NoiseFloorLabel = uilabel(app.KapitzaPhaseDiagramTab);
            app.NoiseFloorLabel.Text = 'Noise Floor:';
            app.KapitzaNoiseFloorEditField = uieditfield(app.KapitzaPhaseDiagramTab, 'numeric');
            app.KapitzaNoiseFloorEditField.ValueChangedFcn = createCallbackFcn(app, @KapitzaNoiseFloorEditFieldValueChanged, true);
            app.KapitzaNoiseFloorEditField.Tooltip = {'Sets the noise threshold for background subtraction. Format as percentage in decimal form (e.g. 0.01= lowest 1% discarded). This is only used when the ''StdDev2'' metric is selected.'};
            app.KapitzaInvertCheckBox = uicheckbox(app.KapitzaPhaseDiagramTab);
            app.KapitzaInvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @KapitzaInvertCheckBoxValueChanged, true);
            app.KapitzaInvertCheckBox.Tooltip = {'Inverts the vertical Omega (frequency) axis on the phase diagram. When checked, lower frequencies will be displayed at the top of the generated image.'};
            app.KapitzaInvertCheckBox.Text = '   Invert y-axis?';
            app.KapitzaInvertCheckBox.Value = true;
            app.DrawTheoryButton = uibutton(app.KapitzaPhaseDiagramTab, 'push');
            app.DrawTheoryButton.ButtonPushedFcn = createCallbackFcn(app, @DrawTheoryButtonPushed, true);
            app.DrawTheoryButton.Text = 'Draw theory curves';
            app.DimensionlessVariables = uicheckbox(app.KapitzaPhaseDiagramTab);
            app.DimensionlessVariables.ValueChangedFcn = createCallbackFcn(app, @DimensionlessVariablesValueChanged, true);
            app.DimensionlessVariables.Text = '  Convert to dimensionless variables (α, Ω)';
            app.DimensionlessVariables.Value = true;
            app.ModulationMonitorTab = uitab(app.TabGroup2);
            app.ModulationMonitorTab.Title = 'ModulationMonitor';
            app.ScopeNameLabel = uilabel(app.ModulationMonitorTab);
            app.ScopeNameLabel.Text = 'Scope name:';
            app.ScopenameEditField = uieditfield(app.ModulationMonitorTab, 'text');
            app.ScopenameEditField.ValueChangedFcn = createCallbackFcn(app, @ScopenameEditFieldValueChanged, true);
            app.ScopenameEditField.Value = 'LatticeScopeHD';
            app.ModulationFrequencyLabel = uilabel(app.ModulationMonitorTab);
            app.ModulationFrequencyLabel.Text = 'Modulation frequency variable:';
            app.ModFreqVariableDropDown = uidropdown(app.ModulationMonitorTab);
            app.ModFreqVariableDropDown.Editable = 'on';
            app.ModFreqVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @ModFreqVariableDropDownValueChanged, true);
            app.CalcRelativePhaseCheckBox = uicheckbox(app.ModulationMonitorTab);
            app.CalcRelativePhaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalcRelativePhaseCheckBoxValueChanged, true);
            app.CalcRelativePhaseCheckBox.Text = 'Calculate relative modulation phase? (requires two enabled channels)';
            app.CalcRelativePhaseCheckBox.Value = true;
            app.TargetphaseEditFieldLabel = uilabel(app.ModulationMonitorTab);
            app.TargetphaseEditFieldLabel.Text = 'Target phase:';
            app.TargetphaseEditField = uieditfield(app.ModulationMonitorTab, 'numeric');
            app.TargetphaseEditField.ValueChangedFcn = createCallbackFcn(app, @TargetphaseEditFieldValueChanged, true);
            app.TargetphaseEditField.Value = 180;
            app.ConvertVoltsCheckBox = uicheckbox(app.ModulationMonitorTab);
            app.ConvertVoltsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConvertVoltsCheckBoxValueChanged, true);
            app.ConvertVoltsCheckBox.Text = 'Convert volts?';
            app.IfyesunitsLabel = uilabel(app.ModulationMonitorTab);
            app.IfyesunitsLabel.Text = 'If yes, units?';
            app.UnitEditField = uieditfield(app.ModulationMonitorTab, 'text');
            app.UnitEditField.ValueChangedFcn = createCallbackFcn(app, @UnitEditFieldValueChanged, true);
            app.UnitEditField.Value = 'Er';
            app.Ch1multiplierLabel = uilabel(app.ModulationMonitorTab);
            app.Ch1multiplierLabel.Text = '(CH1) KD Trial:';
            app.Ch1KdTrialEditField = uieditfield(app.ModulationMonitorTab, 'numeric');
            app.Ch1KdTrialEditField.RoundFractionalValues = 'on';
            app.Ch1KdTrialEditField.ValueChangedFcn = createCallbackFcn(app, @Ch1KdTrialEditFieldValueChanged, true);
            app.Ch1KdTrialEditField.Value = 1;
            app.CH2multiplierLabel = uilabel(app.ModulationMonitorTab);
            app.CH2multiplierLabel.Text = '(CH2) KD Trial:';
            app.Ch2KdTrialEditField = uieditfield(app.ModulationMonitorTab, 'numeric');
            app.Ch2KdTrialEditField.RoundFractionalValues = 'on';
            app.Ch2KdTrialEditField.ValueChangedFcn = createCallbackFcn(app, @Ch2KdTrialEditFieldValueChanged, true);
            app.Ch2KdTrialEditField.Value = 1;
            app.OffsetLabel_2 = uilabel(app.ModulationMonitorTab);
            app.OffsetLabel_2.Text = 'Corr. Divisor:';
            app.Ch2CorrectionEditField = uieditfield(app.ModulationMonitorTab, 'numeric');
            app.Ch2CorrectionEditField.LowerLimitInclusive = 'off';
            app.Ch2CorrectionEditField.Limits = [0 Inf];
            app.Ch2CorrectionEditField.ValueChangedFcn = createCallbackFcn(app, @Ch2CorrectionEditFieldValueChanged, true);
            app.Ch2CorrectionEditField.Value = 1;
            app.OffsetLabel = uilabel(app.ModulationMonitorTab);
            app.OffsetLabel.Text = 'Corr. Divisor:';
            app.Ch1CorrectionEditField = uieditfield(app.ModulationMonitorTab, 'numeric');
            app.Ch1CorrectionEditField.LowerLimitInclusive = 'off';
            app.Ch1CorrectionEditField.Limits = [0 Inf];
            app.Ch1CorrectionEditField.ValueChangedFcn = createCallbackFcn(app, @Ch1CorrectionEditFieldValueChanged, true);
            app.Ch1CorrectionEditField.Value = 1;
            app.ChirpCheckBox = uicheckbox(app.ModulationMonitorTab);
            app.ChirpCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChirpCheckBoxValueChanged, true);
            app.ChirpCheckBox.Text = 'Chirp signal?';
            app.ModulationFrequencyLabel_2 = uilabel(app.ModulationMonitorTab);
            app.ModulationFrequencyLabel_2.Text = 'Chirp end frequency:';
            app.ChirpEndFreqVarDropDown = uidropdown(app.ModulationMonitorTab);
            app.ChirpEndFreqVarDropDown.Editable = 'on';
            app.ChirpEndFreqVarDropDown.ValueChangedFcn = createCallbackFcn(app, @ChirpEndFreqVarDropDownValueChanged, true);
            app.AddNewVariableButton = uibutton(app.BECExperimentPropertiesPanel, 'push');
            app.AddNewVariableButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewVariableButtonPushed, true);
            app.AddNewVariableButton.Text = 'Add New Variable';
            app.DescriptionTextAreaLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.BECExperimentPropertiesPanel);
            app.DescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextAreaValueChanged, true);
            app.AveragingMethodLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.AveragingMethodLabel.Text = 'Averaging Method:';
            app.AveragingMethodDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.AveragingMethodDropDown.Items = {'StdErr', 'StdDev', 'None'};
            app.AveragingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @AveragingMethodDropDownValueChanged, true);
            app.AveragingMethodDropDown.ClickedFcn = createCallbackFcn(app, @AveragingMethodDropDownClicked, true);
            app.AveragingMethodDropDown.Value = 'StdErr';
            app.EditHardwareAssociationButton = uibutton(app.BECExperimentPropertiesPanel, 'push');
            app.EditHardwareAssociationButton.ButtonPushedFcn = createCallbackFcn(app, @EditHardwareAssociationButtonPushed, true);
            app.EditHardwareAssociationButton.Text = 'Edit Hardware Association';
            app.ScannedVariable2Label = uilabel(app.BECExperimentPropertiesPanel);
            app.ScannedVariable2Label.Text = 'Scanned Variable2:';
            app.ScannedVariable2DropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.ScannedVariable2DropDown.Editable = 'on';
            app.ScannedVariable2DropDown.ValueChangedFcn = createCallbackFcn(app, @ScannedVariable2DropDownValueChanged, true);
            app.ScannedVariable2DropDown.ClickedFcn = createCallbackFcn(app, @ScannedVariable2DropDownClicked, true);
            app.RenameTrialTypeButton = uibutton(app.BECExperimentPropertiesPanel, 'push');
            app.RenameTrialTypeButton.ButtonPushedFcn = createCallbackFcn(app, @RenameTrialTypeButtonPushed, true);
            app.RenameTrialTypeButton.Text = 'Rename Trial Type';
            app.AverageDensityCheckBox = uicheckbox(app.BECExperimentPropertiesPanel);
            app.AverageDensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageDensityCheckBoxValueChanged, true);
            app.AverageDensityCheckBox.Text = 'Average Density';
            app.CloudCenterReferenceLabel = uilabel(app.BECExperimentPropertiesPanel);
            app.CloudCenterReferenceLabel.Text = 'Center Reference:';
            app.CenterReferenceDropDown = uidropdown(app.BECExperimentPropertiesPanel);
            app.CenterReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @CenterReferenceDropDownValueChanged, true);
            app.ShowCenterReferenceCheckBox = uicheckbox(app.BECExperimentPropertiesPanel);
            app.ShowCenterReferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCenterReferenceCheckBoxValueChanged, true);
            app.ShowCenterReferenceCheckBox.Text = 'Show Center Reference';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.RunLogLabel = uilabel(app.Panel_2);
            app.RunLogLabel.Text = 'RunLog:';
            app.RunLogTextArea = uitextarea(app.Panel_2);
            app.RunLogTextArea.Editable = 'off';
            app.RunSetParameterButton = uibutton(app.Panel_2, 'push');
            app.RunSetParameterButton.ButtonPushedFcn = createCallbackFcn(app, @RunSetParameterButtonPushed, true);
            app.RunSetParameterButton.Text = 'Run SetParameter';
            app.OpenmmConfigButton = uibutton(app.Panel_2, 'push');
            app.OpenmmConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenmmConfigButtonPushed, true);
            app.OpenmmConfigButton.Text = 'Open mmConfig';
            app.OpenCiceroOriginButton = uibutton(app.Panel_2, 'push');
            app.OpenCiceroOriginButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCiceroOriginButtonPushed, true);
            app.OpenCiceroOriginButton.Text = 'Open Cicero Origin';
            app.WarningsandErrorsLabel = uilabel(app.Panel_2);
            app.WarningsandErrorsLabel.Text = 'Warnings and Errors:';
            app.WarningsandErrorsTextArea = uitextarea(app.Panel_2);
            app.WarningsandErrorsTextArea.Editable = 'off';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RunExperimentTab = uitab(app.TabGroup);
            app.RunExperimentTab.Title = 'Run Experiment';
            app.UpdateButton = uibutton(app.RunExperimentTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.RefreshButton = uibutton(app.RunExperimentTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Text = 'Refresh';
            app.STARTButton = uibutton(app.RunExperimentTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.PAUSEButton = uibutton(app.RunExperimentTab, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            app.STOPButton = uibutton(app.RunExperimentTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.OpenDataPathButton = uibutton(app.RunExperimentTab, 'push');
            app.OpenDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataPathButtonPushed, true);
            app.OpenDataPathButton.Enable = 'off';
            app.OpenDataPathButton.Text = 'Open Data Path';
            app.DeletedRunVariableButton = uibutton(app.RunExperimentTab, 'push');
            app.DeletedRunVariableButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedRunVariableButtonPushed, true);
            app.DeletedRunVariableButton.Enable = 'off';
            app.DeletedRunVariableButton.Text = 'Deleted Run Variable';
            app.RESUMEButton = uibutton(app.RunExperimentTab, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Enable = 'off';
            app.RESUMEButton.Text = 'RESUME';
            app.HoldButton = uibutton(app.RunExperimentTab, 'state');
            app.HoldButton.ValueChangedFcn = createCallbackFcn(app, @HoldButtonValueChanged, true);
            app.HoldButton.Enable = 'off';
            app.HoldButton.Text = 'Hold';
            app.FASTSTOPButton = uibutton(app.RunExperimentTab, 'push');
            app.FASTSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @FASTSTOPButtonPushed, true);
            app.FASTSTOPButton.Enable = 'off';
            app.FASTSTOPButton.Text = 'FAST STOP';
            app.PlotMixandAnimationButton = uibutton(app.RunExperimentTab, 'push');
            app.PlotMixandAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMixandAnimationButtonPushed, true);
            app.PlotMixandAnimationButton.Enable = 'off';
            app.PlotMixandAnimationButton.Text = 'Plot Mix and Animation';
            app.PlotHistogramofScannedVariableButton = uibutton(app.RunExperimentTab, 'push');
            app.PlotHistogramofScannedVariableButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHistogramofScannedVariableButtonPushed, true);
            app.PlotHistogramofScannedVariableButton.Enable = 'off';
            app.PlotHistogramofScannedVariableButton.Text = 'Plot Histogram of Scanned Variable';
            app.LocalTestPanel = uipanel(app.RunExperimentTab);
            app.LocalTestPanel.Title = 'Local Test';
            app.LocalTestCheckBox = uicheckbox(app.LocalTestPanel);
            app.LocalTestCheckBox.ValueChangedFcn = createCallbackFcn(app, @LocalTestCheckBoxValueChanged, true);
            app.LocalTestCheckBox.Text = 'Local Test?';
            app.secondsLabel = uilabel(app.LocalTestPanel);
            app.secondsLabel.Text = 'seconds';
            app.RunTimeIntervalEditFieldLabel = uilabel(app.LocalTestPanel);
            app.RunTimeIntervalEditFieldLabel.Text = 'Run Time Interval';
            app.RunTimeIntervalEditField = uieditfield(app.LocalTestPanel, 'numeric');
            app.RunTimeIntervalEditField.Enable = 'off';
            app.SelectTestDataPathButton = uibutton(app.LocalTestPanel, 'push');
            app.SelectTestDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTestDataPathButtonPushed, true);
            app.SelectTestDataPathButton.Enable = 'off';
            app.SelectTestDataPathButton.Text = 'Select Test Data Path';
            app.TestDataPathEditField = uieditfield(app.RunExperimentTab, 'text');
            app.TestDataPathEditField.Enable = 'off';
            app.LoadSavedExperimentTab = uitab(app.TabGroup);
            app.LoadSavedExperimentTab.Title = 'Load Saved Experiment';
            app.StartDateLabel = uilabel(app.LoadSavedExperimentTab);
            app.StartDateLabel.Text = 'Start Date:';
            app.StartDateDatePicker = uidatepicker(app.LoadSavedExperimentTab);
            app.StartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.StartDateDatePicker.Value = datetime([2024 1 1]);
            app.EndDateLabel = uilabel(app.LoadSavedExperimentTab);
            app.EndDateLabel.Text = 'End Date:';
            app.EndDateDatePicker = uidatepicker(app.LoadSavedExperimentTab);
            app.EndDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DataTable = uitable(app.LoadSavedExperimentTab);
            app.DataTable.ColumnName = {'SerialNumber'; 'DateTime'; 'Name'; 'ScannedVariable'; 'Description'; 'DataPath'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnSortable = [true true true true false];
            app.DataTable.SelectionType = 'row';
            app.DataTable.ClickedFcn = createCallbackFcn(app, @DataTableClicked, true);
            app.DataTable.Multiselect = 'off';
            app.TrialNameLabel_2 = uilabel(app.LoadSavedExperimentTab);
            app.TrialNameLabel_2.Text = 'Trial Name:';
            app.TrialNameDropDown_2 = uidropdown(app.LoadSavedExperimentTab);
            app.TrialNameDropDown_2.Editable = 'on';
            app.TrialNameDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TrialNameDropDown_2ValueChanged, true);
            app.SerialLabel = uilabel(app.LoadSavedExperimentTab);
            app.SerialLabel.Text = 'Serial #';
            app.SerialEditField = uieditfield(app.LoadSavedExperimentTab, 'numeric');
            app.SerialEditField.Limits = [1 Inf];
            app.SerialEditField.RoundFractionalValues = 'on';
            app.SerialEditField.Enable = 'off';
            app.SerialEditField.Value = 1;
            app.SerialCheckBox = uicheckbox(app.LoadSavedExperimentTab);
            app.SerialCheckBox.ValueChangedFcn = createCallbackFcn(app, @SerialCheckBoxValueChanged, true);
            app.SerialCheckBox.Text = '';
            app.Label = uilabel(app.LoadSavedExperimentTab);
            app.Label.Text = '~';
            app.SerialEditField2 = uieditfield(app.LoadSavedExperimentTab, 'numeric');
            app.SerialEditField2.Limits = [1 Inf];
            app.SerialEditField2.Enable = 'off';
            app.SerialEditField2.Value = 1;
            app.SearchButton = uibutton(app.LoadSavedExperimentTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.LoadButton = uibutton(app.LoadSavedExperimentTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.OpenDataPathButton_3 = uibutton(app.LoadSavedExperimentTab, 'push');
            app.OpenDataPathButton_3.ButtonPushedFcn = createCallbackFcn(app, @OpenDataPathButton_3Pushed, true);
            app.OpenDataPathButton_3.Enable = 'off';
            app.OpenDataPathButton_3.Text = 'Open Data Path';
            app.UnloadButton = uibutton(app.LoadSavedExperimentTab, 'push');
            app.UnloadButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadButtonPushed, true);
            app.UnloadButton.Enable = 'off';
            app.UnloadButton.Text = 'Unload';
            app.RefreshButton_3 = uibutton(app.LoadSavedExperimentTab, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_3Pushed, true);
            app.RefreshButton_3.Enable = 'off';
            app.RefreshButton_3.Text = 'Refresh';
            app.HoldButton_3 = uibutton(app.LoadSavedExperimentTab, 'state');
            app.HoldButton_3.ValueChangedFcn = createCallbackFcn(app, @HoldButton_3ValueChanged, true);
            app.HoldButton_3.Enable = 'off';
            app.HoldButton_3.Text = 'Hold';
            app.KeyWordsEditFieldLabel = uilabel(app.LoadSavedExperimentTab);
            app.KeyWordsEditFieldLabel.Text = 'Key Words:';
            app.KeyWordsEditField = uieditfield(app.LoadSavedExperimentTab, 'text');
            app.ServerButtonGroup = uibuttongroup(app.LoadSavedExperimentTab);
            app.RemoteButton = uitogglebutton(app.ServerButtonGroup);
            app.RemoteButton.Text = 'Remote';
            app.RemoteButton.Value = true;
            app.LocalButton = uitogglebutton(app.ServerButtonGroup);
            app.LocalButton.Text = 'Local';
            app.MoreFunctionsTab = uitab(app.TabGroup);
            app.MoreFunctionsTab.Title = 'More Functions';
            app.AssignBecExptoWorkSpaceButton = uibutton(app.MoreFunctionsTab, 'push');
            app.AssignBecExptoWorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignBecExptoWorkSpaceButtonPushed, true);
            app.AssignBecExptoWorkSpaceButton.Text = 'Assign BecExp to Work-Space';
            app.ResetImageAcquisitionButton = uibutton(app.MoreFunctionsTab, 'push');
            app.ResetImageAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageAcquisitionButtonPushed, true);
            app.ResetImageAcquisitionButton.Text = 'Reset Image Acquisition';
            app.CreateParallelPoolButton = uibutton(app.MoreFunctionsTab, 'push');
            app.CreateParallelPoolButton.ButtonPushedFcn = createCallbackFcn(app, @CreateParallelPoolButtonPushed, true);
            app.CreateParallelPoolButton.Text = 'Create Parallel Pool';
            % Show the figure after all components are created
            app.BECExperimentControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BecControlPanel

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BECExperimentControlPanelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BECExperimentControlPanelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BECExperimentControlPanelUIFigure)
        end
    end
end