classdef HardwareAssociation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HardwareAssociationUIFigure  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        WaveformGeneratorTab         matlab.ui.container.Tab
        WgGrid                       matlab.ui.container.GridLayout
        PhaseLockTab                 matlab.ui.container.Tab
        PlGrid                       matlab.ui.container.GridLayout
    end


    properties (Access = public)
        SrcApp % Description
        WgSettings
        PlSettings
        WflName
        VarName
        NWgRow
        NPlRow
    end

    properties (Constant)
        NWgColumn = 4
        NPlColumn = 4
    end

    methods (Access = private)

        function t = grid2table(app)
            nRow = numel(app.WgGrid.RowHeight);
            if isempty(nRow)
                t = table.empty;
            else
                WaveformGeneratorName = strings(nRow,1);
                ChannelName = strings(nRow,1);
                WaveformListName = strings(nRow,1);
                for ii = 1:nRow
                    WaveformGeneratorName(ii) = app.WgGrid.Children(ii*(nRow-1)+1).Value;
                    ChannelName(ii) = app.WgGrid.Children(ii*(nRow-1)+2).Value;
                    WaveformListName(ii) = app.WgGrid.Children(ii*(nRow-1)+3).Value;
                end
                t = table(WaveformGeneratorName,ChannelName,WaveformListName);
            end
        end

        function checkboxValueChanged(app,src,~,rowNumber)
            nColumn = app.NWgColumn;
            if ~src.Value
                for ii = 2:nColumn
                    src.Parent.Children((rowNumber-1)*nColumn+ii).Enable = "off";
                end
            else
                for ii = 2:nColumn
                    src.Parent.Children((rowNumber-1)*nColumn+ii).Enable = "on";
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, srcApp)
            load Config.mat HardwareList
            load WaveformLibrary.mat WaveformLibrary
            load HardwareVariable.mat VariableList
            wgName = HardwareList(HardwareList.Type == "WaveformGenerator",:).Name;
            plName = HardwareList(HardwareList.Type == "PhaseLock",:).Name;
            app.WgSettings = loadVar("WaveformGeneratorSetting","WaveformGeneratorSetting");
            app.WgSettings(~ismember(app.WgSettings.Name,wgName),:) = [];
            app.PlSettings = loadVar("PhaseLockSetting","PhaseLockSetting");
            app.PlSettings(~ismember(app.PlSettings.Name,plName),:) = [];
            app.WflName = [WaveformLibrary.Name];
            app.VarName = [VariableList.Name];
            app.NWgRow = sum(cellfun(@numel,app.WgSettings.IsOutput));
            app.NPlRow = size(app.PlSettings,1);
            rowNumber = 1;
            for ii = 1:size(app.WgSettings,1)
                wfs = app.WgSettings(ii,:);
                nch = numel(wfs.IsOutput{1});
                for jj = 1:nch
                    app.WgGrid.RowHeight{end + 1} = 25;
                    uicheckbox(app.WgGrid,"Text","","ValueChangedFcn",{@app.checkboxValueChanged,rowNumber},"Value",false);
                    uilabel(app.WgGrid,"Text",wfs.Name,"Enable","off");
                    uilabel(app.WgGrid,"Text","Ch"+string(jj),"Enable","off");
                    uidropdown(app.WgGrid,"Items",["None",app.WflName],'Value',wfs.WaveformListName{1}(jj),"Enable","off");
                    rowNumber = rowNumber + 1;
                end
            end
            for jj = 1:size(app.PlSettings,1)
                pls = app.PlSettings(jj,:);
                app.PlGrid.RowHeight{end + 1} = 25;
                uicheckbox(app.PlGrid,"Text","","ValueChangedFcn",{@app.checkboxValueChanged,jj},"Value",false);
                uilabel(app.PlGrid,"Text",pls.Name,"Enable","off");
                uieditfield(app.PlGrid,"numeric","Value",pls.Frequency,"Enable","off");
                uidropdown(app.PlGrid,"Items",["None";app.VarName],'Value',pls.VariableName,"Enable","off");
            end

        if nargin == 2
            app.SrcApp = srcApp;

            %% Load Waveform association
            nColumn = app.NWgColumn;
            wa = srcApp.TrialSetting.WaveformAssociation;         
            if ~isempty(wa) && ~ismissing(wa) && wa~="None"
                wat = str2table(wa);
                allWfNameLabel = string({app.WgGrid.Children(2:nColumn:end).Text});
                allChNameLabel = string({app.WgGrid.Children(3:nColumn:end).Text});
                for ii = 1:size(wat,1)
                    rowNumber = find(allWfNameLabel == wat(ii,:).WaveformGeneratorName & allChNameLabel == wat(ii,:).ChannelName);
                    app.WgGrid.Children((rowNumber-1)*nColumn+1).Value = true;
                    for jj = 2:nColumn
                        app.WgGrid.Children((rowNumber-1)*nColumn+jj).Enable = "on";
                    end
                    app.WgGrid.Children((rowNumber-1)*nColumn+4).Value = wat(ii,:).WaveformListName;
                end
            end

            %% Load Phase Lock association
            nColumn = app.NPlColumn;
            pla = srcApp.TrialSetting.PhaseLockAssociation;
            if ~isempty(pla) && ~ismissing(pla) && pla~="None"
                plat = str2table(pla);
                for ii = 1:size(plat,1)
                    rowNumber = find(plName == plat.PhaseLockName(ii));
                    app.PlGrid.Children((rowNumber-1)*nColumn+1).Value = true;
                    for jj = 2:nColumn
                        app.PlGrid.Children((rowNumber-1)*nColumn+jj).Enable = "on";
                    end
                    app.PlGrid.Children((rowNumber-1)*nColumn+3).Value = double(plat.Frequency(ii));
                    app.PlGrid.Children((rowNumber-1)*nColumn+4).Value = plat.VariableName(ii);
                end
            end
        end

        end

        % Close request function: HardwareAssociationUIFigure
        function HardwareAssociationUIFigureCloseRequest(app, event)
            if ~isempty(app.SrcApp)

                %% Save Waveform Association
                if app.NWgRow
                    nColumn = app.NWgColumn;
                    isEnable = [app.WgGrid.Children(1:nColumn:end).Value];
                    if any(isEnable)
                        nRow = sum(isEnable);
                        WaveformGeneratorName = strings(nRow,1);
                        ChannelName = strings(nRow,1);
                        WaveformListName = strings(nRow,1);
                        rowList = find(isEnable);
                        for ii = 1:numel(rowList)
                            WaveformGeneratorName(ii) = string(app.WgGrid.Children((rowList(ii)-1)*nColumn+2).Text);
                            ChannelName(ii) = string(app.WgGrid.Children((rowList(ii)-1)*nColumn+3).Text);
                            WaveformListName(ii) = string(app.WgGrid.Children((rowList(ii)-1)*nColumn+4).Value);
                        end
                        s = table2str(table(WaveformGeneratorName,ChannelName,WaveformListName));
                    else
                        s = string(missing);
                    end
                    app.SrcApp.TrialSetting.WaveformAssociation = s;
                    if ~isempty(app.SrcApp.BecExp)
                        if isvalid(app.SrcApp.BecExp)
                            app.SrcApp.BecExp.WaveformAssociation = s;
                        end
                    end
                end

                %% Save Phase Lock Association
                if app.NPlRow
                    nColumn = app.NPlColumn;
                    isEnable = [app.PlGrid.Children(1:nColumn:end).Value];
                    if any(isEnable)
                        nRow = sum(isEnable);
                        PhaseLockName = strings(nRow,1);
                        Frequency = strings(nRow,1);
                        VariableName = strings(nRow,1);
                        rowList = find(isEnable);
                        for ii = 1:numel(rowList)
                            PhaseLockName(ii) = string(app.PlGrid.Children((rowList(ii)-1)*nColumn+2).Text);
                            Frequency(ii) = string(app.PlGrid.Children((rowList(ii)-1)*nColumn+3).Value);
                            VariableName(ii) = string(app.PlGrid.Children((rowList(ii)-1)*nColumn+4).Value);
                        end
                        s = table2str(table(PhaseLockName,Frequency,VariableName));
                    else
                        s = string(missing);
                    end
                    app.SrcApp.TrialSetting.PhaseLockAssociation = s;
                    if ~isempty(app.SrcApp.BecExp)
                        if isvalid(app.SrcApp.BecExp)
                            app.SrcApp.BecExp.PhaseLockAssociation = s;
                        end
                    end
                end
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HardwareAssociationUIFigure = uifigure('Visible', 'off');
            app.HardwareAssociationUIFigure.Name = 'WaveformAssociation';
            app.HardwareAssociationUIFigure.CloseRequestFcn = createCallbackFcn(app, @HardwareAssociationUIFigureCloseRequest, true);
            app.HardwareAssociationUIFigure.Tag = 'WaveformAssociation';
            app.TabGroup = uitabgroup(app.HardwareAssociationUIFigure);
            app.WaveformGeneratorTab = uitab(app.TabGroup);
            app.WaveformGeneratorTab.Title = 'Waveform Generator';
            app.WgGrid = uigridlayout(app.WaveformGeneratorTab);
            app.PhaseLockTab = uitab(app.TabGroup);
            app.PhaseLockTab.Title = 'Phase Lock';
            app.PlGrid = uigridlayout(app.PhaseLockTab);
            % Show the figure after all components are created
            app.HardwareAssociationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HardwareAssociation(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.HardwareAssociationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.HardwareAssociationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HardwareAssociationUIFigure)
        end
    end
end