classdef HardwareAssociationEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BoundVariableLabel    matlab.ui.control.Label
        ValueLabel            matlab.ui.control.Label
        SettingNameLabel      matlab.ui.control.Label
        ChannelNumberLabel    matlab.ui.control.Label
        HardwareNameLabel     matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        AddAssociationButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        HardwareList HardwareList
        HardwareSetting HardwareSetting
        HardwareAssociation HardwareAssociation
        HardwareIdName table
        WaveformListIdName table
        VariableIdName table
        TrialID int64
        SettingID int64 = []
    end

    properties (Constant)
        NColumn = 6
    end
    
    methods (Access = public)
        
        function addAssociation(app,setId)
            app.GridLayout.RowHeight{end + 1} = 20;

            % Set Hardware selection list
            if nargin == 2
                t = app.HardwareSetting.readEntry(setId);
            end
            hwList = uidropdown(app.GridLayout,'Items',app.HardwareIdName.Name,'Editable','on',...
                'ValueChangedFcn',@app.hardwareDropDownChanged);

            if nargin == 2
                hwName = app.HardwareList.readValue(t.HardwareID,"Name");
                hwList.Value = hwName;
            else
                hwName = string(hwList.Value);
            end

            % Set channel and setting selection list
            s = app.getSettingName(hwName);
            channelList = uidropdown(app.GridLayout,'Items',string(1:s.NChannel),'Editable','on','ValueChangedFcn',@app.channelDropDownChanged);
            settingList = uidropdown(app.GridLayout,'Items',s.SettingName,'Editable','on','ValueChangedFcn',@(src,evt) app.settingDropDownChanged(src,evt,false));

            % Set channel and setting selection values
            if nargin == 2
                channelList.Value = string(app.HardwareSetting.readValue(setId,"ChannelNumber"));
                settingList.Value = app.HardwareSetting.readValue(setId,"Name");
            end
            
            % Set value and variable selection list
            uieditfield(app.GridLayout,'text','Value',"None");
            uidropdown(app.GridLayout,'Items',["None";app.VariableIdName.Name],...
                'Value',"None","Editable","on",'ValueChangedFcn',@app.dropdownValueChanged);
            
            % Delete button
            uibutton(app.GridLayout, 'push','Text','Delete','ButtonPushedFcn',@app.deleteButtonPushed);

            % Reset value and variable
            nColumn = app.NColumn;
            row = numel(app.GridLayout.RowHeight);
            app.settingDropDownChanged(app.GridLayout.Children(nColumn * (row-1) + 3),0,nargin == 2);

        end

        function hardwareDropDownChanged(app,src,evt)
            hwName = string(src.Value);
            if ~ismember(hwName,string(src.Items))
                src.Value = evt.PreviousValue;
                return
            end

            row = src.Layout.Row;
            nColumn = app.NColumn;
            s = app.getSettingName(hwName);
            app.GridLayout.Children(nColumn * (row-1) + 2).Items = string(1:s.NChannel);
            app.GridLayout.Children(nColumn * (row-1) + 3).Items = s.SettingName;
            app.settingDropDownChanged(app.GridLayout.Children(nColumn * (row-1) + 3),0,false);
        end

        function deleteButtonPushed(app,src,~)
            nRow = numel(app.GridLayout.RowHeight);
            nColumn = app.NColumn;
            row = src.Layout.Row;
            answer = questdlg("Do you want to delete the association?", ...
                'Confirm', ...
                'Yes','No','No');

            if string(answer) == "No"
                return
            end

            for ii = (row+1):nRow
                for jj = 1:nColumn
                    app.GridLayout.Children((ii-1)*nColumn + jj).Layout.Row = ...
                        app.GridLayout.Children((ii-1)*nColumn + jj).Layout.Row - 1;
                end
            end

            permuteIdx = [1:(row-1)*nColumn,(row*nColumn+1):nRow*nColumn,((row-1)*nColumn+1):row*nColumn];
            app.GridLayout.Children = app.GridLayout.Children(permuteIdx);
            delete(app.GridLayout.Children((end-nColumn+1) : end))
            app.GridLayout.RowHeight(end) = [];
            app.SettingID(row) = [];
        end

        function channelDropDownChanged(app,src,evt)
            row = src.Layout.Row;
            nColumn = app.NColumn;
            settingID = app.getSettingID(row);
            if settingID == 0
                src.Value = evt.PreviousValue;
                return
            end
            app.settingDropDownChanged(app.GridLayout.Children(nColumn * (row-1) + 3),0,false);
        end

        function settingDropDownChanged(app,src,evt,isLoading)
            settingName = string(src.Value);
            if ~ismember(settingName,string(src.Items))
                src.Value = evt.PreviousValue;
                return
            end
            row = src.Layout.Row;
            nColumn = app.NColumn;
            valIdx = nColumn * (row-1) + 4;
            settingID = app.getSettingID(row);
            if settingID == 0
                src.Value = evt.PreviousValue;
                return
            end
            app.SettingID(row) = app.getSettingID(row);
            settingType = app.HardwareSetting.readValue(app.SettingID(row),"Type");
            if ~isLoading
                settingValue = app.HardwareSetting.readValue(app.SettingID(row),"DefaultValue");
                settingVariableID = app.HardwareSetting.readValue(app.SettingID(row),"VariableID");
            else
                settingValue = app.HardwareAssociation.readValueTwoKey(app.TrialID,app.SettingID(row),"DefaultValue","TrialID","SettingID");
                settingVariableID = app.HardwareAssociation.readValueTwoKey(app.TrialID,app.SettingID(row),"VariableID","TrialID","SettingID");
            end
            settingVariable = app.VariableIdName(app.VariableIdName.ID == settingVariableID,:).Name;
            if settingName ~= "WaveformList"
                switch settingType
                    case "logical"
                        valueEdit = uicheckbox(app.GridLayout,'Value',str2num(settingValue),'Text','');
                        app.GridLayout.Children(valIdx + 1).Enable = "off";
                    case "double"
                        valueEdit = uieditfield(app.GridLayout,'numeric','Value',double(settingValue));
                        app.GridLayout.Children(valIdx + 1).Enable = "on";
                    otherwise
                        valueEdit = uieditfield(app.GridLayout,'text','Value',settingValue);
                        app.GridLayout.Children(valIdx + 1).Enable = "off";
                end
            else
                wflName = app.WaveformListIdName(app.WaveformListIdName.ID == double(settingValue),:).Name;
                valueEdit = uidropdown(app.GridLayout,'Items',["None";app.WaveformListIdName.Name],...
                    'Value',wflName,'Editable','on','ValueChangedFcn',@app.dropdownValueChanged);
                app.GridLayout.Children(valIdx + 1).Enable = "off";
            end
            app.GridLayout.Children(valIdx + 1).Value = settingVariable;

            nChild = numel(app.GridLayout.Children);
            permuIdx = [1:(valIdx-1),nChild,(valIdx+1):(nChild-1),valIdx];
            app.GridLayout.Children = app.GridLayout.Children(permuIdx);
            delete(app.GridLayout.Children(end))
            valueEdit.Layout.Row = row;
            valueEdit.Layout.Column = 4;
        end

        function s = getSettingName(app,hwName)
            hwId = app.HardwareList.readValue(hwName,"ID","Name");
            name = app.HardwareSetting.readValue(hwId,"Name","HardwareID");
            nChannel = app.HardwareSetting.readValue(hwId,"ChannelNumber","HardwareID");
            s.SettingName = unique(name);
            s.NChannel = max(nChannel);
        end

        function id = getSettingID(app,row)
            nColumn = app.NColumn;
            hwIdx = nColumn * (row-1) + 1;
            hwName = string(app.GridLayout.Children(hwIdx).Value);
            hwId = app.HardwareIdName(app.HardwareIdName.Name == hwName,:).ID;
            cNumber = string(app.GridLayout.Children(hwIdx+1).Value);
            settingName = string(app.GridLayout.Children(hwIdx+2).Value);
            id = app.HardwareSetting.readSettingID(hwId,settingName,cNumber);
        end

        function dropdownValueChanged(app,src,evt)
            if ~ismember(string(src.Value),string(src.Items))
                src.Value = evt.PreviousValue;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, trialId)
            app.HardwareList = HardwareList;
            app.HardwareSetting = HardwareSetting;
            app.HardwareAssociation = HardwareAssociation;
            app.HardwareIdName = app.HardwareList.readColumn(["ID","Name"]);
            p = WaveformListLibrary;
            app.WaveformListIdName = p.readColumn(["ID","Name"]);
            ID = 0;
            Name = "None";
            noneColumn = table(ID,Name);
            app.WaveformListIdName(end+1,:) = noneColumn;
            p = VariableList;
            app.VariableIdName = p.readColumn(["ID","Name"]);
            app.VariableIdName(end+1,:) = noneColumn;
            app.TrialID = trialId;
            settingID = app.HardwareAssociation.readValue(trialId,"SettingID","TrialID");
            if ~isempty(settingID)
                app.SettingID = settingID;
                for ii = 1:numel(settingID)
                    app.addAssociation(settingID(ii));
                end
            end

        end

        % Button pushed function: AddAssociationButton
        function AddAssociationButtonPushed(app, event)
            app.addAssociation
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                % Delete existing associations
                app.HardwareAssociation.deleteEntry(app.TrialID,"TrialID");
                nRow = numel(app.GridLayout.RowHeight);
                if nRow ~= 0
                    % Build table
                    nColumn = app.NColumn;
                    DefaultValue = string.empty(nRow,0);
                    VariableID = zeros(nRow,1);
                    TrialID = repmat(app.TrialID,nRow,1);
                    SettingID = app.SettingID(:);
                    for ii = 1:nRow
                        val = app.GridLayout.Children((ii-1) * nColumn + 4).Value;
                        if string(app.GridLayout.Children((ii-1) * nColumn + 3).Value) == "WaveformList"
                            val = app.WaveformListIdName(app.WaveformListIdName.Name == string(val),:).ID;
                        end
                        if ~isstring(val)
                            DefaultValue(ii) = string(val);
                        else
                            DefaultValue(ii) = val;
                        end
                        varName = string(app.GridLayout.Children((ii-1) * nColumn + 5).Value);
                        VariableID(ii) = app.VariableIdName(app.VariableIdName.Name == varName,:).ID;
                    end
                    DefaultValue = DefaultValue(:);
                    t = table(TrialID,SettingID,DefaultValue,VariableID);

                    % Insert associations
                    app.HardwareAssociation.writeEntry(t);
                end
            catch me
                warning("Association failed. Error message: " + me.message)
            end

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddAssociationButton = uibutton(app.UIFigure, 'push');
            app.AddAssociationButton.ButtonPushedFcn = createCallbackFcn(app, @AddAssociationButtonPushed, true);
            app.AddAssociationButton.Text = 'Add Association';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.HardwareNameLabel = uilabel(app.UIFigure);
            app.HardwareNameLabel.Text = 'Hardware Name';
            app.ChannelNumberLabel = uilabel(app.UIFigure);
            app.ChannelNumberLabel.Text = 'Channel Number';
            app.SettingNameLabel = uilabel(app.UIFigure);
            app.SettingNameLabel.Text = 'Setting Name';
            app.ValueLabel = uilabel(app.UIFigure);
            app.ValueLabel.Text = 'Value';
            app.BoundVariableLabel = uilabel(app.UIFigure);
            app.BoundVariableLabel.Text = 'Bound Variable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HardwareAssociationEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end